classdef ROIMatcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShowROIsData2CheckBox   matlab.ui.control.CheckBox
        ShowROIsData1CheckBox   matlab.ui.control.CheckBox
        ShowdendritesCheckBox   matlab.ui.control.CheckBox
        LoadData2Button         matlab.ui.control.Button
        LoadConfocalButton      matlab.ui.control.Button
        RotateEditField         matlab.ui.control.NumericEditField
        RotateEditFieldLabel    matlab.ui.control.Label
        FlipCheckBox            matlab.ui.control.CheckBox
        Data1ConfocalDataLabel  matlab.ui.control.Label
        Data2DropDown           matlab.ui.control.DropDown
        Data2DropDownLabel      matlab.ui.control.Label
        AnimalField             matlab.ui.control.EditField
        AnimalEditFieldLabel    matlab.ui.control.Label
        LoaddataLabel           matlab.ui.control.Label
        UIData2                 matlab.ui.control.UIAxes
        UIData1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % DataPaths
        TwoPDataPath = []; STEDDataPath = []; ConfocalDataPath = [];
        TwoPCellImg = []; STEDCellImg = []; ConfocalCellImg = [];
        TwoPData = struct;
        STED_Data = struct;
        ConfocalData = struct('xPos', [], 'yPos', [], 'type', [], 'body', [], 'perimeter', [], 'size', [])
        DendData = struct('dendrite', [])
        SelectedItems = struct('dendTrace', 1, 'dataType', '2p Data', 'Angle', 0, 'Flip', 0)
    end
    
    methods (Access = private)
        function UpdateAxisData1(app)
            app.UIData1.Visible = 'on';
            imagesc(app.ConfocalCellImg, 'Parent', app.UIData1);
            axis(app.UIData1, 'image');
            axis(app.UIData1,'off');
            if app.SelectedItems.dendTrace
                hold(app.UIData1, 'on')
                for dend = 1:length(app.DendData.dendrite)
                    plot(app.UIData1, app.DendData.dendrite{dend}.xTrace ,app.DendData.dendrite{dend}.yTrace, 'LineWidth',1.5, 'color', 'red');
                    hold(app.UIData1, 'on')
                end 
            end
        end
        
        function UpdateAxisData2(app)
            app.UIData2.Visible = 'on';
            switch app.SelectedItems.dataType
                case '2p Data'
                    imgShow = app.TwoPCellImg;
                    %imagesc(app.TwoPCellImg, 'Parent', app.UIData2);
                case 'STED Data'
                    imgShow = app.STEDCellImg;
                    %imagesc(app.STEDCellImg, 'Parent', app.UIData2);
            end
            if app.SelectedItems.Flip == 1
                imgShow = fliplr(imgShow); 
            end
            if app.SelectedItems.Angle ~= 0
                tform = affine2d([cosd(app.SelectedItems.Angle) sind(app.SelectedItems.Angle) 0; -sind(app.SelectedItems.Angle) cosd(app.SelectedItems.Angle) 0; 0 0 1]);
                imgShow = imwarp(imgShow,tform);
            end
            imagesc(imgShow, 'Parent', app.UIData2);
            axis(app.UIData2, 'image');
            axis(app.UIData2,'off');
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Data2DropDown
        function UpdateDataType(app, event)
            app.SelectedItems.dataType = app.Data2DropDown.Value;
            UpdateAxisData2(app)
        end

        % Value changed function: RotateEditField
        function UpdateRotateData2(app, event)
            app.SelectedItems.Angle = app.RotateEditField.Value;
            UpdateAxisData2(app)
        end

        % Value changed function: FlipCheckBox
        function UpdateFlipData2(app, event)
            app.SelectedItems.Flip = app.FlipCheckBox.Value;
            UpdateAxisData2(app)
        end

        % Value changed function: ShowdendritesCheckBox
        function UpdateDendTraces(app, event)
            app.SelectedItems.dendTrace = app.ShowdendritesCheckBox.Value;
            UpdateAxisData1(app);
        end

        % Button pushed function: LoadConfocalButton
        function LoadData1(app, event)
            imgSizeConf = [4060, 4060]; %number of pixels -> adjust to get out of imaging data
            umSize = [302.1508, 302.1508]; %size in microns -> adjust to get out of imaging data
            
            %set dir
            ConfocalDir = 'Z:\Juliane\Data\Confocal\';
            animalName = char(app.AnimalField.Value);
            app.ConfocalDataPath = [char(ConfocalDir) char(animalName) filesep];
            
            %load image
            [fileName, Path] = uigetfile('*.tif', 'Select confocal image',app.ConfocalDataPath);
            tifFile = imread([Path fileName]);
            app.ConfocalCellImg = mat2gray(tifFile); %scales it for better viewability
            
            %load tracing Data
            confReconFiles = dir([app.ConfocalDataPath 'Dendrite Tracing' filesep '*.swc']);
            xTrace = [];
            yTrace = [];
            for f = 1:length(confReconFiles)
                file = [app.ConfocalDataPath 'Dendrite Tracing' filesep confReconFiles(f).name];
                b = loadfilelist(file);
                a = b(4:end);
                c = cellfun(@str2num, a, 'UniformOutput', false);
                traceInfo = cell2mat(c');
                xTrace = [xTrace; traceInfo(:,3)];
                yTrace = [yTrace; traceInfo(:,4)];
            end
            
            %load ROI data
            ROIFiles = dir([app.ConfocalDataPath  'Spine Tracings' filesep '\*.zip']);
            cvsROIs = [];
            for r = 1:length(ROIFiles)
                file = [app.ConfocalDataPath 'Spine Tracings' filesep ROIFiles(r).name];
                [ROI] = ReadImageJROI(file);
                cvsROIs = [cvsROIs ROI];
            end
            
            %% process data
            %calc umfactor to change from micrometer to pixelposition
            umfactor = imgSizeConf(1)/umSize(1);
            
            %separate tracing data into dendrites by finding jumps
            dffXTrace = diff(xTrace); dffYTrace = diff(yTrace);
            jumpsMins = find(dffXTrace < -1.5); jumpsMinsY = find(dffYTrace < -1.5);
            jumpMax = find(dffXTrace > 1.5); jumpMaxY = find(dffYTrace > 1.5);
            jumps = [jumpsMins; jumpMax; jumpsMinsY; jumpMaxY];
            jumps = sort(jumps);
            jumps = unique(jumps);
            
            x = 1;
            for j = 1:length(jumps)
                dendrite{j}.xTrace = xTrace(x:jumps(j))*umfactor;
                dendrite{j}.yTrace = yTrace(x:jumps(j))*umfactor;
                x = jumps(j)+1;
            end
            app.DendData.dendrite = dendrite;
            
            %process ROIs
            for i = 1:length(cvsROIs)
                tempR = poly2mask(cvsROIs{i}.mnCoordinates(:,1), cvsROIs{i}.mnCoordinates(:,2),imgSizeConf(1),imgSizeConf(2));
                %CHECK FOR DOUBLE REGIONPROPS!!! USE LARGEST ONE
                STATS = regionprops(tempR,'Centroid', 'Area', 'PixelList', 'ConvexHull' );
                [~, idx] = max([STATS.Area]);
                
                app.ConfocalData(i).perimeter = cvsROIs{i}.mnCoordinates+1;
                app.ConfocalData(i).body = STATS.PixelList;
                if length(app.ConfocalData(i).perimeter) > 100
                    app.ConfocalData(i).type = 'soma';
                else
                    app.ConfocalData(i).type = 'spine';
                end
                app.ConfocalData(i).size = length(app.ConfocalData(i).body(:,2));
                app.ConfocalData(i).xPos = STATS(idx).Centroid(:,1)+1;
                app.ConfocalData(i).yPos = STATS(idx).Centroid(:,2)+1;
            end
            
            %load into the axis
            UpdateAxisData1(app)
        end

        % Button pushed function: LoadData2Button
        function LoadData2(app, event)
            animalName = char(app.AnimalField.Value);   
            switch app.SelectedItems.dataType
                case '2p Data'
                    TwoPhontondir = 'Z:\Juliane\Data\2P_Data\';
                    allFolders = dir(TwoPhontondir);
                    a = {allFolders.name};
                    folderInfo = cellfun(@(x) find(contains(x, char(animalName))),a,'UniformOutput',false);
                    notFolder = cellfun(@isempty,folderInfo); %all other cells are empty
                    indFolder = find(~notFolder);                                      
                    app.TwoPDataPath = [char(TwoPhontondir) char(allFolders(indFolder).name) filesep];
                    [fileName, Path] = uigetfile('*.mat', 'Select data for reading in 2p imaging data',app.TwoPDataPath);
                    load([Path fileName], 'CellExpData', 'InVivoImage');
                    app.TwoPData = CellExpData;
                    app.TwoPCellImg = InVivoImage;
                case 'STED Data'
                    STEDDir = 'Y:\Data_JJ_Ferret_project\JJ_FerretProject_Organized\';
                    app.STEDDataPath = [char(STEDDir) char(animalName) filesep];
                    [fileName, Path] = uigetfile('*.mat', 'Select data for reading in STED data',app.STEDDataPath);
                    load([Path fileName], 'CellImage', 'STEDData')
                    app.STED_Data = STEDData;
                    app.STEDCellImg = CellImage;
            end
            UpdateAxisData2(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIData1 = uiaxes(app.UIFigure);
            title(app.UIData1, 'Confocal Data')
            app.UIData1.XTick = [];
            app.UIData1.YTick = [];
            app.UIData1.Visible = 'off';
            app.UIData2 = uiaxes(app.UIFigure);
            title(app.UIData2, 'Data 2')
            app.UIData2.XTick = [];
            app.UIData2.YTick = [];
            app.UIData2.Visible = 'off';
            app.LoaddataLabel = uilabel(app.UIFigure);
            app.LoaddataLabel.Text = '1. Load data';
            app.AnimalEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalField = uieditfield(app.UIFigure, 'text');
            app.AnimalField.Value = 'F2688';
            app.Data2DropDownLabel = uilabel(app.UIFigure);
            app.Data2DropDownLabel.Text = 'Data 2:';
            app.Data2DropDown = uidropdown(app.UIFigure);
            app.Data2DropDown.Items = {'2p Data', 'STED Data'};
            app.Data2DropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateDataType, true);
            app.Data2DropDown.Value = '2p Data';
            app.Data1ConfocalDataLabel = uilabel(app.UIFigure);
            app.Data1ConfocalDataLabel.Text = 'Data 1:  Confocal Data';
            app.FlipCheckBox = uicheckbox(app.UIFigure);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateFlipData2, true);
            app.FlipCheckBox.Text = 'Flip';
            app.RotateEditFieldLabel = uilabel(app.UIFigure);
            app.RotateEditFieldLabel.Text = 'Rotate ';
            app.RotateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotateEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateRotateData2, true);
            app.LoadConfocalButton = uibutton(app.UIFigure, 'push');
            app.LoadConfocalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData1, true);
            app.LoadConfocalButton.Text = 'Load Confocal';
            app.LoadData2Button = uibutton(app.UIFigure, 'push');
            app.LoadData2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadData2, true);
            app.LoadData2Button.Text = 'Load Data 2';
            app.ShowdendritesCheckBox = uicheckbox(app.UIFigure);
            app.ShowdendritesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDendTraces, true);
            app.ShowdendritesCheckBox.Text = 'Show dendrites';
            app.ShowdendritesCheckBox.Value = true;
            app.ShowROIsData1CheckBox = uicheckbox(app.UIFigure);
            app.ShowROIsData1CheckBox.Text = 'Show ROIs';
            app.ShowROIsData2CheckBox = uicheckbox(app.UIFigure);
            app.ShowROIsData2CheckBox.Text = 'Show ROIs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIMatcher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end