classdef AlignementTwoP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadAlignementButton            matlab.ui.control.Button
        ExportimageButton               matlab.ui.control.Button
        SavematdataButton               matlab.ui.control.Button
        SelectfunctionaldataDropDown    matlab.ui.control.DropDown
        SelectfunctionaldataDropDownLabel  matlab.ui.control.Label
        VisibleexperimentsListBox       matlab.ui.control.ListBox
        VisibleexperimentsListBoxLabel  matlab.ui.control.Label
        FunctionaldataLabel             matlab.ui.control.Label
        SaveAlignementButton            matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        PixelMove                       matlab.ui.control.NumericEditField
        Up                              matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Left                            matlab.ui.control.Button
        MoveSelectDropDown              matlab.ui.control.DropDown
        ExpDropDownLabel                matlab.ui.control.Label
        MovesessionsLabel               matlab.ui.control.Label
        LoadDataLabel                   matlab.ui.control.Label
        LoadfunctionaldataButton        matlab.ui.control.Button
        LoadpositionaldataButton        matlab.ui.control.Button
        AnimalEditField                 matlab.ui.control.EditField
        AnimalEditFieldLabel            matlab.ui.control.Label
        UICellConst                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xlsFile = [];
        posDataPath = [];
        functDataPath = [];
        SelectedItems = struct('MoveInPixel', 10, 'currMovSess', [], 'SessMoveNr', [],'visibleID', [], 'currVisibleSess', [], 'ROIProp', [], 'ROIPropNr', [])
        ExpData = struct('Name', [], 'Image', [],'type', [],'RelLeft', [],  'RelRight', [], 'RelUp', [], 'RelDown', [], 'ROIData', []) %contains all data of the individual sessions
        CellImage = []; CellImage3D = [];
    end
    
    methods (Access = private)
        
        function expInfo = findExpInfo(~,xlsFile)
            [~, xls_txt, xls_all]=xlsread(xlsFile, 'bimodal');
            animalCol = find(contains(xls_txt(1,:),'animal'),1);
            expCol = find(contains(xls_txt(1,:),'expNumber'),1);
            flagCol = find(contains(xls_txt(1,:),'Flag'),1);
            regCol = find(contains(xls_txt(1,:),'region'), 1);
            
            expInfo = struct;
            for i=2:size(xls_txt,1)
                %get animals
                expInfo.animal{i-1} = xls_all(i,animalCol);
                %get expIDs
                exp = xls_all(i,expCol);
                if iscell(exp)
                    exp = cell2mat(exp);
                    if ischar(exp)
                        exp = str2double(exp);
                    end
                end
                if exp > 9
                    if exp > 99
                        expInfo.exp_id{i-1} = ['t00' num2str(exp)];
                    else
                        expInfo.exp_id{i-1} = ['t000' num2str(exp)];
                    end
                else
                    expInfo.exp_id{i-1} = ['t0000' num2str(exp)];
                end
                %get flags
                flagInd = xls_all{i,flagCol};
                if ischar(flagInd)
                    flagInd = str2double(flagInd);
                end
                expInfo.flag(i-1) = flagInd;
                %get regions
                regInd = xls_all{i, regCol};
                if ischar(regInd)
                    regInd = str2double(regInd);
                    if isnan(regInd)
                       regInd = xls_all{i, regCol};
                    end
                end
                expInfo.region{i-1} = regInd;
            end
        end
        
        function UpdateCellConst(app)
            if ~isempty(app.SelectedItems.currVisibleSess)
                allDendrites = zeros(size(app.CellImage,1), size(app.CellImage,2), size(app.SelectedItems.currVisibleSess,1));
                for vs = 1:size(app.SelectedItems.visibleID,2)
                    id = cell2mat(app.SelectedItems.visibleID(vs));
                    if length(app.ExpData(id).type) < 7  % 'cells' is less characters than 'dendrite'
                        continue
                    end
                    allDendrites(app.ExpData(id).RelUp:app.ExpData(id).RelDown-1, app.ExpData(id).RelLeft:app.ExpData(id).RelRight-1,vs) = app.ExpData(id).Image;
                end
            end
            app.CellImage = mean(allDendrites,3);
            app.CellImage3D = allDendrites;
            imagesc(app.CellImage, 'Parent', app.UICellConst);
            axis(app.UICellConst, 'image');
            axis(app.UICellConst,'off');
            
             if ~isempty(app.SelectedItems.currMovSess)
                id = app.SelectedItems.SessMoveNr;
                h = app.ExpData(id).RelDown-app.ExpData(id).RelUp;
                w = app.ExpData(id).RelRight-app.ExpData(id).RelLeft;
                rectangle(app.UICellConst, 'Position', [app.ExpData(id).RelLeft,app.ExpData(id).RelUp,w,h], 'EdgeColor', 'white', 'LineStyle', '--')
             end
             
             if app.SelectedItems.ROIPropNr > 1
                hold(app.UICellConst, 'on')
                if ~isempty(app.SelectedItems.currVisibleSess)
                    for vs = 1:size(app.SelectedItems.visibleID,2)
                        id = cell2mat(app.SelectedItems.visibleID(vs));
                        if length(app.ExpData(id).type) <7
                            continue
                        end
                        for r = 1:length(app.ExpData(id).ROIData)
                            xPos = app.ExpData(id).ROIData(r).xPos + app.ExpData(id).RelLeft;
                            yPos = app.ExpData(id).ROIData(r).yPos + app.ExpData(id).RelUp;
                            switch app.SelectedItems.ROIProp
                                case 'ROI Positions'
                                    plot(app.UICellConst, xPos,yPos,'ok', 'MarkerSize',5', 'MarkerFaceColor','red')
                                case 'Responsive'
                                    if app.ExpData(id).ROIData(r).isResp == 1
                                        plot(app.UICellConst, xPos,yPos,'ok','MarkerSize',5', 'MarkerFaceColor','green')
                                    end
                                case 'Ori Pref'
                                    LUT = hsv(180);
                                    if ~isnan(app.ExpData(id).ROIData(r).prefOri)
                                        if app.ExpData(id).ROIData(r).isResp == 1
                                            plot(app.UICellConst, xPos, yPos, 'ok', 'MarkerSize', 5', 'MarkerFaceColor', LUT(1+ floor(app.ExpData(id).ROIData(r).prefOri),:));
                                        end
                                    end
                                case 'Dir Pref'
                                    LUT = hsv(360);
                                    if ~isnan(app.ExpData(id).ROIData(r).prefDir)
                                        if app.ExpData(id).ROIData(r).isResp == 1
                                            plot(app.UICellConst, xPos, yPos, 'ok', 'MarkerSize', 5', 'MarkerFaceColor', LUT(1+ floor(app.ExpData(id).ROIData(r).prefDir),:));
                                        end
                                    end
                                case 'OSI'
                                    LUT = jet(100);
                                    if ~isnan(app.ExpData(id).ROIData(r).OSI)
                                        if app.ExpData(id).ROIData(r).isResp == 1
                                            plot(app.UICellConst, xPos, yPos, 'ok', 'MarkerSize', 5', 'MarkerFaceColor', LUT(1+ floor(app.ExpData(id).ROIData(r).OSI*100),:));
                                        end
                                    end
                                case 'DSI'
                                    LUT = jet(100);
                                    if ~isnan(app.ExpData(id).ROIData(r).DSI)
                                        if app.ExpData(id).ROIData(r).isResp == 1
                                            plot(app.UICellConst, xPos, yPos, 'ok', 'MarkerSize', 5', 'MarkerFaceColor', LUT(1+ floor(app.ExpData(id).ROIData(r).DSI*100),:));
                                        end
                                    end
                            end
                            hold(app.UICellConst, 'on')
                        end
                    end
                end
             end
             hold(app.UICellConst, 'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MoveSelectDropDown
        function UpdateMoveSelect(app, event)
            app.SelectedItems.currMovSess = app.MoveSelectDropDown.Value;
            sessNames = app.MoveSelectDropDown.Items;
            app.SelectedItems.SessMoveNr = find(contains(sessNames,app.SelectedItems.currMovSess));
            UpdateCellConst(app)
        end

        % Value changed function: VisibleexperimentsListBox
        function UpdateVisibleExp(app, event)
            app.SelectedItems.currVisibleSess = app.VisibleexperimentsListBox.Value;
            sessNames = app.VisibleexperimentsListBox.Items;
            app.SelectedItems.visibleID = [];
            
            for sel = 1:length(app.SelectedItems.currVisibleSess)
                app.SelectedItems.visibleID{sel} = find(contains(sessNames, app.SelectedItems.currVisibleSess{sel}));
            end
           
            UpdateCellConst(app)
        end

        % Value changed function: PixelMove
        function UpdateMovement(app, event)
            app.SelectedItems.MoveInPixel = app.PixelMove.Value;  
        end

        % Value changed function: SelectfunctionaldataDropDown
        function UpdateROIProp(app, event)
            app.SelectedItems.ROIProp = app.SelectfunctionaldataDropDown.Value;
            PropNames = app.SelectfunctionaldataDropDown.Items;
            app.SelectedItems.ROIPropNr = find(contains(PropNames, app.SelectedItems.ROIProp));
            UpdateCellConst(app);
        end

        % Button pushed function: LoadpositionaldataButton
        function LoadPosData(app, event)

            bufferInUm = 30;
            posToUmFactor = 0.82;
            
            %Step 1: Read in the exp info for all and find all dendrites
            filePath = 'Z:\Juliane\Organization\Animals\';
            file = 'SpinePerAnimal.xlsx';
            expInfo = findExpInfo(app,[filePath file]);
            animal = char(app.AnimalEditField.Value);
            animalInfo = cellfun(@(x) find(contains(x, animal)), expInfo.animal, 'UniformOutput', false); %which ones contain the animal
            notAnimal = cellfun(@isempty,animalInfo); %all other cells are empty
            indAnimal = find(~notAnimal); %all the dendrites
            flags = find(expInfo.flag); %remove flagged experiments
            for f = 1:length(flags)
                indAnimal = indAnimal(indAnimal~=flags(f));
            end
            
            %Step 2: For each dendrite, get information about size,
            imagePos = struct;
            SessNames = strings([length(indAnimal),1]);
            TwoPhontondir = 'Z:\Juliane\Data\2P_Data\';
            animalName = char(expInfo.animal{indAnimal(1)});
            
            app.posDataPath = [char(TwoPhontondir) char(animalName) '/'];

            %position and the image
            for ind = 1:length(indAnimal)
                expID = char(expInfo.exp_id{indAnimal(ind)});
                SessNames(ind) = char(expID);
                disp(['Reading in ' char(TwoPhontondir) char(animalName) '/' char(expID) '/' char(expID) '_00001_00001.tif'])
                
                imagePos(ind).expID= expID;
                imagePos(ind).type = char(expInfo.region{indAnimal(ind)});
                
                %read in header
                reader = ScanImageTiffReader([TwoPhontondir animalName filesep expID filesep expID '_00001_00001.tif']);
                meta=reader.metadata();
            
                %find position of sample in header
                startAxes = strfind(meta,'SI.hMotors.axesPosition = [');
                splitPos = strfind(meta,']');
                endAxes = splitPos(find(splitPos > startAxes, 1));
                axesPosLabel = meta(startAxes:endAxes);
            
                %split position into variables
                axesPosLabelCell = strsplit(axesPosLabel);
                imagePos(ind).xPos = str2double(axesPosLabelCell{3}(2:end));
                imagePos(ind).yPos = str2double(axesPosLabelCell{4});
                imagePos(ind).zPos = str2double(axesPosLabelCell{5}(1:end-1));
            
                %find size of image
                Zoom = regexp(meta, '(?<=scanZoomFactor = )\d+\.?\d*', 'match');
                sizeImage = 823.0500 / str2double(Zoom{1});
                imagePos(ind).sizeImage = sizeImage;
                imagePos(ind).zoom = Zoom;
            
                %add image of the region
                filePath = [TwoPhontondir animalName filesep expID filesep 'Registered\slice1\Projection\Projection.tif'];
                rawImage = imread(filePath);
                imagePos(ind).image = mat2gray(rawImage);
                
                %SCALE IMAGE IN TERMS OF MIN AND MAX TO MAKE THEM BETTER COMPARABLE BETWEEEN REGIONS!!!! 
                %add scaled image of 10 pixel per um
                scale = imagePos(ind).sizeImage*10/size(imagePos(ind).image,2);
                imagePos(ind).imageScaled = imresize(imagePos(ind).image,scale);
                
                %add ROI data
                ROIFile = load([TwoPhontondir animalName filesep expID filesep 'Registered\slice1\Projection\ROIs.mat']);
                try
                    imagePos(ind).ROIs = ROIFile.data.roi;
                catch
                    imagePos(ind).ROIs = ROIFile.ROIs;
                end
            end
            
            %Step 3: Get parameters for plotting in x & y
            %define region for plotting
            minX = min([imagePos.xPos]);
            maxX = max([imagePos.xPos]);
            minY = min([imagePos.yPos]);
            maxY = max([imagePos.yPos]);
            
            startX = maxX+bufferInUm*2; %let's set 0 to minX/minY - buffer for the extend of the imaging region
            startY = maxY+bufferInUm*2;
            
            %define size of image in total - one z per image minus cell image
            sizeY = (abs(maxX-minX)+bufferInUm*2)*10; %size in um -> lets have 10 pixels per um
            sizeX = (abs(maxY-minY)+bufferInUm*2)*10;
            
            %write X and Y position in relation to startX and startY, save
            %in app data all data for dendrite and ROIs
            for d = 1:length(imagePos)
                imagePos(d).relXPos = abs(-imagePos(d).xPos + startX);
                imagePos(d).relYPos = abs(-imagePos(d).yPos + startY);
                imagePos(d).pixelXPos = abs(-imagePos(d).xPos + startX)*10*posToUmFactor;
                imagePos(d).pixelYPos = abs(-imagePos(d).yPos + startY)*10*posToUmFactor;
                
                app.ExpData(d).RelLeft =  ceil(imagePos(d).pixelXPos)-size(imagePos(d).imageScaled,1)/2;
                app.ExpData(d).RelRight = ceil(app.ExpData(d).RelLeft + size(imagePos(d).imageScaled,1));
                app.ExpData(d).RelUp = ceil(imagePos(d).pixelYPos)-size(imagePos(d).imageScaled,1)/2;
                app.ExpData(d).RelDown = ceil(app.ExpData(d).RelUp + size(imagePos(d).imageScaled,2));
                app.ExpData(d).Image = imagePos(d).imageScaled;
                app.ExpData(d).type = imagePos(d).type;
                app.ExpData(d).Name = imagePos(d).expID;
                
                app.ExpData(d).ROIData = imagePos(d).ROIs; 
            end
            
            %plot all dendrites in a different z plane
            allDendrites = zeros(sizeX, sizeY, length(imagePos)-1);
            z = 1;
            for d = 1:length(imagePos)
                if length(app.ExpData(d).type) < 7  % 'cells' is less characters than 'dendrite'
                    continue
                end
                allDendrites(app.ExpData(d).RelUp:app.ExpData(d).RelDown-1,app.ExpData(d).RelLeft:app.ExpData(d).RelRight-1,z) = app.ExpData(d).Image;
                z = z+1;    
            end
            app.CellImage = mean(allDendrites,3);
            
            %Step 4: Mark all dendrites as visible and update the axis
            app.VisibleexperimentsListBox.Items = SessNames;
            
            app.VisibleexperimentsListBox.Value = app.VisibleexperimentsListBox.Items;
            app.SelectedItems.currVisibleSess = app.VisibleexperimentsListBox.Value;
            sessNames = app.VisibleexperimentsListBox.Items;
            app.SelectedItems.visibileID = [];
            
            for sel = 1:length(app.SelectedItems.currVisibleSess)
                app.SelectedItems.visibleID{sel} = find(contains(sessNames, app.SelectedItems.currVisibleSess{sel}));
            end
            
            app.MoveSelectDropDown.Items = SessNames;
            
            UpdateCellConst(app)
        end

        % Button pushed function: LoadAlignementButton
        function LoadAlign(app, event)
            [fileName, Path] = uigetfile('*.mat', 'Select mat-file for reading in aligned coordinates',app.posDataPath);
            load([Path fileName], 'CellExpData');
            
            app.ExpData = CellExpData;
            UpdateCellConst(app)
        end

        % Button pushed function: LoadfunctionaldataButton
        function LoadFunct(app, event)
            allFolders = dir('Z:\Juliane\Data\ImageAnalysis\');
            a = {allFolders.name};
            folderInfo = cellfun(@(x) find(contains(x, char(app.AnimalEditField.Value))),a,'UniformOutput',false);
            notFolder = cellfun(@isempty,folderInfo); %all other cells are empty
            indFolder = find(~notFolder);
            app.functDataPath = ['Z:\Juliane\Data\ImageAnalysis\' char(allFolders(indFolder).name) filesep];
            for dend = 1:size(app.ExpData,2)
                anaData = load([app.functDataPath char(app.ExpData(dend).Name) filesep 'ROIsAna.mat']);
                anaROI = anaData.ce;
                for r = 1:length(anaROI)
                    try
                    app.ExpData(dend).ROIData(r).isResp = anaROI(r).good;
                    catch
                     app.ExpData(dend).ROIData(r).isResp = 1;
                    end
                    app.ExpData(dend).ROIData(r).prefOri = anaROI(r).prefOri;
                    app.ExpData(dend).ROIData(r).OSI = anaROI(r).OSI;
                    app.ExpData(dend).ROIData(r).prefDir = anaROI(r).prefDir;
                    app.ExpData(dend).ROIData(r).DSI = anaROI(r).DSI;
                    app.ExpData(dend).ROIData(r).DSIvect = anaROI(r).DSIvect;
                end
            end
        end

        % Button pushed function: SaveAlignementButton
        function SaveAlign(app, event)
            %get today's date
            todayDate = today('datetime');
            %save output image
            InVivoImage = app.CellImage;
            imwrite(InVivoImage, [app.posDataPath filesep 'CellAligned_' char(todayDate)  '.tif'], 'tiff');
            
            %save aligned pixels
            CellExpData = app.ExpData;
            save([app.posDataPath filesep 'CellAligned' char(todayDate) '.mat'],'CellExpData', 'InVivoImage');
        end

        % Button pushed function: SavematdataButton
        function SaveMat(app, event)
            %get today's date
            todayDate = today('datetime');
            %save aligned pixels and ROIdata
            CellExpData = app.ExpData;
            InVivoImage = app.CellImage;
            InVivo3D = app.CellImage3D;
            save([app.posDataPath filesep 'CellAligned' char(todayDate) '_All.mat'],'CellExpData', 'InVivoImage', 'InVivo3D');
        end

        % Button pushed function: ExportimageButton
        function ExportImag(app, event)
            %get today's date
            todayDate = today('datetime');
            %which property is selected?
            prop = char(app.SelectedItems.ROIProp);
            %save output image
            F = getframe(app.UICellConst);
            [X, ~] = frame2im(F);
            imwrite(X, [app.posDataPath filesep 'CellAligned_' char(todayDate) '_' char(prop) '.tif'], 'tiff');
        end

        % Button pushed function: Up
        function MoveUp(app, event)
            app.ExpData(app.SelectedItems.SessMoveNr).RelUp = app.ExpData(app.SelectedItems.SessMoveNr).RelUp-app.SelectedItems.MoveInPixel;
            app.ExpData(app.SelectedItems.SessMoveNr).RelDown = app.ExpData(app.SelectedItems.SessMoveNr).RelDown-app.SelectedItems.MoveInPixel;
            % adjust for having to add more space in the overview
            if app.ExpData(app.SelectedItems.SessMoveNr).RelUp < 1
                pixelsToAdd = -app.ExpData(app.SelectedItems.SessMoveNr).RelUp+1;
                for t = 1:size(app.ExpData,2)
                    app.ExpData(t).RelUp = app.ExpData(t).RelUp+pixelsToAdd;
                    app.ExpData(t).RelDown = app.ExpData(t).RelDown+pixelsToAdd;
                end
            end
            UpdateCellConst(app)
        end

        % Button pushed function: Down
        function MoveDown(app, event)
            app.ExpData(app.SelectedItems.SessMoveNr).RelUp = app.ExpData(app.SelectedItems.SessMoveNr).RelUp+app.SelectedItems.MoveInPixel;
            app.ExpData(app.SelectedItems.SessMoveNr).RelDown = app.ExpData(app.SelectedItems.SessMoveNr).RelDown+app.SelectedItems.MoveInPixel;
            UpdateCellConst(app)
        end

        % Button pushed function: Left
        function MoveLeft(app, event)
            app.ExpData(app.SelectedItems.SessMoveNr).RelLeft = app.ExpData(app.SelectedItems.SessMoveNr).RelLeft-app.SelectedItems.MoveInPixel;
            app.ExpData(app.SelectedItems.SessMoveNr).RelRight = app.ExpData(app.SelectedItems.SessMoveNr).RelRight-app.SelectedItems.MoveInPixel;
            % adjust for having to add more space in the overview
            if app.ExpData(app.SelectedItems.SessMoveNr).RelLeft < 1
                pixelsToAdd = -app.ExpData(app.SelectedItems.SessMoveNr).RelLeft+1;
                for t = 1:size(app.SessionData,2)
                    app.ExpData(t).RelLeft = app.ExpData(t).RelLeft+pixelsToAdd;
                    app.ExpData(t).RelRight = app.ExpData(t).RelRight+pixelsToAdd;
                end
            end
            UpdateCellConst(app) 
        end

        % Button pushed function: Right
        function MoveRight(app, event)
            app.ExpData(app.SelectedItems.SessMoveNr).RelLeft = app.ExpData(app.SelectedItems.SessMoveNr).RelLeft+app.SelectedItems.MoveInPixel;
            app.ExpData(app.SelectedItems.SessMoveNr).RelRight = app.ExpData(app.SelectedItems.SessMoveNr).RelRight+app.SelectedItems.MoveInPixel;
            UpdateCellConst(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UICellConst = uiaxes(app.UIFigure);
            app.UICellConst.XTick = [];
            app.UICellConst.YTick = [];
            app.AnimalEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalEditField = uieditfield(app.UIFigure, 'text');
            app.AnimalEditField.Value = 'F2688';
            app.LoadpositionaldataButton = uibutton(app.UIFigure, 'push');
            app.LoadpositionaldataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPosData, true);
            app.LoadpositionaldataButton.Text = 'Load positional data';
            app.LoadfunctionaldataButton = uibutton(app.UIFigure, 'push');
            app.LoadfunctionaldataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFunct, true);
            app.LoadfunctionaldataButton.Text = 'Load functional data';
            app.LoadDataLabel = uilabel(app.UIFigure);
            app.LoadDataLabel.Text = '1. Load Data';
            app.MovesessionsLabel = uilabel(app.UIFigure);
            app.MovesessionsLabel.Text = '2. Move sessions';
            app.ExpDropDownLabel = uilabel(app.UIFigure);
            app.ExpDropDownLabel.Text = 'Exp:';
            app.MoveSelectDropDown = uidropdown(app.UIFigure);
            app.MoveSelectDropDown.Items = {};
            app.MoveSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateMoveSelect, true);
            app.MoveSelectDropDown.Value = {};
            app.Left = uibutton(app.UIFigure, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @MoveLeft, true);
            app.Left.Text = '<';
            app.Right = uibutton(app.UIFigure, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @MoveRight, true);
            app.Right.Text = '>';
            app.Up = uibutton(app.UIFigure, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @MoveUp, true);
            app.Up.Text = '/\';
            app.PixelMove = uieditfield(app.UIFigure, 'numeric');
            app.PixelMove.Limits = [1 50];
            app.PixelMove.ValueChangedFcn = createCallbackFcn(app, @UpdateMovement, true);
            app.PixelMove.Value = 10;
            app.Down = uibutton(app.UIFigure, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @MoveDown, true);
            app.Down.Text = '\/';
            app.SaveAlignementButton = uibutton(app.UIFigure, 'push');
            app.SaveAlignementButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAlign, true);
            app.SaveAlignementButton.Text = 'Save Alignement';
            app.FunctionaldataLabel = uilabel(app.UIFigure);
            app.FunctionaldataLabel.Text = '3. Functional data';
            app.VisibleexperimentsListBoxLabel = uilabel(app.UIFigure);
            app.VisibleexperimentsListBoxLabel.Text = 'Visible experiments';
            app.VisibleexperimentsListBox = uilistbox(app.UIFigure);
            app.VisibleexperimentsListBox.Items = {};
            app.VisibleexperimentsListBox.Multiselect = 'on';
            app.VisibleexperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateVisibleExp, true);
            app.VisibleexperimentsListBox.Value = {};
            app.SelectfunctionaldataDropDownLabel = uilabel(app.UIFigure);
            app.SelectfunctionaldataDropDownLabel.Text = 'Select functional data';
            app.SelectfunctionaldataDropDown = uidropdown(app.UIFigure);
            app.SelectfunctionaldataDropDown.Items = {'No property', 'ROI Positions', 'Responsive', 'Ori Pref', 'OSI', 'Dir Pref', 'DSI'};
            app.SelectfunctionaldataDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateROIProp, true);
            app.SelectfunctionaldataDropDown.Value = 'No property';
            app.SavematdataButton = uibutton(app.UIFigure, 'push');
            app.SavematdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMat, true);
            app.SavematdataButton.Text = 'Save .mat data';
            app.ExportimageButton = uibutton(app.UIFigure, 'push');
            app.ExportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImag, true);
            app.ExportimageButton.Text = 'Export image';
            app.LoadAlignementButton = uibutton(app.UIFigure, 'push');
            app.LoadAlignementButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAlign, true);
            app.LoadAlignementButton.Text = 'Load Alignement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AlignementTwoP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end