classdef ConfTo2pMatching < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartnextButton         matlab.ui.control.Button
        GroupsPanel             matlab.ui.container.Panel
        LoadallButton           matlab.ui.control.Button
        GroupListBox            matlab.ui.control.ListBox
        SaveAllSButton          matlab.ui.control.Button
        UngroupButton           matlab.ui.control.Button
        GroupGButton            matlab.ui.control.Button
        pDataPanel              matlab.ui.container.Panel
        SelectROITButton        matlab.ui.control.Button
        ShowROIsData2CheckBox   matlab.ui.control.CheckBox
        ROIs2pListBox           matlab.ui.control.ListBox
        Button                  matlab.ui.control.Button
        RotateEditField         matlab.ui.control.NumericEditField
        RotateEditFieldLabel    matlab.ui.control.Label
        FlipCheckBox            matlab.ui.control.CheckBox
        MaximumSlider2p         matlab.ui.control.Slider
        MaximumSliderLabel2p    matlab.ui.control.Label
        UIData2                 matlab.ui.control.UIAxes
        ConfocalPanel           matlab.ui.container.Panel
        NewROI                  matlab.ui.control.Button
        DeleteROIButton         matlab.ui.control.Button
        SelectROIConf           matlab.ui.control.Button
        ShowROIsConfCheckBox_2  matlab.ui.control.CheckBox
        ShowROIsConfCheckBox    matlab.ui.control.CheckBox
        ROIsConfocalListBox     matlab.ui.control.ListBox
        ZplaneSlider            matlab.ui.control.Slider
        ZplaneSliderLabel       matlab.ui.control.Label
        MaximumSlider           matlab.ui.control.Slider
        MaximumSliderLabel      matlab.ui.control.Label
        UIData1                 matlab.ui.control.UIAxes
        LoadDataPanel           matlab.ui.container.Panel
        SliceCheckBox           matlab.ui.control.CheckBox
        SliceField              matlab.ui.control.EditField
        CellField               matlab.ui.control.EditField
        CellCheckBox            matlab.ui.control.CheckBox
        BranchesListBox         matlab.ui.control.ListBox
        BranchesListBoxLabel    matlab.ui.control.Label
        LoaddataButton          matlab.ui.control.Button
        pDendriteListBox        matlab.ui.control.ListBox
        pDendriteListBoxLabel   matlab.ui.control.Label
        LoadInfoButton          matlab.ui.control.Button
        AnimalField             matlab.ui.control.EditField
        AnimalEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        TwoPDataPath = []; 
        TwoPImage = [];
        TwoPImageDisplayed = [];
        TwoPData = struct('id', [], 'xPos', [], 'yPos', [], 'mask', [], 'grouped', []);
        ROIs2Image = []; ROIs2ImageMod = [];

        ConfocalPath = []; 
        Confocalmage = []
        DendData = [];
        ConfocalData = struct('xPos', [], 'yPos', [], 'perimeter', [], 'grouped', [])
        cellReconstruction = [];
        
        SelectedItems = struct('ConfBranch', [], 'TwoPDendrite', [], 'Zplane', 1, 'Maximum', 1, 'Maximum2p', 1, 'Flip', 0, 'Angle', 0,...
            'ROIsConf', 1, 'ROIs2p', 1, 'ROINrConf', [], 'ROINr2p', [],'Dendrites', 1)
        %SelectedItems = struct('dendTrace', 1, 'dataType', '2p Data', 'Angle', 0, 'Flip', 0, 'ROIs1', 0, 'ROIs2', 0, 'SelectedROI1', [], 'SelectedROI2', [])
        Groups = struct('GroupID', [], 'ConfID', [], 'TwoPID', [])
        GroupCounter = 0; % Description
        
        tempfolder = []; ZipName = []; ZipOrder = [];
    end
    
    methods (Access = private)
        function UpdateAxisConfocal(app)
            %Delete old traces
            oldImage = findobj(app.UIData1, 'Type', 'Image'); %delete old images
            delete(oldImage)
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            %Update the confocal image
            CellImg = app.Confocalmage(:,:,ceil(app.SelectedItems.Zplane));
            CellImg = mat2gray(CellImg); %should scale the values between 0 and 1
            newImage = imadjust(CellImg, [0; app.SelectedItems.Maximum], []);
            imshow(newImage, 'Parent', app.UIData1);
            axis(app.UIData1, 'image');
            colormap(app.UIData1, 'gray')
        end
        
        function UpdateAxisROIs1(app)
            %update the ROIs on the confocal image
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            oldIDs = findobj(app.UIData1, 'Type', 'text');
            delete(oldIDs)
            hold(app.UIData1, 'on')
            zPlane = ceil(app.SelectedItems.Zplane);
            %update the dendrite tracing on there
            hold(app.UIData1, 'on')
            if app.SelectedItems.Dendrites
                for dend = 1:size(app.DendData,2)
                    plot(app.UIData1, app.DendData(dend).newCoord(:,1),app.DendData(dend).newCoord(:,2), 'LineWidth',1.5, 'color', 'red');
                    hold(app.UIData1, 'on')
                end 
            end
            
            if app.SelectedItems.ROIsConf
                for r = 1:length(app.ConfocalData)
                    %let's check if it is within 5 slices of the z plane
                    if app.ConfocalData(r).zPos >= zPlane - 10 && app.ConfocalData(r).zPos <= zPlane +10
                        groupNr = app.ConfocalData(r).grouped;
                        if groupNr > 0
                            info = app.ConfocalData(r).group(7:end);
                            plot(app.UIData1, app.ConfocalData(r).xPos, app.ConfocalData(r).yPos, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Green');
                            text(app.UIData1, app.ConfocalData(r).xPos-15, app.ConfocalData(r).yPos-15, char(info), ...
                                'Color', 'Green', 'FontSize',9)
                        else
                            plot(app.UIData1, app.ConfocalData(r).xPos, app.ConfocalData(r).yPos, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Blue');
                        end
                        hold(app.UIData1, 'on')
                    end
                end
            end
            
            if ~isempty(app.SelectedItems.ROINrConf)
                hold(app.UIData1, 'on')
                idData = app.SelectedItems.ROINrConf;
                infoData = strsplit(idData, ' - ');
                Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ConfocalData.id});
                ROINr = find(Matches,1);
                plot(app.UIData1, app.ConfocalData(ROINr).xPos, app.ConfocalData(ROINr).yPos, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Yellow');
            end
        end
        function UpdateAxisTwoP(app)
            %update the confocal image
            oldImage = findobj(app.UIData2, 'Type', 'Image'); %delete old images
            delete(oldImage)
            oldROIs = findobj(app.UIData2, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            ExpImage = mat2gray(app.TwoPImageDisplayed); %should scale the values between 0 and 1
            newImage = imadjust(ExpImage, [0; app.SelectedItems.Maximum2p], []);
            
            imshow(newImage, 'Parent', app.UIData2);
            axis(app.UIData2, 'image');
            colormap(app.UIData2, 'gray')
        end
        function UpdateAxisROIs2(app)
            %update the ROIs on the confocal image
            oldROIs = findobj(app.UIData2, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            oldIDs = findobj(app.UIData2, 'Type', 'text');
            delete(oldIDs)
            hold(app.UIData2, 'on')
            if app.SelectedItems.ROIs2p
                for r = 1:length(app.TwoPData)
                    if app.TwoPData(r).spine
                        groupNr = app.TwoPData(r).grouped;
                        if groupNr > 0
                            info = app.TwoPData(r).group(7:end);
                            plot(app.UIData2, app.TwoPData(r).xPosNew, app.TwoPData(r).yPosNew, 'ok', 'MarkerSize',3', 'MarkerFaceColor','green')
                            text(app.UIData2, app.TwoPData(r).xPosNew-10, app.TwoPData(r).yPosNew-10, char(info), ...
                                'Color', 'Green', 'FontSize',9)
                        else
                                plot(app.UIData2, app.TwoPData(r).xPosNew, app.TwoPData(r).yPosNew, 'ok', 'MarkerSize',3', 'MarkerFaceColor','blue')
                        end
                        hold(app.UIData2, 'on')
                    end
                end
            end
            if ~isempty(app.SelectedItems.ROINr2p)
                hold(app.UIData2, 'on')
                idData = app.SelectedItems.ROINr2p;
                infoData = strsplit(idData, ' - ');
                Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.TwoPData.id});
                ROINr = find(Matches,1);
                plot(app.UIData2, app.TwoPData(ROINr).xPosNew, app.TwoPData(ROINr).yPosNew, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Yellow');
            end
        end
        function flipAndRotate(app)
            %makes sure to reset the ROI positions
            for r = 1:length(app.TwoPData)
               app.TwoPData(r).xPosNew = app.TwoPData(r).xPos;
               app.TwoPData(r).yPosNew = app.TwoPData(r).yPos; 
            end
            app.TwoPImageDisplayed = app.TwoPImage;
            app.ROIs2ImageMod = app.ROIs2Image;
            
            %flip ROI positions if checked
            if app.SelectedItems.Flip == 1
                for r = 1:length(app.TwoPData)
                    app.TwoPData(r).xPosNew = size(app.TwoPImageDisplayed,2)-app.TwoPData(r).xPosNew;
                end
                app.TwoPImageDisplayed = fliplr(app.TwoPImageDisplayed);
                app.ROIs2ImageMod = fliplr(app.ROIs2ImageMod);
            end
            
            if app.SelectedItems.Angle ~= 0 
                %transform angle into radius
                rads=2*pi*app.SelectedItems.Angle/360; 
                
                %get the original image size and calculate the new dimensions
                [rowsOri,colsOri,~]= size(app.TwoPImage);
                rowsNew=ceil(rowsOri*abs(cos(rads))+colsOri*abs(sin(rads)));                      
                colsNew=ceil(rowsOri*abs(sin(rads))+colsOri*abs(cos(rads))); 
                
                %calculate the center of original image and of final image
                xMidOri=ceil(rowsOri/2);                                                            
                yMidOri=ceil(colsOri/2);
                
                xMidNew=ceil(rowsNew/2);
                yMidNew=ceil(colsNew/2);
                
                %same time, rotating the old imag
                tempImageAxis=zeros(rowsNew, colsNew);
                tempImageROIs=tempImageAxis;
                for xNew = 1:rowsNew
                    for yNew = 1:colsNew
                        xOri = (xNew-xMidNew)*cos(rads)+(yNew-yMidNew)*sin(rads);
                        yOri = -(xNew-xMidNew)*sin(rads)+(yNew-yMidNew)*cos(rads);
                        xOri = round(xOri)+xMidOri;
                        yOri = round(yOri)+yMidOri;
                        
                        if xOri>=1 && yOri >1 && xOri<=rowsOri && yOri<=colsOri
                            tempImageAxis(xNew, yNew)=app.TwoPImageDisplayed(xOri,yOri);
                            tempImageROIs(xNew, yNew)=app.ROIs2ImageMod(xOri,yOri);
                        end
                    end
                end
                
                app.TwoPImageDisplayed = tempImageAxis;
                app.ROIs2ImageMod = tempImageROIs;
                for r = 1:length(app.TwoPData)
                    [UpInd,LeftInd] = find(uint16(app.ROIs2ImageMod) == str2double(app.TwoPData(r).id));
                    app.TwoPData(r).yPosNew = ceil(mean(UpInd));
                    app.TwoPData(r).xPosNew = ceil(mean(LeftInd)); 
                end
            end
            UpdateAxisTwoP(app)
            UpdateAxisROIs2(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadInfoButton
        function LoadInfoButtonPushed(app, event)
            %load 2p info -> how many experiments are there?
            [~, xls_txt, xls_all]=xlsread('Z:\Juliane\Organization\Animals\SpinePerAnimal.xlsx', 'bimodal'); %open the sheet with the exp info
            animalCol = find(contains(xls_txt(1,:),'animal'),1); %which column is the animal name?
            dendriteCol = find(contains(xls_txt(1,:),'dendrite'),1); %dendrite ROI
            nameCol = find(contains(xls_txt(1,:),'name'),1); %exp Name
            
            animalExp = cellfun(@(x) find(contains(x, char(app.AnimalField.Value))),xls_txt(:,animalCol),'UniformOutput',false); %find the ones that contain the animal
            animalIDs = cellfun(@(x) ~isempty(x), animalExp);
            dendriteROIsAll = cell2mat(xls_all(animalIDs,dendriteCol)); %find the corresponding dendrite ROIs and exp Names
            expNrAll =  cell2mat(xls_all(animalIDs,nameCol));
            expNrDend = expNrAll(dendriteROIsAll>0,:); %filter for the ones that are dendrite ROIs
            %write list of selectable dendrites to confocal selector
            app.pDendriteListBox.Items = string(expNrDend);
            
            
            %load cell reconstruction -> how many branches are there?
            ConfocalDir = 'Z:\Juliane\InputAnalysis\';
            animalName = char(app.AnimalField.Value);
            if app.CellCheckBox.Value == 1
                cellName = app.CellField.Value;
                if app.SliceCheckBox.Value == 1
                    sliceName = app.SliceField.Value;
                    app.ConfocalPath = [char(ConfocalDir) char(animalName) '/B - Confocal/' char(cellName) filesep char(sliceName) filesep]; 
                else
                    app.ConfocalPath = [char(ConfocalDir) char(animalName) '/B - Confocal/' char(cellName) filesep];
                end
            else
                if app.SliceCheckBox.Value == 1
                    sliceName = app.SliceField.Value;
                    app.ConfocalPath = [char(ConfocalDir) char(animalName) '/B - Confocal/' char(sliceName) filesep];
                else
                    app.ConfocalPath = [char(ConfocalDir) char(animalName) '/B - Confocal/'];
                end
            end
            app.cellReconstruction = load([app.ConfocalPath 'cellReconstruction.mat']);
            
            %write list of selectable dendrites to confocal selector
            app.BranchesListBox.Items = string(unique([app.cellReconstruction.allDendrites.Branch]));
            
            %switch Button appearances 
            app.LoaddataButton.Visible = 'on';
            app.LoadInfoButton.Enable = 'off';
            app.StartnextButton.Visible = 'on';
        end

        % Button pushed function: LoaddataButton
        function LoadData(app, event)
            %confocal data -> first load the image
            Path = app.ConfocalPath;
            fileName =dir([Path '\*.tif']);
            fileName = fileName.name;
            %[fileName, Path] = uigetfile('*.tif', 'Select confocal image',app.ConfocalPath);
            %only need to load a partial part of the image
            
            selectedBranch = cellfun(@(x) find(contains(x, char(app.BranchesListBox.Value))),app.BranchesListBox.Items,'UniformOutput',false);
            BranchID = find(cellfun(@(x) ~isempty(x), selectedBranch) == 1);
            
            BranchInfo = app.cellReconstruction.Branches(BranchID);
            if BranchInfo.zMin < 1
                BranchInfo.zMin = 1;
            end
            xSize = BranchInfo.xMax - BranchInfo.xMin+1;
            ySize = BranchInfo.yMax - BranchInfo.yMin+1;
            zSize = BranchInfo.zMax - BranchInfo.zMin+1;
            zSlices = linspace(BranchInfo.zMin,BranchInfo.zMax, zSize);
            app.Confocalmage = zeros(ySize, xSize, zSize);
            for k = 1:length(zSlices)
                tifFile = imread([Path fileName], zSlices(k));
                app.Confocalmage(:,:,k) = mat2gray(tifFile(BranchInfo.yMin:BranchInfo.yMax,BranchInfo.xMin:BranchInfo.xMax)); %scales it for better viewability
            end
            %now the dendrite tracing for that image -> make sure that you
            %modify the positions accordingly to fit the image
            for d = 1:size(BranchInfo.dendrites,2)
                app.DendData(d).newCoord = bsxfun(@minus, BranchInfo.dendrites(d).pixelCoord,[BranchInfo.xMin,BranchInfo.yMin]);
            end
            for R = 1:size(BranchInfo.ROIs,2)
                app.ConfocalData(R).id = num2str(BranchInfo.ROIs(R).ROINrOnBranch);
                app.ConfocalData(R).group = 'No group';
                app.ConfocalData(R).label = [app.ConfocalData(R).id ' - ' app.ConfocalData(R).group];
                app.ConfocalData(R).xPos = BranchInfo.ROIs(R).xPos-BranchInfo.xMin;
                app.ConfocalData(R).yPos = BranchInfo.ROIs(R).yPos-BranchInfo.yMin;
                %let's get the z pos as well
                app.ConfocalData(R).zPos = BranchInfo.ROIs(R).slice-BranchInfo.zMin;
                app.ConfocalData(R).perimeter = BranchInfo.ROIs(R).perimeter;
                app.ConfocalData(R).grouped = 0;
            end
            
            %to be able to add and delete ROIs and still be updating the
            %ROIzip file, unzip that data into a temp folder
            app.tempfolder = [app.ConfocalPath filesep 'temp\'];
            if ~exist( app.tempfolder, 'dir') %if it doesn't exist, make it
                mkdir( app.tempfolder)
            else
                delete([ app.tempfolder '\*.roi']) %otherwise empty it
            end
            zipFilesAll = dir([Path filesep 'Spines']);
            zipFile = find(cellfun(@(x) contains(x, ['Dendrite' sprintf('%02d',str2double(app.SelectedItems.ConfBranch))]),{zipFilesAll.name}));
            app.ZipName = [zipFilesAll(zipFile).name];
            app.ZipOrder = unzip([Path filesep 'Spines' filesep app.ZipName],  app.tempfolder);
            
            %load the data into the axis
            app.ConfocalPanel.Visible  ='on';
            app.UIData1.Visible = 'on';
            UpdateAxisConfocal(app)
            UpdateAxisROIs1(app)
            app.BranchesListBox.Enable = 'off';
            app.ZplaneSlider.Limits = [1, size(app.Confocalmage,3)];
            app.ROIsConfocalListBox.Items = {app.ConfocalData.label};
            
            
            % 2p data -> first find the corresponding folder
            TwoPhontondir = 'Z:\Juliane\InputAnalysis\';
            allFolders = dir(TwoPhontondir);
            folderInfo = cellfun(@(x) find(contains(x, char(app.AnimalField.Value))),{allFolders.name},'UniformOutput',false);
            notFolder = cellfun(@isempty,folderInfo); %all other cells are empty
            indFolder = find(~notFolder);
            app.TwoPDataPath = [char(TwoPhontondir) char(allFolders(indFolder).name) filesep 'A - 2p Imaging' filesep char(app.SelectedItems.TwoPDendrite) filesep];
            temp = load([app.TwoPDataPath 'meanImg.mat']); %load the image
            app.TwoPImage = temp.meanImg; clear temp;
            app.TwoPImageDisplayed = app.TwoPImage;
            app.ROIs2Image = zeros(size(app.TwoPImage,1), size(app.TwoPImage,2)); %this is for rotating the image
            temp = load([app.TwoPDataPath 'ROIs.mat']); %load the ROIs and write them into the struct
            for ROI = 1:size(temp.ce,2)
                app.TwoPData(ROI).id = char(sprintf( '%02d', ROI));
                if temp.ce(ROI).dendrite %make sure that the dendrite ROIs won't get matched
                    app.TwoPData(ROI).group = 'Dendrite ROI';
                    app.TwoPData(ROI).xPos = [];
                    app.TwoPData(ROI).yPos = [];
                else
                    app.TwoPData(ROI).group = 'No group';
                    app.TwoPData(ROI).xPos = temp.ce(ROI).xPos;
                    app.TwoPData(ROI).yPos = temp.ce(ROI).yPos;
                    app.TwoPData(ROI).mask = temp.ce(ROI).mask;                   
                    app.ROIs2Image(ceil(app.TwoPData(ROI).yPos)-1:ceil(app.TwoPData(ROI).yPos)+1,ceil(app.TwoPData(ROI).xPos)-1:ceil(app.TwoPData(ROI).xPos)+1)=ROI;
                end
                app.TwoPData(ROI).label = [(app.TwoPData(ROI).id) ' - ' app.TwoPData(ROI).group];
                app.TwoPData(ROI).yPosNew = app.TwoPData(ROI).yPos;
                app.TwoPData(ROI).xPosNew = app.TwoPData(ROI).xPos; %this is to be able to rotate and flip the image
                app.TwoPData(ROI).grouped = 0;  
                app.TwoPData(ROI).spine = temp.ce(ROI).spine;  
            end
            app.ROIs2ImageMod = app.ROIs2Image; %this is for rotating the ROIs
            clear temp
            %load the data into the axis
            app.pDataPanel.Visible = 'on';
            app.UIData2.Visible = 'on';
            app.ROIs2pListBox.Items = {app.TwoPData.label};
            UpdateAxisTwoP(app)
            UpdateAxisROIs2(app)
            
            app.pDendriteListBox.Enable = 'off';
            app.LoaddataButton.Enable = 'off';
        end

        % Value changed function: BranchesListBox
        function UpdateConfBranch(app, event)
            app.SelectedItems.ConfBranch = app.BranchesListBox.Value;
        end

        % Value changed function: pDendriteListBox
        function UpdateTwoPBranch(app, event)
            app.SelectedItems.TwoPDendrite = app.pDendriteListBox.Value;
        end

        % Value changed function: ZplaneSlider
        function UpdateZPlane(app, event)
            app.SelectedItems.Zplane =app.ZplaneSlider.Value;
            UpdateAxisConfocal(app)
            UpdateAxisROIs1(app)
        end

        % Value changed function: MaximumSlider
        function UpdateMaximumConf(app, event)
            app.SelectedItems.Maximum = app.MaximumSlider.Value;
            UpdateAxisConfocal(app)
            UpdateAxisROIs1(app)
        end

        % Callback function
        function UpdateBrightnessConf(app, event)
            app.SelectedItems.Brightness = app.BrightnessSlider.Value;
            UpdateAxisConfocal(app)
            UpdateAxisROIs1(app)
        end

        % Value changed function: MaximumSlider2p
        function UpdateMaximum2p(app, event)
            app.SelectedItems.Maximum2p = app.MaximumSlider2p.Value;
            UpdateAxisTwoP(app)
            UpdateAxisROIs2(app)
        end

        % Callback function
        function UpdateBrightness2p(app, event)
            app.SelectedItems.Brightness2p = app.BrightnessSlider2p.Value;
            UpdateAxisTwoP(app)
            UpdateAxisROIs2(app)
        end

        % Value changed function: ShowROIsData2CheckBox
        function UpdateROIs2p(app, event)
            app.SelectedItems.ROIs2p = app.ShowROIsData2CheckBox.Value;
            UpdateAxisROIs2(app)
        end

        % Value changed function: ShowROIsConfCheckBox
        function UpdateROIsConf(app, event)
            app.SelectedItems.ROIsConf = app.ShowROIsConfCheckBox.Value;
            UpdateAxisROIs1(app)
        end

        % Value changed function: RotateEditField
        function UpdateAngle(app, event)
            app.SelectedItems.Angle = app.RotateEditField.Value;
            flipAndRotate(app)
        end

        % Value changed function: FlipCheckBox
        function UpdateFlip(app, event)
            app.SelectedItems.Flip = app.FlipCheckBox.Value;
            flipAndRotate(app)
        end

        % Value changed function: ROIsConfocalListBox
        function UpdateROINrConf(app, event)
            app.SelectedItems.ROINrConf = app.ROIsConfocalListBox.Value;
            UpdateAxisROIs1(app)
        end

        % Value changed function: ROIs2pListBox
        function UpdateROINr2p(app, event)
            app.SelectedItems.ROINr2p= app.ROIs2pListBox.Value;
            UpdateAxisROIs2(app)
        end

        % Value changed function: ShowROIsConfCheckBox_2
        function UpdateShowDend(app, event)
            app.SelectedItems.Dendrites = app.ShowROIsConfCheckBox_2.Value;
            UpdateAxisROIs1(app)
        end

        % Button pushed function: Button
        function StopRotation(app, event)
            app.RotateEditField.Enable = 'off';
            app.FlipCheckBox.Enable = 'off';
            app.GroupsPanel.Visible = 'On';
            app.StartnextButton.Visible = 'on';
        end

        % Button pushed function: GroupGButton
        function GroupROIs(app, event)
            idData1 = app.SelectedItems.ROINrConf;
            infoData1 = strsplit(idData1, ' - ');
            idData2 = app.SelectedItems.ROINr2p;
            infoData2 = strsplit(idData2, ' - ');
            
            %get the group number
            newGroupID = app.GroupCounter + 1;
            if app.GroupCounter == 0
                groupLength = 1; %this is to make sure that there is not an empty row inbetween
            else
                groupLength = size(app.Groups,2)+1; %this is to make sure that there is not an empty row inbetween
            end
            
            %write the new group data into app.Group
            app.Groups(groupLength).GroupID = ['Group ' num2str(newGroupID)];
            app.Groups(groupLength).ConfID = infoData1{1};
            app.Groups(groupLength).TwoPID = infoData2{1};
            
            %update the group number and labels in the two datasets
            ConfROINrMatches =  cellfun(@(x) strcmp(x, infoData1{1}),{app.ConfocalData.id});
            ConfROINrinStruct = find(ConfROINrMatches,1);
            app.ConfocalData(ConfROINrinStruct).group = ['Group ' num2str(newGroupID)];
            app.ConfocalData(ConfROINrinStruct).label = [app.ConfocalData(ConfROINrinStruct).id ' - ' app.ConfocalData(ConfROINrinStruct).group];
            app.ConfocalData(ConfROINrinStruct).grouped = 1;
            Data2ROINrMatches =  cellfun(@(x) strcmp(x, infoData2{1}),{app.TwoPData.id});
            Data2ROINrinStruct = find(Data2ROINrMatches,1);
            app.TwoPData(Data2ROINrinStruct).group = ['Group ' num2str(newGroupID)];
            app.TwoPData(Data2ROINrinStruct).label = [app.TwoPData(Data2ROINrinStruct).id ' - ' app.TwoPData(Data2ROINrinStruct).group];
            app.TwoPData(Data2ROINrinStruct).grouped = 1;
            
            %update the listboxes
            app.ROIsConfocalListBox.Items = {app.ConfocalData.label};
            app.ROIs2pListBox.Items = {app.TwoPData.label};
            app.GroupListBox.Items = {app.Groups.GroupID};
            app.GroupCounter = app.GroupCounter+1;
            
            %update the ROIs
            UpdateAxisROIs1(app)
            UpdateAxisROIs2(app)
        end

        % Button pushed function: UngroupButton
        function UngroupROIs(app, event)
            oldGroupNr = app.GroupListBox.Value;
                        
            %delete the group
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.Groups.GroupID});
            GroupID = find(GroupMatches,1);
            app.Groups(GroupID)= [];
                
            %delete the corresponding group in Confocal data
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.ConfocalData.group});
            GroupID = find(GroupMatches,1);
            app.ConfocalData(GroupID).group = 'No group';
            app.ConfocalData(GroupID).label = [app.ConfocalData(GroupID).id ' - ' app.ConfocalData(GroupID).group];
            app.ConfocalData(GroupID).grouped = 0;
             
            %delete the corresponding group in data 2
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.TwoPData.group});
            GroupID = find(GroupMatches,1);
            app.TwoPData(GroupID).group = 'No group';
            app.TwoPData(GroupID).label = [app.TwoPData(GroupID).id ' - ' app.TwoPData(GroupID).group];
            app.TwoPData(GroupID).grouped = 0;
            
            %update the listboxes
            app.ROIsConfocalListBox.Items = {app.ConfocalData.label};
            app.ROIs2pListBox.Items = {app.TwoPData.label};
            app.GroupListBox.Items = {app.Groups.GroupID};
            
            %update the ROIs
            UpdateAxisROIs1(app)
            UpdateAxisROIs2(app)
        end

        % Button pushed function: SaveAllSButton
        function Save(app, event)
            %get today's date
            todayDate = today('datetime');
            if app.CellCheckBox.Value == 1
                 if app.SliceCheckBox.Value == 1
                     AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) filesep char(app.CellField.Value) filesep '\D - Alignments\' char(app.SliceField.Value) filesep 'Alignment 2p' filesep];
                 else
                     AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) filesep char(app.CellField.Value) '\D - Alignments\Alignment 2p' filesep];    
                 end
            else
               if app.SliceCheckBox.Value == 1
                   AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) filesep '\D - Alignments\' char(app.SliceField.Value) filesep 'Alignment 2p' filesep];    
               else
                   AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) '\D - Alignments\Alignment 2p' filesep];
               end
            end
            
            if ~exist(AnalysisDir, 'dir')
                mkdir(AnalysisDir)
            end
            
            %write a new variable with everything ordered by groups
            MatchData = struct;
            try
                for m = 1:length(app.Groups)
                    MatchData(m).GroupID = app.Groups(m).GroupID;
                    %add in the confocal Data Info
                    MatchData(m).ConfocalID = app.Groups(m).ConfID;
                    %add in 2p data
                    MatchData(m).TwoPID = app.Groups(m).TwoPID;
                end
            catch
                disp('No groups yet')
            end
            ConfocalDataT = app.ConfocalData; GroupsT = app.Groups; TwoPDataT = app.TwoPData;
            Name = ['Groups_Dendrite' sprintf( '%02d',str2double(app.SelectedItems.ConfBranch)) '_Exp' char(app.SelectedItems.TwoPDendrite) '_'];
            %save all
            save([AnalysisDir char(Name) char(todayDate) '.mat'],'TwoPDataT', 'ConfocalDataT', 'GroupsT', 'MatchData');
            
            %update the ROIfile in the save folder in case that some ROIs
            %were added to it and app.cellReconstruction
            cd(app.tempfolder)
            zip(app.ZipName, app.ZipOrder) %makes the zip file
            movefile(app.ZipName,[app.ConfocalPath filesep 'Spines']); %moves it to where it was beforehand
            [parentFolder, ~] = fileparts(cd);
            cd(parentFolder)
            rmdir 'temp' s
        end

        % Button pushed function: LoadallButton
        function Load(app, event)
            if app.CellCheckBox.Value == 1
                 if app.SliceCheckBox.Value == 1
                     AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) filesep char(app.CellField.Value) filesep '\D - Alignments\' char(app.SliceField.Value) filesep 'Alignment 2p' filesep];
                 else
                     AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) filesep char(app.CellField.Value) '\D - Alignments\Alignment 2p' filesep];    
                 end
            else
               if app.SliceCheckBox.Value == 1
                   AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) filesep '\D - Alignments\' char(app.SliceField.Value) filesep 'Alignment 2p' filesep];    
               else
                   AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) '\D - Alignments\Alignment 2p' filesep];
               end
            end
            
            [fileName, Path] = uigetfile('*.mat', 'Select mat-file for reading in aligned ROIs',AnalysisDir);
            temp = load([Path fileName]);
            
            app.TwoPData = temp.TwoPDataT;
            app.ConfocalData = temp.ConfocalDataT;
            app.Groups = temp.GroupsT;
            
            %update groupNr Counter
            app.GroupCounter = str2double(temp.GroupsT(end).GroupID(7:end));
            
            %update Listboxes
            app.ROIsConfocalListBox.Items = {app.ConfocalData.label};
            app.ROIs2pListBox.Items = {app.TwoPData.label};
            try
                app.GroupListBox.Items = {app.Groups.GroupID};
            catch
                disp('No groups found')
            end
            app.SelectedItems.ROINrConf = [];
            app.SelectedItems.ROINr2p = [];
            
            %update Axis1
            UpdateAxisConfocal(app)
            UpdateAxisROIs1(app)
            
            %update Axis2
            UpdateAxisTwoP(app)
            UpdateAxisROIs2(app)
        end

        % Button pushed function: StartnextButton
        function StartNew(app, event)
            %set panels etc to reset values
            app.MaximumSlider.Value = app.SelectedItems.Maximum;
            app.MaximumSlider2p.Value = app.SelectedItems.Maximum2p;
            app.FlipCheckBox.Value = app.SelectedItems.Flip;
            app.RotateEditField.Value = app.SelectedItems.Angle;
            app.BranchesListBox.Value = app.SelectedItems.ConfBranch;
            app.pDendriteListBox.Value = app.SelectedItems.TwoPDendrite;
            
            %delete all data for the specific ones
            app.TwoPData = []; app.TwoPImage = []; app.TwoPImageDisplayed = [];
            app.ConfocalData = []; app.Confocalmage = []; app.DendData = [];
            app.Groups = struct('GroupID', [], 'ConfID', [], 'TwoPID', []); app.GroupCounter = 0;
            app.SelectedItems = struct('ConfBranch', [], 'TwoPDendrite', [], 'Zplane', 1, 'Maximum', 1, 'Maximum2p', 1, 'Flip', 0, 'Angle', 0,...
            'ROIsConf', 1, 'ROIs2p', 1, 'ROINrConf', [], 'ROINr2p', [],'Dendrites', 1);
            
            %switch off the panels
            app.GroupsPanel.Visible = 'off';
            app.ConfocalPanel.Visible = 'off';
            app.pDataPanel.Visible = 'off';
            
            %switch on the initial panel
            app.BranchesListBox.Enable = 'on';
            app.pDendriteListBox.Enable = 'on';
            app.LoaddataButton.Enable = 'on';
            
            %enable the rotation & flip
            app.RotateEditField.Enable = 'on';
            app.FlipCheckBox.Enable = 'on';
            app.GroupListBox.Items = {};
        end

        % Button pushed function: SelectROITButton
        function SelectROI(app, event)
            roiPOC = drawpoint(app.UIData2);    %Use Mouse To Select a point ROI
            PosPOC=round(get(roiPOC,'Position')); %Extract Coordinates of the point ROI
            Point = findobj(app.UIData2, 'Type', 'images.roi.point'); %delete old images
            delete(Point)
            %find nearest ROI
            distToROIs = zeros(size(app.TwoPData,2),1);
            for c = 1:size(app.TwoPData,2)
                if ~isempty(app.TwoPData(c).xPosNew) %dendriteROIs are empty, so put those upt to a big value
                    distToROIs(c) = sqrt((app.TwoPData(c).xPosNew - PosPOC(1))^2+(app.TwoPData(c).yPosNew - PosPOC(2))^2);
                else
                    distToROIs(c) = 50000;
                end
            end
            [~,ID] = min(distToROIs);
            %update listbox and axis
            app.ROIs2pListBox.Value = app.ROIs2pListBox.Items(ID);
            app.SelectedItems.ROINr2p = app.ROIs2pListBox.Value; 
            UpdateAxisROIs2(app)
        end

        % Button pushed function: SelectROIConf
        function SelectROIConfocal(app, event)
            roiPOC = drawpoint(app.UIData1);    %Use Mouse To Select a point ROI
            PosPOC=round(get(roiPOC,'Position')); %Extract Coordinates of the point ROI
            Point = findobj(app.UIData1, 'Type', 'images.roi.point'); %delete old images
            delete(Point)
            %find nearest ROI
            distToROIs = zeros(size(app.ConfocalData,2),1);
            for c = 1:size(app.ConfocalData,2)
                distToROIs(c) = sqrt((app.ConfocalData(c).xPos - PosPOC(1))^2+(app.ConfocalData(c).yPos - PosPOC(2))^2);
            end
            [~,ID] = min(distToROIs);
            %update listbox and axis
            app.ROIsConfocalListBox.Value = app.ROIsConfocalListBox.Items(ID);
            app.SelectedItems.ROINrConf = app.ROIsConfocalListBox.Value; 
            UpdateAxisROIs1(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            %app.Message.Value = key;
            switch key
                case 't'
                    app.SelectROI(app)
                case 'c'
                    app.SelectROIConfocal(app)
                case 'g'
                    app.GroupROIs(app)
                case 's'
                    app.Save(app)
            end
        end

        % Button pushed function: NewROI
        function NewROIPushed(app, event)
            roiNew = drawcircle(app.UIData1);    %Use Mouse To Select a draw ROI
            PosPOC=round(get(roiNew,'Position')); %Extract Coordinates of the  ROI
            r = get(roiNew, 'Radius'); %get radius to calculate perimeter
            theta = linspace(pi/2,2*pi+pi/2,30);
            x= r *cos(theta)+PosPOC(1);
            y = r *sin(theta)+PosPOC(2);
            
            Point = findobj(app.UIData1, 'Type', 'images.roi.circle'); %delete old images
            delete(Point)
            
            %find length of current ROIs to get ID on Branch
            numROIs = size(app.ConfocalData,2);
            IDoflastROI = app.ConfocalData(numROIs).id;
            
            %add the coordinates and perimeter to it
            app.ConfocalData(numROIs+1).id = num2str(str2double(IDoflastROI)+1);
            app.ConfocalData(numROIs+1).group = 'No group';
            app.ConfocalData(numROIs+1).label = [app.ConfocalData(numROIs+1).id ' - ' app.ConfocalData(numROIs+1).group];
            app.ConfocalData(numROIs+1).xPos = PosPOC(1);
            app.ConfocalData(numROIs+1).yPos = PosPOC(2);
            app.ConfocalData(numROIs+1).zPos = app.SelectedItems.Zplane;
            
            %for the perimeter, make sure to add the real position
            %information
            BranchInfo = app.cellReconstruction.Branches(str2double(app.SelectedItems.ConfBranch));
            app.ConfocalData(numROIs+1).perimeter =[y+BranchInfo.yMin;x+BranchInfo.xMin]';
            app.ConfocalData(numROIs+1).grouped = 0;
            
            %make a .roi file and add it to the folder with all the other
            %ROIs, add it also to the ziporder
            zipName = writeImageJROI(app.ConfocalData(numROIs+1).perimeter, 2, 1, ceil(app.SelectedItems.Zplane), 0,[app.tempfolder filesep]);
            app.ZipOrder(size(app.ZipOrder,2)+1) = {[app.tempfolder filesep zipName '.roi']};
            
            %add it to the list of ROIs and draw ROI
            app.ROIsConfocalListBox.Items = {app.ConfocalData.label};
            app.ROIsConfocalListBox.Value = app.ROIsConfocalListBox.Items(end);
            app.SelectedItems.ROINrConf = app.ROIsConfocalListBox.Value; 
            UpdateAxisROIs1(app)
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            ROItoDel = app.SelectedItems.ROINrConf; %find which ROI it is
            
            %find it in the ConfocalData
            id = find(cellfun(@(x) strcmp(x, ROItoDel),{app.ConfocalData.label}));

            
            %delete the .roi file
            %do find it, you have to look which name comes closes to the
            %x and y position
            yCenter = ceil(((max(app.ConfocalData(id).perimeter(:,2))-min(app.ConfocalData(id).perimeter(:,2)))/2)+min(app.ConfocalData(id).perimeter(:,2)));
            xCenter = ceil(((max(app.ConfocalData(id).perimeter(:,1))-min(app.ConfocalData(id).perimeter(:,1)))/2)+min(app.ConfocalData(id).perimeter(:,1)));
            
            %go through all RoiFiles 
            Files = dir(app.tempfolder);
            ROIFiles = find(cellfun(@(x) contains(x, 'roi'),{Files.name}));
            minDist = zeros(1,length(ROIFiles));
            for r = 1:length(ROIFiles)
                xComp = str2double(Files(ROIFiles(r)).name(6:9));
                yComp = str2double(Files(ROIFiles(r)).name(11:14));
                minDist(r) = sqrt((yCenter-yComp)^2+(xCenter-xComp)^2);
            end
            [~, minDisAll] = min(minDist);
            
            %delete RoiFile & remove the entry from the zipOrder
            delete([app.tempfolder filesep Files(ROIFiles(minDisAll)).name])
            toDelete = find(cell2mat(cellfun(@(x) contains(x, Files(ROIFiles(minDisAll)).name),{app.ZipOrder},'UniformOutput',false))==1);
            app.ZipOrder(toDelete) = [];

            %change the id of all following ROIs
            for r = id+1:size(app.ConfocalData,2)
                app.ConfocalData(r).id = num2str(str2double(app.ConfocalData(r).id)-1);
                app.ConfocalData(r).label = [app.ConfocalData(r).id ' - ' app.ConfocalData(r).group];
            end
            
            %delete the confocal data
            app.ConfocalData(id) = [];
            
            %update the list and image
            app.ROIsConfocalListBox.Items = {app.ConfocalData.label};
            app.SelectedItems.ROINrConf = [];
            UpdateAxisROIs1(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load Data:';
            app.AnimalEditFieldLabel = uilabel(app.LoadDataPanel);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalField = uieditfield(app.LoadDataPanel, 'text');
            app.AnimalField.Value = 'F2715';
            app.LoadInfoButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfoButtonPushed, true);
            app.LoadInfoButton.Text = 'Load Info';
            app.pDendriteListBoxLabel = uilabel(app.LoadDataPanel);
            app.pDendriteListBoxLabel.Text = '2p Dendrite';
            app.pDendriteListBox = uilistbox(app.LoadDataPanel);
            app.pDendriteListBox.Items = {};
            app.pDendriteListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateTwoPBranch, true);
            app.pDendriteListBox.Value = {};
            app.LoaddataButton = uibutton(app.LoadDataPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoaddataButton.Visible = 'off';
            app.LoaddataButton.Text = 'Load data';
            app.BranchesListBoxLabel = uilabel(app.LoadDataPanel);
            app.BranchesListBoxLabel.Text = 'Branches:';
            app.BranchesListBox = uilistbox(app.LoadDataPanel);
            app.BranchesListBox.Items = {};
            app.BranchesListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateConfBranch, true);
            app.BranchesListBox.Value = {};
            app.CellCheckBox = uicheckbox(app.LoadDataPanel);
            app.CellCheckBox.Text = 'Cell';
            app.CellField = uieditfield(app.LoadDataPanel, 'text');
            app.CellField.Value = 'Cell right';
            app.SliceField = uieditfield(app.LoadDataPanel, 'text');
            app.SliceField.Value = 'SL03';
            app.SliceCheckBox = uicheckbox(app.LoadDataPanel);
            app.SliceCheckBox.Text = 'Slice';
            app.SliceCheckBox.Value = true;
            app.ConfocalPanel = uipanel(app.UIFigure);
            app.ConfocalPanel.Title = 'Confocal';
            app.ConfocalPanel.Visible = 'off';
            app.UIData1 = uiaxes(app.ConfocalPanel);
            title(app.UIData1, 'Confocal Data')
            app.UIData1.XTick = [];
            app.UIData1.YTick = [];
            app.UIData1.Visible = 'off';
            app.MaximumSliderLabel = uilabel(app.ConfocalPanel);
            app.MaximumSliderLabel.Text = 'Maximum';
            app.MaximumSlider = uislider(app.ConfocalPanel);
            app.MaximumSlider.Limits = [0 1];
            app.MaximumSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.MaximumSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateMaximumConf, true);
            app.ZplaneSliderLabel = uilabel(app.ConfocalPanel);
            app.ZplaneSliderLabel.Text = 'Z plane';
            app.ZplaneSlider = uislider(app.ConfocalPanel);
            app.ZplaneSlider.Limits = [0 20];
            app.ZplaneSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateZPlane, true);
            app.ROIsConfocalListBox = uilistbox(app.ConfocalPanel);
            app.ROIsConfocalListBox.Items = {};
            app.ROIsConfocalListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROINrConf, true);
            app.ROIsConfocalListBox.Value = {};
            app.ShowROIsConfCheckBox = uicheckbox(app.ConfocalPanel);
            app.ShowROIsConfCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROIsConf, true);
            app.ShowROIsConfCheckBox.Text = 'Show ROIs';
            app.ShowROIsConfCheckBox.Value = true;
            app.ShowROIsConfCheckBox_2 = uicheckbox(app.ConfocalPanel);
            app.ShowROIsConfCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UpdateShowDend, true);
            app.ShowROIsConfCheckBox_2.Text = 'Show dendrites';
            app.ShowROIsConfCheckBox_2.Value = true;
            app.SelectROIConf = uibutton(app.ConfocalPanel, 'push');
            app.SelectROIConf.ButtonPushedFcn = createCallbackFcn(app, @SelectROIConfocal, true);
            app.SelectROIConf.Text = 'Select ROI [C]';
            app.DeleteROIButton = uibutton(app.ConfocalPanel, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.NewROI = uibutton(app.ConfocalPanel, 'push');
            app.NewROI.ButtonPushedFcn = createCallbackFcn(app, @NewROIPushed, true);
            app.NewROI.Text = 'New ROI';
            app.pDataPanel = uipanel(app.UIFigure);
            app.pDataPanel.Title = '2p Data';
            app.pDataPanel.Visible = 'off';
            app.UIData2 = uiaxes(app.pDataPanel);
            title(app.UIData2, '2p Data')
            app.UIData2.XTick = [];
            app.UIData2.YTick = [];
            app.UIData2.Visible = 'off';
            app.MaximumSliderLabel2p = uilabel(app.pDataPanel);
            app.MaximumSliderLabel2p.Text = 'Maximum';
            app.MaximumSlider2p = uislider(app.pDataPanel);
            app.MaximumSlider2p.Limits = [0 1];
            app.MaximumSlider2p.MajorTicks = [-1 -0.5 0 0.5 1];
            app.MaximumSlider2p.ValueChangedFcn = createCallbackFcn(app, @UpdateMaximum2p, true);
            app.FlipCheckBox = uicheckbox(app.pDataPanel);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateFlip, true);
            app.FlipCheckBox.Text = 'Flip';
            app.RotateEditFieldLabel = uilabel(app.pDataPanel);
            app.RotateEditFieldLabel.Text = 'Rotate ';
            app.RotateEditField = uieditfield(app.pDataPanel, 'numeric');
            app.RotateEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateAngle, true);
            app.Button = uibutton(app.pDataPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @StopRotation, true);
            app.Button.Icon = 'lock.png';
            app.Button.Text = '';
            app.ROIs2pListBox = uilistbox(app.pDataPanel);
            app.ROIs2pListBox.Items = {};
            app.ROIs2pListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROINr2p, true);
            app.ROIs2pListBox.Value = {};
            app.ShowROIsData2CheckBox = uicheckbox(app.pDataPanel);
            app.ShowROIsData2CheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROIs2p, true);
            app.ShowROIsData2CheckBox.Text = 'Show ROIs';
            app.ShowROIsData2CheckBox.Value = true;
            app.SelectROITButton = uibutton(app.pDataPanel, 'push');
            app.SelectROITButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROI, true);
            app.SelectROITButton.Text = 'Select ROI [T]';
            app.GroupsPanel = uipanel(app.UIFigure);
            app.GroupsPanel.Title = 'Groups';
            app.GroupsPanel.Visible = 'off';
            app.GroupGButton = uibutton(app.GroupsPanel, 'push');
            app.GroupGButton.ButtonPushedFcn = createCallbackFcn(app, @GroupROIs, true);
            app.GroupGButton.Text = 'Group [G]';
            app.UngroupButton = uibutton(app.GroupsPanel, 'push');
            app.UngroupButton.ButtonPushedFcn = createCallbackFcn(app, @UngroupROIs, true);
            app.UngroupButton.Text = 'Ungroup';
            app.SaveAllSButton = uibutton(app.GroupsPanel, 'push');
            app.SaveAllSButton.ButtonPushedFcn = createCallbackFcn(app, @Save, true);
            app.SaveAllSButton.Text = 'Save All [S]';
            app.GroupListBox = uilistbox(app.GroupsPanel);
            app.GroupListBox.Items = {};
            app.GroupListBox.Value = {};
            app.LoadallButton = uibutton(app.GroupsPanel, 'push');
            app.LoadallButton.ButtonPushedFcn = createCallbackFcn(app, @Load, true);
            app.LoadallButton.Text = 'Load all';
            app.StartnextButton = uibutton(app.UIFigure, 'push');
            app.StartnextButton.ButtonPushedFcn = createCallbackFcn(app, @StartNew, true);
            app.StartnextButton.Visible = 'off';
            app.StartnextButton.Text = 'Start next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfTo2pMatching

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end