classdef ROIMatcherDendrite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LoadDataPanel          matlab.ui.container.Panel
        LoadButton             matlab.ui.control.Button
        LoadConfocalButton     matlab.ui.control.Button
        Data2DropDown          matlab.ui.control.DropDown
        AnimalField            matlab.ui.control.EditField
        AnimalEditFieldLabel   matlab.ui.control.Label
        GroupsPanel            matlab.ui.container.Panel
        LoadallButton          matlab.ui.control.Button
        GroupListBox           matlab.ui.control.ListBox
        SaveAllButton          matlab.ui.control.Button
        UngroupButton          matlab.ui.control.Button
        GroupButton            matlab.ui.control.Button
        View2pDataPanel        matlab.ui.container.Panel
        SelectareaButton_2     matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        DeleteROIData2Button   matlab.ui.control.Button
        ROIsData2ListBox       matlab.ui.control.ListBox
        ShowROIsData2CheckBox  matlab.ui.control.CheckBox
        FlipCheckBox           matlab.ui.control.CheckBox
        RotateEditField        matlab.ui.control.NumericEditField
        RotateEditFieldLabel   matlab.ui.control.Label
        ViewConfocalDataPanel  matlab.ui.container.Panel
        SelectareaButton       matlab.ui.control.Button
        ROIsData1ListBox       matlab.ui.control.ListBox
        DeleteROIData1Button   matlab.ui.control.Button
        ShowdendritesCheckBox  matlab.ui.control.CheckBox
        ShowROIsData1CheckBox  matlab.ui.control.CheckBox
        UIData2                matlab.ui.control.UIAxes
        UIData1                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % DataPaths
        TwoPDataPath = []; STEDDataPath = []; ConfocalDataPath = [];
        ConfocalCellImg = [];
        Data2Image = []; Data2Img3D = []; Data2ImageMod =[];
        ROIs2Image = []; ROIs2ImageMod = [];
        ROIsData2 = struct('id', [], 'type', [], 'RelLeft', [], 'RelUp', [], 'xPos', [], 'yPos', [], 'exp', [], 'RoiId', [], 'AbsLeft', [], 'AbsUp', []);
        ConfocalData = struct('xPos', [], 'yPos', [], 'type', [], 'body', [], 'perimeter', [], 'size', [])
        DendData = struct('dendrite', [])
        SelectedItems = struct('dendTrace', 1, 'dataType', '2p Data', 'Angle', 0, 'Flip', 0, 'ROIs1', 0, 'ROIs2', 0, 'SelectedROI1', [], 'SelectedROI2', [])
        Groups = struct('GroupID', [], 'ConfID', [], 'Data2ID', [])
        GroupCounter = 0;
        RotationInfo = struct('rowsNew', [], 'colsNew', [], 'rads', [], 'xMidNew', [], 'yMidNew',[], 'xMidOri', [], 'yMidOri',[],'rowsOri', [], 'colsOri', [])
        ZoomToData1  % Dialog box app for Zoom Data1
        ZoomToData2  % Dialog box app for Zoom Data2
    end
    
    methods (Access = private)
        function UpdateAxisImage1(app)
            oldImage = findobj(app.UIData1, 'Type', 'Image'); %delete old images
            delete(oldImage)
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            CellImg = mean(app.ConfocalCellImg,3);
            imagesc(CellImg, 'Parent', app.UIData1);
            axis(app.UIData1, 'image');
        end
        function UpdateAxisDend(app)
            if app.SelectedItems.dendTrace
                hold(app.UIData1, 'on')
                for dend = 1:length(app.DendData.dendrite)
                    plot(app.UIData1, app.DendData.dendrite{dend}.xTrace ,app.DendData.dendrite{dend}.yTrace, 'LineWidth',1.5, 'color', 'red');
                    hold(app.UIData1, 'on')
                end 
            end
        end
        function UpdateAxisROIs1(app)
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            UpdateAxisDend(app)
            if app.SelectedItems.ROIs1
                hold(app.UIData1, 'on')
                for r = 1:length(app.ConfocalData)
                    groupTyp = app.ConfocalData(r).group;
                    switch groupTyp
                        case 'No group'
                            plot(app.UIData1, app.ConfocalData(r).xPos, app.ConfocalData(r).yPos, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Green');
                        otherwise
                            plot(app.UIData1, app.ConfocalData(r).xPos, app.ConfocalData(r).yPos, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Blue');
                    end
                    hold(app.UIData1, 'on')
                end
            end
            if ~isempty(app.SelectedItems.SelectedROI1)
                hold(app.UIData1, 'on')
                idData = app.SelectedItems.SelectedROI1;
                infoData = strsplit(idData, ' - ');
                Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ConfocalData.id});
                ROINr = find(Matches,1);
                plot(app.UIData1, app.ConfocalData(ROINr).xPos, app.ConfocalData(ROINr).yPos, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Yellow');
                hold(app.UIData1, 'on')
            end
        end
        
        function UpdateAxisImage2(app)
            %bring everything back to original
            oldImage = findobj(app.UIData2, 'Type', 'Image'); %delete old images
            delete(oldImage)
            oldROIs = findobj(app.UIData2, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
                       
            app.UIData2.Visible = 'on';
            imagesc(app.Data2ImageMod, 'Parent', app.UIData2);
            axis(app.UIData2, 'image');
        end
        function UpdateAxisROIs2(app)
            oldROIs = findobj(app.UIData2, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            if app.SelectedItems.ROIs2 == 1 
                hold(app.UIData2, 'on')
                for r = 1:length(app.ROIsData2)
                    groupTyp = app.ROIsData2(r).group;
                    switch groupTyp
                        case 'No group'
                            plot(app.UIData2, app.ROIsData2(r).AbsLeftMod, app.ROIsData2(r).AbsUpMod, 'ok', 'MarkerSize',3', 'MarkerFaceColor','green')
                        otherwise 
                            plot(app.UIData2, app.ROIsData2(r).AbsLeftMod, app.ROIsData2(r).AbsUpMod, 'ok', 'MarkerSize',3', 'MarkerFaceColor','blue')
                    end
                    hold(app.UIData2, 'on')
                end
            end
            
            if ~isempty(app.SelectedItems.SelectedROI2)
                idData = app.SelectedItems.SelectedROI2;
                infoData = strsplit(idData, ' - ');
                Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ROIsData2.id});
                ROINr = find(Matches,1);
                hold(app.UIData2, 'on')
                plot(app.UIData2, app.ROIsData2(ROINr).AbsLeftMod, app.ROIsData2(ROINr).AbsUpMod, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Yellow');
                hold(app.UIData2, 'on')
            end
            
            axis(app.UIData2, 'image');
            axis(app.UIData2,'off');
        end       
        
        function FlipandRotate(app)
            %makes sure to reset the ROI positions
            for r = 1:length(app.ROIsData2)
               app.ROIsData2(r).AbsUpMod = app.ROIsData2(r).AbsUp;
               app.ROIsData2(r).AbsLeftMod = app.ROIsData2(r).AbsLeft; 
            end
            
            app.Data2ImageMod = app.Data2Image;
            app.ROIs2ImageMod = app.ROIs2Image;
            
            %flip ROI positions if checked
            if app.SelectedItems.Flip == 1
                for r = 1:length(app.ROIsData2)
                    app.ROIsData2(r).AbsLeftMod = size(app.Data2Image,2)-app.ROIsData2(r).AbsLeft;
                end
                app.Data2ImageMod = fliplr(app.Data2ImageMod);
                app.ROIs2ImageMod = fliplr(app.ROIs2ImageMod);
            end
            
            if app.SelectedItems.Angle ~= 0 
                %transform angle into radius
                rads=2*pi*app.SelectedItems.Angle/360; 
                
                %get the original image size and calculate the new dimensions
                [rowsOri,colsOri,~]= size(app.Data2Image);
                rowsNew=ceil(rowsOri*abs(cos(rads))+colsOri*abs(sin(rads)));                      
                colsNew=ceil(rowsOri*abs(sin(rads))+colsOri*abs(cos(rads))); 
                
                %calculate the center of original image and of final image
                xMidOri=ceil(rowsOri/2);                                                            
                yMidOri=ceil(colsOri/2);
                
                xMidNew=ceil(rowsNew/2);
                yMidNew=ceil(colsNew/2);
                
                %same time, rotating the old imag
                tempImageAxis=zeros(rowsNew, colsNew);
                tempImageROIs=tempImageAxis;
                for xNew = 1:rowsNew
                    for yNew = 1:colsNew
                        xOri = (xNew-xMidNew)*cos(rads)+(yNew-yMidNew)*sin(rads);
                        yOri = -(xNew-xMidNew)*sin(rads)+(yNew-yMidNew)*cos(rads);
                        xOri = round(xOri)+xMidOri;
                        yOri = round(yOri)+yMidOri;
                        
                        if xOri>=1 && yOri >1 && xOri<=rowsOri && yOri<=colsOri
                            tempImageAxis(xNew, yNew)=app.Data2ImageMod(xOri,yOri);
                            tempImageROIs(xNew, yNew)=app.ROIs2ImageMod(xOri,yOri);
                        end
                    end
                end
                
                app.Data2ImageMod = tempImageAxis;
                app.ROIs2ImageMod = tempImageROIs;
                for r = 1:length(app.ROIsData2)
                    [UpInd,LeftInd] = find(uint16(app.ROIs2ImageMod) == str2double(app.ROIsData2(r).id));
                    app.ROIsData2(r).AbsUpMod = ceil(mean(UpInd));
                    app.ROIsData2(r).AbsLeftMod = ceil(mean(LeftInd)); 
                end
                %save parameter to struct
                app.RotationInfo.rowsNew = rowsNew;  app.RotationInfo.colsNew = colsNew; app.RotationInfo.rads = rads;
                app.RotationInfo.xMidNew = xMidNew;  app.RotationInfo.yMidNew = yMidNew; 
                app.RotationInfo.xMidOri = xMidOri;  app.RotationInfo.yMidOri = yMidOri; 
                app.RotationInfo.rowsOri = rowsOri;  app.RotationInfo.colsOri = colsOri;
            end
           UpdateAxisImage2(app)
           UpdateAxisROIs2(app)
        end
    end
    
    methods (Access = public)
        
        function GetNewSelectedROI1(app, ROIInfo)
            idData = ROIInfo;
            infoData = strsplit(idData, ' - ');
            Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ConfocalData.id});
            ROINr = find(Matches,1);
            app.ROIsData1ListBox.Value = app.ConfocalData(ROINr).label;
            app.SelectedItems.SelectedROI1 = app.ROIsData1ListBox.Value;
            UpdateAxisROIs1(app)
        end
        
        function GetNewSelectedROI2(app, ROIInfo)
            idData = ROIInfo;
            infoData = strsplit(idData, ' - ');
            Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ROIsData2.id});
            ROINr = find(Matches,1);
            app.ROIsData2ListBox.Value = app.ROIsData2(ROINr).label;
            app.SelectedItems.SelectedROI2 = app.ROIsData2ListBox.Value;
            UpdateAxisROIs2(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Data2DropDown
        function UpdateDataType(app, event)
            app.SelectedItems.dataType = app.Data2DropDown.Value;
        end

        % Value changed function: RotateEditField
        function UpdateRotateData2(app, event)
            app.SelectedItems.Angle = app.RotateEditField.Value;
            FlipandRotate(app)
        end

        % Value changed function: FlipCheckBox
        function UpdateFlipData2(app, event)
            app.SelectedItems.Flip = app.FlipCheckBox.Value;
            FlipandRotate(app)
        end

        % Value changed function: ShowdendritesCheckBox
        function UpdateDendTraces(app, event)
            app.SelectedItems.dendTrace = app.ShowdendritesCheckBox.Value;
            UpdateAxisDend(app);
        end

        % Button pushed function: LoadConfocalButton
        function LoadData1(app, event)
            imgSizeConf = [4060, 4060]; %number of pixels -> adjust to get out of imaging data
            umSize = [302.1508, 302.1508]; %size in microns -> adjust to get out of imaging data
            
            %set dir
            ConfocalDir = 'Z:\Juliane\Data\Confocal\';
            animalName = char(app.AnimalField.Value);
            app.ConfocalDataPath = [char(ConfocalDir) char(animalName) filesep];
            
            %load image
            [fileName, Path] = uigetfile('*.tif', 'Select confocal image',app.ConfocalDataPath);
            info = imfinfo([Path fileName]);
            app.ConfocalCellImg = zeros(info(1).Width, info(1).Height, length(info));
            for k = 1:length(info)
                tifFile = imread([Path fileName], k);
                app.ConfocalCellImg(:,:,k) = mat2gray(tifFile); %scales it for better viewability
            end
            
            %load tracing Data
            confReconFiles = dir([app.ConfocalDataPath 'Dendrite Tracing' filesep '*.swc']);
            xTrace = [];
            yTrace = [];
            for f = 1:length(confReconFiles)
                file = [app.ConfocalDataPath 'Dendrite Tracing' filesep confReconFiles(f).name];
                b = loadfilelist(file);
                a = b(4:end);
                c = cellfun(@str2num, a, 'UniformOutput', false);
                traceInfo = cell2mat(c');
                xTrace = [xTrace; traceInfo(:,3)];
                yTrace = [yTrace; traceInfo(:,4)];
            end
            
            %load ROI data
            ROIFiles = dir([app.ConfocalDataPath  'Spine Tracings' filesep '\*.zip']);
            cvsROIs = [];
            for r = 1:length(ROIFiles)
                file = [app.ConfocalDataPath 'Spine Tracings' filesep ROIFiles(r).name];
                [ROI] = ReadImageJROI(file);
                cvsROIs = [cvsROIs ROI];
            end
            
            %% process data
            %calc umfactor to change from micrometer to pixelposition
            umfactor = imgSizeConf(1)/umSize(1);
            
            %separate tracing data into dendrites by finding jumps
            dffXTrace = diff(xTrace); dffYTrace = diff(yTrace);
            jumpsMins = find(dffXTrace < -1.5); jumpsMinsY = find(dffYTrace < -1.5);
            jumpMax = find(dffXTrace > 1.5); jumpMaxY = find(dffYTrace > 1.5);
            jumps = [jumpsMins; jumpMax; jumpsMinsY; jumpMaxY];
            jumps = sort(jumps);
            jumps = unique(jumps);
            
            x = 1;
            for j = 1:length(jumps)
                dendrite{j}.xTrace = xTrace(x:jumps(j))*umfactor;
                dendrite{j}.yTrace = yTrace(x:jumps(j))*umfactor;
                x = jumps(j)+1;
            end
            app.DendData.dendrite = dendrite;
            
            %process ROIs
            for i = 1:length(cvsROIs)
                tempR = poly2mask(cvsROIs{i}.mnCoordinates(:,1), cvsROIs{i}.mnCoordinates(:,2),imgSizeConf(1),imgSizeConf(2));
                %CHECK FOR DOUBLE REGIONPROPS!!! USE LARGEST ONE
                STATS = regionprops(tempR,'Centroid', 'Area', 'PixelList', 'ConvexHull' );
                [~, idx] = max([STATS.Area]);
                app.ConfocalData(i).id = char(sprintf( '%04d', (i)));
                app.ConfocalData(i).group = 'No group';
                app.ConfocalData(i).label = [app.ConfocalData(i).id ' - ' app.ConfocalData(i).group];
                
                app.ConfocalData(i).perimeter = cvsROIs{i}.mnCoordinates+1;
                app.ConfocalData(i).body = STATS.PixelList;
                if length(app.ConfocalData(i).perimeter) > 100
                    app.ConfocalData(i).type = 'soma';
                else
                    app.ConfocalData(i).type = 'spine';
                end
                app.ConfocalData(i).size = length(app.ConfocalData(i).body(:,2));
                app.ConfocalData(i).xPos = STATS(idx).Centroid(:,1)+1;
                app.ConfocalData(i).yPos = STATS(idx).Centroid(:,2)+1;
            end
            app.ROIsData1ListBox.Items = {app.ConfocalData.label};
            %load into the axis
            UpdateAxisImage1(app)
        end

        % Button pushed function: LoadButton
        function LoadData2(app, event)
            animalName = char(app.AnimalField.Value);   
            switch app.SelectedItems.dataType
                case '2p Data'
                    TwoPhontondir = 'Z:\Juliane\Data\2P_Data\';
                    allFolders = dir(TwoPhontondir);
                    a = {allFolders.name};
                    folderInfo = cellfun(@(x) find(contains(x, char(animalName))),a,'UniformOutput',false);
                    notFolder = cellfun(@isempty,folderInfo); %all other cells are empty
                    indFolder = find(~notFolder);                                      
                    app.TwoPDataPath = [char(TwoPhontondir) char(allFolders(indFolder).name) filesep];
                    [fileName, Path] = uigetfile('*.mat', 'Select data for reading in 2p imaging data',app.TwoPDataPath);
                    load([Path fileName], 'CellExpData', 'InVivoImage', 'InVivo3D');
                    
                    app.ROIs2Image = zeros(size(InVivoImage,1), size(InVivoImage,2));
                    
                    %rewrite the ROIs to give them new numbers, scale
                    %positions!!!
                    i = 1;
                    for exp = 1:size(CellExpData,2)
                        scale = size(CellExpData(exp).Image,1)/512;
                        for r = 1:size(CellExpData(exp).ROIData,2)
                            if exp == 1
                                continue
                            else 
                                if CellExpData(exp).ROIData(r).spine == 1
                                    app.ROIsData2(i).type = 'Spine';
                                else
                                    app.ROIsData2(i).type = 'Dendrite';
                                end
                                app.ROIsData2(i).id =  char(sprintf( '%03d', (100*(exp-1)+r)));
                                app.ROIsData2(i).group = 'No group';
                                app.ROIsData2(i).label = [app.ROIsData2(i).id ' - ' app.ROIsData2(i).group];
                                app.ROIsData2(i).RelLeft = CellExpData(exp).RelLeft;
                                app.ROIsData2(i).RelUp = CellExpData(exp).RelUp;
                                app.ROIsData2(i).xPos = CellExpData(exp).ROIData(r).xPos;
                                app.ROIsData2(i).yPos = CellExpData(exp).ROIData(r).yPos;
                                app.ROIsData2(i).AbsLeft = app.ROIsData2(i).RelLeft+scale*app.ROIsData2(i).xPos-1;
                                app.ROIsData2(i).AbsUp = app.ROIsData2(i).RelUp + scale*app.ROIsData2(i).yPos-1;
                                app.ROIsData2(i).perimeter = CellExpData(exp).ROIData(r).mask;
                                temp = zeros(size(CellExpData(exp).ROIData(r).mask,1),2);
                                for p=1:size(CellExpData(exp).ROIData(r).mask,1)
                                    temp(p,1)=app.ROIsData2(i).RelLeft+scale*CellExpData(exp).ROIData(r).mask(p,1)-1;
                                    temp(p,2)=app.ROIsData2(i).RelUp+scale*CellExpData(exp).ROIData(r).mask(p,2)-1;
                                end
                                app.ROIsData2(i).AbsPeri = temp;
                                app.ROIsData2(i).AbsLeftMod = app.ROIsData2(i).AbsLeft;
                                app.ROIsData2(i).AbsUpMod = app.ROIsData2(i).AbsUp;
                                app.ROIsData2(i).exp = CellExpData(exp).Name;
                                app.ROIsData2(i).RoiId = r;
                                app.ROIsData2(i).OSI = CellExpData(exp).ROIData(r).OSI;
                                app.ROIsData2(i).prefOri = CellExpData(exp).ROIData(r).prefOri;
                                app.ROIsData2(i).isReps = CellExpData(exp).ROIData(r).isResp;
                                app.ROIsData2(i).prefDir = CellExpData(exp).ROIData(r).prefDir;
                                app.ROIsData2(i).DSI = CellExpData(exp).ROIData(r).DSI;
                                app.ROIs2Image(ceil(app.ROIsData2(i).AbsUp)-1:ceil(app.ROIsData2(i).AbsUp)+1,ceil(app.ROIsData2(i).AbsLeft)-1:ceil(app.ROIsData2(i).AbsLeft)+1)=str2double(app.ROIsData2(i).id);
                                i = i+1;
                            end
                        end
                    end
                    app.Data2Image = InVivoImage;
                    app.Data2Img3D = mat2gray(InVivo3D);
                    app.Data2ImageMod = app.Data2Image;
                    app.ROIs2ImageMod = app.ROIs2Image;

                    %add ROIs to list
                    app.ROIsData2ListBox.Items = {app.ROIsData2.label};
                case 'STED Data'
                    STEDDir = 'Y:\Data_JJ_Ferret_project\JJ_FerretProject_Organized\';
                    app.STEDDataPath = [char(STEDDir) char(animalName) filesep];
                    [fileName, Path] = uigetfile('*.mat', 'Select data for reading in STED data',app.STEDDataPath);
                    load([Path fileName], 'CellImage', 'STEDData')
                    i = 1;
                    for r = 1:size(STEDData,2)
                        if find(contains(STEDData(r).type,'Spine'))
                            if find(contains(STEDData(r).Input,'yes'))
                                app.ROIsData2(i).id =  char(sprintf( '%03d', (i)));
                                app.ROIsData2(i).group = 'No group';
                                app.ROIsData2(i).label = [app.ROIsData2(i).id ' - ' app.ROIsData2(i).group];
                                app.ROIsData2(i).AbsLeft = STEDData(r).Left;
                                app.ROIsData2(i).AbsUp = STEDData(r).Up;
                                app.ROIsData2(i).AbsLeftMod = app.ROIsData2(i).AbsLeft;
                                app.ROIsData2(i).AbsUpMod = app.ROIsData2(i).AbsUp;
                                app.ROIsData2(i).name = STEDData(r).Name;
                                i = i+1; 
                            end
                        end
                    end
                    app.Data2Image = CellImage;
                    app.Data2ImageMod = CellImage;
                    
                    %add ROIs to list
                    app.ROIsData2ListBox.Items = {app.ROIsData2.label};
            end
            UpdateAxisImage2(app)
            app.Data2DropDown.Enable = 'off';
        end

        % Value changed function: ShowROIsData1CheckBox
        function UpdateROIsData1(app, event)
            app.SelectedItems.ROIs1 = app.ShowROIsData1CheckBox.Value;
            UpdateAxisROIs1(app)
        end

        % Value changed function: ShowROIsData2CheckBox
        function UpdateROIsData2(app, event)
            app.SelectedItems.ROIs2 = app.ShowROIsData2CheckBox.Value;
            UpdateAxisROIs2(app)
        end

        % Value changed function: ROIsData1ListBox
        function UpdateSelectROI1(app, event)
            app.SelectedItems.SelectedROI1 = app.ROIsData1ListBox.Value;
            UpdateAxisROIs1(app)
        end

        % Value changed function: ROIsData2ListBox
        function UpdateSelectROI2(app, event)
            app.SelectedItems.SelectedROI2 = app.ROIsData2ListBox.Value;
            UpdateAxisROIs2(app)
        end

        % Button pushed function: GroupButton
        function GroupROI(app, event)
            %get the IDs of the ROIs from both datas
            idData1 = app.SelectedItems.SelectedROI1;
            infoData1 = strsplit(idData1, ' - ');
            idData2 = app.SelectedItems.SelectedROI2;
            infoData2 = strsplit(idData2, ' - ');
            
%             %check if they are already group and ungroup accordingly
%             if ~contains(infoData1{2}, 'No group') 
%                 %write something here to delete the previously defined group
%                 oldGroupNr = infoData1{2}; 
%                 %delete the groupID line - just removing the linked ones,
%                 %not removing the line in general
%                 GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.Groups.GroupID});
%                 GroupID = find(GroupMatches,1);
%                 app.Groups(GroupID) = [];
%                 
%                 %delete the corresponding group in data 2
%                 GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.ROIsData2.group});
%                 GroupID = find(GroupMatches,1);
%                 app.ROIsData2(GroupID).group = 'No group';
%                 app.ROIsData2(GroupID).label = [app.ROIsData2(GroupID).id ' - ' app.ROIsData2(GroupID).group];
%                 app.GroupCounter = app.GroupCounter-1;
%             end
%             if ~contains(infoData2{2}, 'No group') 
%                 %write something here to delete the previously defined group
%                 oldGroupNr = infoData2{2};
% 
%                 %delete the groupID line - just removing the linked ones,
%                 %not removing the line in general
%                 GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.Groups.GroupID});
%                 GroupID = find(GroupMatches,1);
%                 app.Groups(GroupID)= [];
%                 
%                 %delete the corresponding group in Confocal data
%                 GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.ConfocalData.group});
%                 GroupID = find(GroupMatches,1);
%                 app.ConfocalData(GroupID).group = 'No group';
%                 app.ConfocalData(GroupID).label = [app.ConfocalData(GroupID).id ' - ' app.ConfocalData(GroupID).group];
%                 app.GroupCounter = app.GroupCounter-1;
%             end
            
            %get the group number
            newGroupID = app.GroupCounter + 1;
            if app.GroupCounter == 0
                groupLength = 1; %this is to make sure that there is not an empty row inbetween
            else
                groupLength = size(app.Groups,2)+1; %this is to make sure that there is not an empty row inbetween
            end
            
            %write the new group data into app.Group
            app.Groups(groupLength).GroupID = ['Group ' num2str(newGroupID)];
            app.Groups(groupLength).ConfID = infoData1{1};
            app.Groups(groupLength).Data2ID = infoData2{1};
            
            %update the group number and labels in the two datasets
            ConfROINrMatches =  cellfun(@(x) strcmp(x, infoData1{1}),{app.ConfocalData.id});
            ConfROINrinStruct = find(ConfROINrMatches,1);
            app.ConfocalData(ConfROINrinStruct).group = ['Group ' num2str(newGroupID)];
            app.ConfocalData(ConfROINrinStruct).label = [app.ConfocalData(ConfROINrinStruct).id ' - ' app.ConfocalData(ConfROINrinStruct).group];
            Data2ROINrMatches =  cellfun(@(x) strcmp(x, infoData2{1}),{app.ROIsData2.id});
            Data2ROINrinStruct = find(Data2ROINrMatches,1);
            app.ROIsData2(Data2ROINrinStruct).group = ['Group ' num2str(newGroupID)];
            app.ROIsData2(Data2ROINrinStruct).label = [app.ROIsData2(Data2ROINrinStruct).id ' - ' app.ROIsData2(Data2ROINrinStruct).group];
            
            %update the listboxes
            app.ROIsData1ListBox.Items = {app.ConfocalData.label};
            app.ROIsData2ListBox.Items = {app.ROIsData2.label};
            app.GroupListBox.Items = {app.Groups.GroupID};
            app.GroupCounter = app.GroupCounter+1;
            
            %update the zoom windows
            try
                UpdateGoupNrConfocal(app.ZoomToData1, newGroupID, idData1)
                UpdateGoupNrData2(app.ZoomToData2, newGroupID, idData2)
            end
        end

        % Button pushed function: UngroupButton
        function Ungroup(app, event)
            oldGroupNr = app.GroupListBox.Value;
                        
            %delete the group
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.Groups.GroupID});
            GroupID = find(GroupMatches,1);
            app.Groups(GroupID)= [];
                
            %delete the corresponding group in Confocal data
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.ConfocalData.group});
            GroupID = find(GroupMatches,1);
            app.ConfocalData(GroupID).group = 'No group';
            app.ConfocalData(GroupID).label = [app.ConfocalData(GroupID).id ' - ' app.ConfocalData(GroupID).group];
             
            %delete the corresponding group in data 2
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.ROIsData2.group});
            GroupID = find(GroupMatches,1);
            app.ROIsData2(GroupID).group = 'No group';
            app.ROIsData2(GroupID).label = [app.ROIsData2(GroupID).id ' - ' app.ROIsData2(GroupID).group];
            
            %update the listboxes
            app.ROIsData1ListBox.Items = {app.ConfocalData.label};
            app.ROIsData2ListBox.Items = {app.ROIsData2.label};
            app.GroupListBox.Items = {app.Groups.GroupID};
            
            %update the zoom windows
            try
                UngroupConfocal(app.ZoomToData1, oldGroupNr)
                UngroupData2(app.ZoomToData2, oldGroupNr)
            end
        end

        % Button pushed function: DeleteROIData1Button
        function DeleteROIConf(app, event)
           idData = app.SelectedItems.SelectedROI1;
           infoData = strsplit(idData, ' - '); 
           Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ConfocalData.id});
           ROINr = find(Matches,1);
           app.ConfocalData(ROINr) = [];
           app.ROIsData1ListBox.Items = {app.ConfocalData.label};
           app.SelectedItems.SelectedROI1 = [];
           UpdateAxisROIs1(app)
           try
            DeleteRoiConfocal(app.ZoomToData1,idData);
           end
        end

        % Button pushed function: DeleteROIData2Button
        function DeleteROIData2(app, event)
           idData = app.SelectedItems.SelectedROI2;
           infoData = strsplit(idData, ' - '); 
           Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ROIsData2.id});
           ROINr = find(Matches,1);
           app.ROIsData2(ROINr) = []; 
           app.ROIsData2ListBox.Items = {app.ROIsData2.label};
           app.SelectedItems.SelectedROI2 = [];
           UpdateAxisROIs2(app)
           try
            DeleteRoiData2(app.ZoomToData2,idData);
           end
        end

        % Button pushed function: SaveAllButton
        function SaveAll(app, event)
            %get today's date
            todayDate = today('datetime');
            AnalysisDir = 'Z:\Juliane\Data\ImageAnalysis\';
            folderSplits = strsplit(app.TwoPDataPath, '\');
            AnalysisDir = [char(AnalysisDir) char(folderSplits{5}) filesep];            
            
            %write a new variable with everything ordered by groups
            MatchData = struct;
            try
                for m = 1:length(app.Groups)
                    MatchData(m).GroupID = app.Groups(m).GroupID;
                    %add in the confocal Data Info
                    MatchData(m).ConfocalID = app.Groups(m).ConfID;
                    Matches =  cellfun(@(x) strcmp(x, MatchData(m).ConfocalID),{app.ConfocalData.id});
                    ROINr = find(Matches,1);
                    MatchData(m).xPos = app.ConfocalData(ROINr).xPos;
                    MatchData(m).yPos = app.ConfocalData(ROINr).yPos;
                    %depending on whether it is STED or 2p data, add in the
                    %other fields
                    data2ID = app.Groups(m).Data2ID;
                    Matches2= cellfun(@(x) strcmp(x, data2ID),{app.ROIsData2.id});
                    ROINr2 = find(Matches2,1);
                    switch app.SelectedItems.dataType
                        case '2p Data'
                            MatchData(m).TwoP_ID = app.ROIsData2(ROINr2).id;
                            MatchData(m).expID = app.ROIsData2(ROINr2).exp;
                            MatchData(m).isResp = app.ROIsData2(ROINr2).isReps;
                            MatchData(m).OSI = app.ROIsData2(ROINr2).OSI;
                            MatchData(m).prefOri = app.ROIsData2(ROINr2).prefOri;
                            MatchData(m).DSI = app.ROIsData2(ROINr2).DSI;
                            MatchData(m).prefDir = app.ROIsData2(ROINr2).prefDir;
                            Name = 'GroupedData2P_';
                        case 'STED Data'
                            MatchData(m).STED_ID = app.ROIsData2(ROINr2).id;
                            MatchData(m).STED_Name = app.ROIsData2(ROINr2).name;
                            MatchData(m).STED_Left = app.ROIsData2(ROINr2).AbsLeft;
                            MatchData(m).STED_Up = app.ROIsData2(ROINr2).AbsUp;
                            Name = 'GroupedDataSTED_';
                    end
                end
            end
            ConfocalData = app.ConfocalData; Groups = app.Groups; ROIsData2 = app.ROIsData2;
            %save all
            save([AnalysisDir char(Name) char(todayDate) '.mat'],'ROIsData2', 'ConfocalData', 'Groups', 'MatchData');
        end

        % Button pushed function: LoadallButton
        function LoadAll(app, event)
            AnalysisDir = 'Z:\Juliane\Data\ImageAnalysis\';
            %allFolders = dir(AnalysisDir);
            %a = {allFolders.name};
            %folderInfo = cellfun(@(x) find(contains(x, char(animalName))),a,'UniformOutput',false);
            %notFolder = cellfun(@isempty,folderInfo); %all other cells are empty
            %indFolder = find(~notFolder);                                      
            %AnalysisDir = [char(AnalysisDir) char(allFolders(indFolder).name) filesep];
            [fileName, Path] = uigetfile('*.mat', 'Select mat-file for reading in aligned coordinates',AnalysisDir);
            temp = load([Path fileName]);
            
            app.ROIsData2 = temp.ROIsData2;
            app.ConfocalData = temp.ConfocalData;
            app.Groups = temp.Groups;
            
            %update groupNr Counter
            app.GroupCounter = str2double(temp.Groups(end).GroupID(7:end));
            
            %update Listboxes
            app.ROIsData1ListBox.Items = {app.ConfocalData.label};
            app.ROIsData2ListBox.Items = {app.ROIsData2.label};
            try
                app.GroupListBox.Items = {app.Groups.GroupID};
            end
            
            %update Axis1
            UpdateAxisImage1(app)
            UpdateAxisDend(app)
            UpdateAxisROIs1(app)
            
            %update Axis2
            UpdateAxisImage2(app)
            UpdateAxisROIs2(app)
        end

        % Button pushed function: Button
        function LockImage(app, event)
            app.FlipCheckBox.Enable = 'off';
            app.RotateEditField.Enable = 'off';
            %app.LockImage.Enable = 'off';
        end

        % Button pushed function: SelectareaButton
        function SelectArea1(app, event)
            oldObjects = findobj(app.UIData1, 'LineWidth', 2.25);
            delete(oldObjects)
            
            currAx = app.UIData1;
            h = drawrectangle(currAx);
            
            %restruct zoomed image to selected region
            ZoomedImg = app.ConfocalCellImg(ceil(min(h.Vertices(:,2))):ceil(max(h.Vertices(:,2))),ceil(min(h.Vertices(:,1))):ceil(max(h.Vertices(:,1))),:);
           
            %find ROIs that are in that region
            allX = {app.ConfocalData.xPos};
            xPosCheck = cellfun(@(x) find(x > ceil(min(h.Vertices(:,1))) & x < ceil(max(h.Vertices(:,1)))),allX,'UniformOutput',false);
            xPosNo = find(cellfun(@isempty,xPosCheck));
            allY = {app.ConfocalData.yPos};
            yPosCheck = cellfun(@(x) find(x > ceil(min(h.Vertices(:,2))) & x < ceil(max(h.Vertices(:,2)))),allY,'UniformOutput',false);
            yPosNo = find(cellfun(@isempty,yPosCheck));
            ROIsInImage = setdiff(linspace(1,length(app.ConfocalData),length(app.ConfocalData)), [xPosNo yPosNo]);
            ZoomedROIs = app.ConfocalData(ROIsInImage);
            
            %add new ROI positions
            for newROI = 1:length(ZoomedROIs)
                %new x,y coordinates
                ZoomedROIs(newROI).newX = ZoomedROIs(newROI).xPos - ceil(min(h.Vertices(:,1)));
                ZoomedROIs(newROI).newY = ZoomedROIs(newROI).yPos - ceil(min(h.Vertices(:,2)));
                %new perimeter
                temp = zeros(size(ZoomedROIs(newROI).perimeter,1),2);
                for p =1:size(ZoomedROIs(newROI).perimeter,1)
                    temp(p,1) = ZoomedROIs(newROI).perimeter(p,1)-ceil(min(h.Vertices(:,1)));
                    temp(p,2) = ZoomedROIs(newROI).perimeter(p,2)-ceil(min(h.Vertices(:,2)));
                end
                 ZoomedROIs(newROI).newPerimeter = temp;
            end
            
            %find dendrites that are in that region and add it
            ZoomDendData = struct;
            d = 1;
            for dend = 1:length(app.DendData.dendrite)
                tempData = app.DendData.dendrite(dend);
                xTracesYes = find(tempData{1}.xTrace > ceil(min(h.Vertices(:,1))) & tempData{1}.xTrace  < ceil(max(h.Vertices(:,1))));
                yTracesYes = find(tempData{1}.yTrace > ceil(min(h.Vertices(:,2))) & tempData{1}.yTrace  < ceil(max(h.Vertices(:,2))));
                TracesYes = intersect(xTracesYes, yTracesYes);
                if ~isempty(TracesYes)
                    ZoomDendData(d).xTrace = tempData{1}.xTrace(TracesYes)- ceil(min(h.Vertices(:,1)));
                    ZoomDendData(d).yTrace = tempData{1}.yTrace(TracesYes)- ceil(min(h.Vertices(:,2)));
                    d = d+1;
                end
            end
            
             % Open the options dialog and pass inputs
            app.ZoomToData1= ZoomData1(app, ZoomedImg, ZoomedROIs, ZoomDendData);
        end

        % Button pushed function: SelectareaButton_2
        function SelectArea2(app, event)
            %clear previously selected areas
            oldObjects = findobj(app.UIData2, 'LineWidth', 2.25);
            delete(oldObjects)
            
            %draw new area
            currAx = app.UIData2;
            h = drawrectangle(currAx);
            
%             tempImag = app.Data2Img3D; %need to be rotated accordingly
%             %flip ROI positions if checked
%             if app.SelectedItems.Flip == 1
%                tempImag = fliplr(tempImag); 
%             end
%             if app.SelectedItems.Angle ~= 0 
%                temp3DImg = zeros(app.RotationInfo.rowsNew, app.RotationInfo.colsNew, size(tempImag,3));
%                for plane = 1:size(tempImag,3)
%                    for xNew = 1:app.RotationInfo.rowsNew
%                         for yNew = 1:app.RotationInfo.colsNew
%                             xOri = (xNew-app.RotationInfo.xMidNew)*cos(app.RotationInfo.rads)+(yNew-app.RotationInfo.yMidNew)*sin(app.RotationInfo.rads);
%                             yOri = -(xNew-app.RotationInfo.xMidNew)*sin(app.RotationInfo.rads)+(yNew-app.RotationInfo.yMidNew)*cos(app.RotationInfo.rads);
%                             xOri = round(xOri)+app.RotationInfo.xMidOri;
%                             yOri = round(yOri)+app.RotationInfo.yMidOri;
%                             
%                             if xOri>=1 && yOri >1 && xOri<=app.RotationInfo.rowsOri && yOri<=app.RotationInfo.colsOri
%                                 temp3DImg(xNew, yNew, plane)=tempImag(xOri,yOri,plane);
%                             end
%                         end
%                     end
%                end
%                tempImag = temp3DImg; clear temp3DImg
%             end

            %restruct zoomed image to selected region
            ZoomedData2Img = app.Data2ImageMod(ceil(min(h.Vertices(:,2))):ceil(max(h.Vertices(:,2))),ceil(min(h.Vertices(:,1))):ceil(max(h.Vertices(:,1))),:);
            
            %get ROI positions based on the coordinates AbsUpMod and AbsLeftMod
            %find ROIs that are in that region
            yPosYes = find([app.ROIsData2(:).AbsUpMod] > ceil(min(h.Vertices(:,2))) & [app.ROIsData2(:).AbsUpMod] < ceil(max(h.Vertices(:,2))));
            xPosYes = find([app.ROIsData2(:).AbsLeftMod] > ceil(min(h.Vertices(:,1))) & [app.ROIsData2(:).AbsLeftMod] < ceil(max(h.Vertices(:,1))));
            ROIsInImage = intersect(xPosYes, yPosYes);            
            ZoomedData2ROIs = app.ROIsData2(ROIsInImage);
            
            %add new ROI positions
            for newROI = 1:length(ZoomedData2ROIs)
                %make positions relative to the selection window
                ZoomedData2ROIs(newROI).newX = ZoomedData2ROIs(newROI).AbsLeftMod - ceil(min(h.Vertices(:,1)));
                ZoomedData2ROIs(newROI).newY = ZoomedData2ROIs(newROI).AbsUpMod - ceil(min(h.Vertices(:,2)));
            end
            
            
            % Open the options dialog and pass inputs
            app.ZoomToData2= ZoomData2(app, ZoomedData2Img, ZoomedData2ROIs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIData1 = uiaxes(app.UIFigure);
            title(app.UIData1, 'Confocal Data')
            app.UIData1.XTick = [];
            app.UIData1.YTick = [];
            app.UIData2 = uiaxes(app.UIFigure);
            title(app.UIData2, '2p Data')
            app.UIData2.XTick = [];
            app.UIData2.YTick = [];
            app.ViewConfocalDataPanel = uipanel(app.UIFigure);
            app.ViewConfocalDataPanel.Title = 'View Confocal Data';
            app.ShowROIsData1CheckBox = uicheckbox(app.ViewConfocalDataPanel);
            app.ShowROIsData1CheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROIsData1, true);
            app.ShowROIsData1CheckBox.Text = 'Show ROIs';
            app.ShowdendritesCheckBox = uicheckbox(app.ViewConfocalDataPanel);
            app.ShowdendritesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDendTraces, true);
            app.ShowdendritesCheckBox.Text = 'Show dendrites';
            app.ShowdendritesCheckBox.Value = true;
            app.DeleteROIData1Button = uibutton(app.ViewConfocalDataPanel, 'push');
            app.DeleteROIData1Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIConf, true);
            app.DeleteROIData1Button.Text = 'Delete ROI';
            app.ROIsData1ListBox = uilistbox(app.ViewConfocalDataPanel);
            app.ROIsData1ListBox.Items = {};
            app.ROIsData1ListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectROI1, true);
            app.ROIsData1ListBox.Value = {};
            app.SelectareaButton = uibutton(app.ViewConfocalDataPanel, 'push');
            app.SelectareaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectArea1, true);
            app.SelectareaButton.Text = 'Select area';
            app.View2pDataPanel = uipanel(app.UIFigure);
            app.View2pDataPanel.Title = 'View 2p Data';
            app.RotateEditFieldLabel = uilabel(app.View2pDataPanel);
            app.RotateEditFieldLabel.Text = 'Rotate ';
            app.RotateEditField = uieditfield(app.View2pDataPanel, 'numeric');
            app.RotateEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateRotateData2, true);
            app.FlipCheckBox = uicheckbox(app.View2pDataPanel);
            app.FlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateFlipData2, true);
            app.FlipCheckBox.Text = 'Flip';
            app.ShowROIsData2CheckBox = uicheckbox(app.View2pDataPanel);
            app.ShowROIsData2CheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateROIsData2, true);
            app.ShowROIsData2CheckBox.Text = 'Show ROIs';
            app.ROIsData2ListBox = uilistbox(app.View2pDataPanel);
            app.ROIsData2ListBox.Items = {};
            app.ROIsData2ListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectROI2, true);
            app.ROIsData2ListBox.Value = {};
            app.DeleteROIData2Button = uibutton(app.View2pDataPanel, 'push');
            app.DeleteROIData2Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIData2, true);
            app.DeleteROIData2Button.Text = 'Delete ROI';
            app.Button = uibutton(app.View2pDataPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LockImage, true);
            app.Button.Icon = 'lock.png';
            app.Button.Text = '';
            app.SelectareaButton_2 = uibutton(app.View2pDataPanel, 'push');
            app.SelectareaButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectArea2, true);
            app.SelectareaButton_2.Text = 'Select area';
            app.GroupsPanel = uipanel(app.UIFigure);
            app.GroupsPanel.Title = 'Groups';
            app.GroupButton = uibutton(app.GroupsPanel, 'push');
            app.GroupButton.ButtonPushedFcn = createCallbackFcn(app, @GroupROI, true);
            app.GroupButton.Text = 'Group';
            app.UngroupButton = uibutton(app.GroupsPanel, 'push');
            app.UngroupButton.ButtonPushedFcn = createCallbackFcn(app, @Ungroup, true);
            app.UngroupButton.Text = 'Ungroup';
            app.SaveAllButton = uibutton(app.GroupsPanel, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAll, true);
            app.SaveAllButton.Text = 'Save All';
            app.GroupListBox = uilistbox(app.GroupsPanel);
            app.GroupListBox.Items = {};
            app.GroupListBox.Value = {};
            app.LoadallButton = uibutton(app.GroupsPanel, 'push');
            app.LoadallButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAll, true);
            app.LoadallButton.Text = 'Load all';
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load Data:';
            app.AnimalEditFieldLabel = uilabel(app.LoadDataPanel);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalField = uieditfield(app.LoadDataPanel, 'text');
            app.AnimalField.Value = 'F2688';
            app.Data2DropDown = uidropdown(app.LoadDataPanel);
            app.Data2DropDown.Items = {'2p Data', 'STED Data'};
            app.Data2DropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateDataType, true);
            app.Data2DropDown.Value = '2p Data';
            app.LoadConfocalButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadConfocalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData1, true);
            app.LoadConfocalButton.Text = 'Load Confocal';
            app.LoadButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData2, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIMatcherDendrite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end