classdef STEDAlignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LOADButton                    matlab.ui.control.Button
        LoadallreferencepointsButton  matlab.ui.control.Button
        SetreferencepointsLabel       matlab.ui.control.Label
        MoveDropDown                  matlab.ui.control.DropDown
        MoveDropDownLabel             matlab.ui.control.Label
        SKIPAlignmentButton           matlab.ui.control.Button
        LoadreferencepointButton      matlab.ui.control.Button
        SavereferencepointButton      matlab.ui.control.Button
        ReferencessetCheckBox         matlab.ui.control.CheckBox
        SAVEButton                    matlab.ui.control.Button
        PixelMoveStitch               matlab.ui.control.NumericEditField
        DownStitch                    matlab.ui.control.Button
        UpStitch                      matlab.ui.control.Button
        RightStitch                   matlab.ui.control.Button
        LeftStitch                    matlab.ui.control.Button
        VisibleSessionsListBox        matlab.ui.control.ListBox
        VisibleSessionsListBoxLabel   matlab.ui.control.Label
        AutostitchsessionsButton      matlab.ui.control.Button
        SetreferencepointButton       matlab.ui.control.Button
        StitchsessionsLabel           matlab.ui.control.Label
        AllcompleteCheckBox           matlab.ui.control.CheckBox
        AlignindividualsessionLabel   matlab.ui.control.Label
        LoadAlignmentButton           matlab.ui.control.Button
        SaveAlignmentButton           matlab.ui.control.Button
        AnimalField                   matlab.ui.control.EditField
        AnimalEditFieldLabel          matlab.ui.control.Label
        Down                          matlab.ui.control.Button
        PixelMove                     matlab.ui.control.NumericEditField
        Up                            matlab.ui.control.Button
        Right                         matlab.ui.control.Button
        Left                          matlab.ui.control.Button
        StructuresListBox             matlab.ui.control.ListBox
        StructuresListBoxLabel        matlab.ui.control.Label
        ManualsessionAlignmentButton  matlab.ui.control.Button
        PixelperumEditField           matlab.ui.control.NumericEditField
        ImagingSessionsListBox        matlab.ui.control.ListBox
        ImagingSessionsListBoxLabel   matlab.ui.control.Label
        PixelperumEditFieldLabel      matlab.ui.control.Label
        AutosessionAlignmentButton    matlab.ui.control.Button
        LoadSTEDData                  matlab.ui.control.Button
        UICellConst                   matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        coordDataFile %file Name of exceel sheet
        coordDataPath %file path of excel sheet
        SessionData = struct('SizeX', [], 'SizeY', [], 'NrStruct', [], 'Image', [], 'Name', [], 'Structures', [], 'RefPoint', [], 'RelLeft', [],  'RelRight', [], 'RelUp', [], 'RelDown', []); %contains all data of the individual sessions
        structPerSheet %how many files to we have per sheet?
        SelectedItems = struct('currSheet', [], 'SheetNr', [], 'currStruct', [], 'structID', [], 'MoveInPixel', 10, 'visibleID', [], 'currVisibleSess', [], 'currMovSess', [], 'SessMoveNr', [], 'MoveSess', 10)
        CellConstImg
    end
    
    methods (Access = private)
        
        function UpdateImageAxis(app)
            %think about how to clear the axes beforehand
            %app.UIAxes = uiaxes(app.UIFigure);
            cla(app.UIAxes);
            %app.UIAxes.Visible = 'off';
            
            allImages = zeros(app.SessionData(app.SelectedItems.SheetNr).SizeY, app.SessionData(app.SelectedItems.SheetNr).SizeX, app.SessionData(app.SelectedItems.SheetNr).NrStruct);
            
            for d = 1:size(allImages,3)
                allImages(app.SessionData(app.SelectedItems.SheetNr).Structures(d).PixelYUp :app.SessionData(app.SelectedItems.SheetNr).Structures(d).PixelYDown,app.SessionData(app.SelectedItems.SheetNr).Structures(d).PixelXLeft:app.SessionData(app.SelectedItems.SheetNr).Structures(d).PixelXRight,d) = app.SessionData(app.SelectedItems.SheetNr).Structures(d).Image;
            end
            
            app.UIAxes.Visible = 'on';
            meanImg = mean(allImages,3);
            imagesc(meanImg,'Parent',app.UIAxes);
            axis(app.UIAxes,'image')
            axis(app.UIAxes, 'off');
            
            if ~isempty(app.SelectedItems.currStruct)
                for s = 1:size(app.SelectedItems.structID,2)
                    id = cell2mat(app.SelectedItems.structID(s));
                    h = app.SessionData(app.SelectedItems.SheetNr).Structures(id).PixelYDown-app.SessionData(app.SelectedItems.SheetNr).Structures(id).PixelYUp;
                    w = app.SessionData(app.SelectedItems.SheetNr).Structures(id).PixelXRight-app.SessionData(app.SelectedItems.SheetNr).Structures(id).PixelXLeft;
                    rectangle(app.UIAxes, 'Position', [app.SessionData(app.SelectedItems.SheetNr).Structures(id).PixelXLeft,app.SessionData(app.SelectedItems.SheetNr).Structures(id).PixelYUp,w,h], 'EdgeColor', 'white', 'LineStyle', '--')
                end
            end
            
            %save in structure
            app.SessionData(app.SelectedItems.SheetNr).Image = meanImg;
        end
        
        function UpdateCellConst(app)
            allSession = zeros(size(app.CellConstImg,1), size(app.CellConstImg,2), size(app.SessionData,2));
            
            if ~isempty(app.SelectedItems.currVisibleSess)
                for vs = 1:size(app.SelectedItems.visibleID,2)
                    id = cell2mat(app.SelectedItems.visibleID(vs));
                    allSession(app.SessionData(id).RelUp:app.SessionData(id).RelDown, app.SessionData(id).RelLeft:app.SessionData(id).RelRight,vs) = app.SessionData(id).Image;
                end
            end
            
            CellAll = mean(allSession,3);
            app.UICellConst.Visible = 'on';
            imagesc(CellAll, 'Parent', app.UICellConst);
            axis(app.UICellConst, 'image');
            axis(app.UICellConst,'off');
            
            if ~isempty(app.SelectedItems.currMovSess)
                h = app.SessionData(app.SelectedItems.SessMoveNr).RelDown-app.SessionData(app.SelectedItems.SessMoveNr).RelUp;
                w = app.SessionData(app.SelectedItems.SessMoveNr).RelRight-app.SessionData(app.SelectedItems.SessMoveNr).RelLeft;
                rectangle(app.UICellConst, 'Position', [app.SessionData(app.SelectedItems.SessMoveNr).RelLeft,app.SessionData(app.SelectedItems.SessMoveNr).RelUp,w,h], 'EdgeColor', 'white', 'LineStyle', '--')
            end
            app.CellConstImg = CellAll;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ImagingSessionsListBox
        function UpdateSession(app, event)
            app.SelectedItems.currSheet = app.ImagingSessionsListBox.Value;
            sheetNames = app.ImagingSessionsListBox.Items;
            app.SelectedItems.SheetNr = find(contains(sheetNames,app.SelectedItems.currSheet));
        end

        % Value changed function: StructuresListBox
        function UpdateStructure(app, event)
            app.SelectedItems.currStruct = app.StructuresListBox.Value;
            structNames = app.StructuresListBox.Items;
            app.SelectedItems.structID = [];
            for sel=1:length(app.SelectedItems.currStruct)
                app.SelectedItems.structID{sel} = find(contains(structNames,app.SelectedItems.currStruct{sel}));
            end
            UpdateImageAxis(app)
        end

        % Value changed function: PixelMove
        function UpdateMovement(app, event)
            app.SelectedItems.MoveInPixel = app.PixelMove.Value;
        end

        % Value changed function: VisibleSessionsListBox
        function UpdateVisibleSess(app, event)
            app.SelectedItems.currVisibleSess = app.VisibleSessionsListBox.Value;
            sessNames = app.VisibleSessionsListBox.Items;
            app.SelectedItems.visibleID = [];
            
            for sel = 1:length(app.SelectedItems.currVisibleSess)
                app.SelectedItems.visibleID{sel} = find(contains(sessNames, app.SelectedItems.currVisibleSess{sel}));
            end
           
            UpdateCellConst(app)
        end

        % Value changed function: MoveDropDown
        function UpdateMoveSess(app, event)
            app.SelectedItems.currMovSess = app.MoveDropDown.Value;
            sessNames = app.MoveDropDown.Items;
            app.SelectedItems.SessMoveNr = find(contains(sessNames,app.SelectedItems.currMovSess));
            UpdateCellConst(app)
        end

        % Value changed function: PixelMoveStitch
        function UpdateSessMove(app, event)
            app.SelectedItems.MoveSess = app.PixelMoveStitch.Value;      
        end

        % Button pushed function: LoadSTEDData
        function LoadSTEDDataPushed(app, event)
             % Get coordFile
            animal = app.AnimalField.Value;
	        [app.coordDataFile, app.coordDataPath] = uigetfile('*.xlsx', 'Select excel-file for reading in coordinates',['Y:\Data_JJ_Ferret_project\JJ_FerretProject_Organized\' char(animal) filesep]);
            sheets = sheetnames([app.coordDataPath app.coordDataFile]);
            app.ImagingSessionsListBox.Items = sheets;
            for sh = 1:length(sheets)
                [~, ~, all]=xlsread([app.coordDataPath app.coordDataFile], sheets(sh));
                app.structPerSheet(sh) = size(all,1);
            end
            
            %switch on visiblity of next steps
            app.ImagingSessionsListBoxLabel.Visible = 'on';
            app.ImagingSessionsListBox.Visible = 'on';
            app.AutosessionAlignmentButton.Visible = 'on';
            app.SKIPAlignmentButton.Visible = 'on';
        end

        % Button pushed function: AutosessionAlignmentButton
        function AutoAlignment(app, event)
            pixelperUm = app.PixelperumEditField.Value;
            animal = app.AnimalField.Value;
            
            %Step 1: read in data from selected sheet
            [~, xls_txt, xls_all]=xlsread([app.coordDataPath app.coordDataFile], app.SelectedItems.currSheet);
            coordInfo = struct;
            fileNameCol = find(contains(xls_txt(1,:),'File'),1);
            xPosCol = find(contains(xls_txt(1,:),'xPos'),1);
            yPosCol = find(contains(xls_txt(1,:),'yPos'),1);
            SizeXCol = find(contains(xls_txt(1,:),'SizeX'),1);
            SizeYCol = find(contains(xls_txt(1,:),'SizeY'),1);
            AngleCol = find(contains(xls_txt(1,:),'Angle'),1);
            InputCol = find(contains(xls_txt(1,:),'Input?'),1);
            PixelXCol = find(contains(xls_txt(1,:),'PixelSizeX'),1);
            PixelYCol = find(contains(xls_txt(1,:),'PixelSizeY'),1); 
            
            k = 1;
            for i = 2:size(xls_txt,1)
                coordInfo(k).file = xls_all(i,fileNameCol);
                coordInfo(k).xPos = cell2mat(xls_all(i,xPosCol));
                coordInfo(k).yPos = cell2mat(xls_all(i,yPosCol));
                coordInfo(k).Angle = cell2mat(xls_all(i,AngleCol));
                coordInfo(k).SizeX = cell2mat(xls_all(i,SizeXCol));
                coordInfo(k).SizeY = cell2mat(xls_all(i,SizeYCol));
                coordInfo(k).PixelX = cell2mat(xls_all(i,PixelXCol));
                coordInfo(k).PixelY = cell2mat(xls_all(i,PixelYCol));
                coordInfo(k).Input = cell2mat(xls_all(i,InputCol));
                k = k+1;
            end
            
            %Step 2: Read in tif data, scale them to fit and rotate
            tifFolder = ['Y:\Data_JJ_Ferret_project\JJ_FerretProject_Organized\' char(animal) filesep char(app.SelectedItems.currSheet) '\Copies\'];
            for ind= 1:size(coordInfo,2)
                try
                    coordInfo(ind).img = imread([char(tifFolder) char(coordInfo(ind).file) '.tif']);
                catch
                    coordInfo(ind).img = imread([char(tifFolder) char(coordInfo(ind).file) '.tiff']);
                end
                %scale it in terms of max and min
                coordInfo(ind).img = mat2gray(coordInfo(ind).img);
                %rotate image using angle in file
                coordInfo(ind).rotImg = imrotate(coordInfo(ind).img, coordInfo(ind).Angle);
                %add scaled image of 1 pixel = 0.04 um = 25 pixel per um
                scale = coordInfo(ind).SizeX*coordInfo(ind).PixelX*pixelperUm/size(coordInfo(ind).img,2);
                coordInfo(ind).ImageScaled = imresize(coordInfo(ind).rotImg,scale);
            end

            %Step 3: Find the position of the left upper corner, position of image is
            %given as the center - those are um positions!
            for i =1:length(coordInfo)

                %calc size of each image)
                coordInfo(i).SizeXum = 1/pixelperUm*size(coordInfo(i).ImageScaled,2);
                coordInfo(i).SizeYum = 1/pixelperUm*size(coordInfo(i).ImageScaled,1);
                
                if coordInfo(i).xPos > 0
                    coordInfo(i).AbsPosLeftX = coordInfo(i).xPos + coordInfo(i).SizeXum/2;
                    coordInfo(i).AbsPosRightX = coordInfo(i).AbsPosLeftX - coordInfo(i).SizeXum; 
                else
                    coordInfo(i).AbsPosLeftX = coordInfo(i).xPos - coordInfo(i).SizeXum/2;
                    coordInfo(i).AbsPosRightX = coordInfo(i).AbsPosLeftX + coordInfo(i).SizeXum;
                end
                
                coordInfo(i).AbsPosUpY = coordInfo(i).yPos - coordInfo(i).SizeYum/2;
                coordInfo(i).AbsPosDownY = coordInfo(i).AbsPosUpY  + coordInfo(i).SizeYum;
            end
        
            %Step 4: Get the 0/0 position and write everything into relative positions
            %Left upper corner of the resulting image should be maxX, MinY
            if max(coordInfo(i).xPos) > 0
                startPosLeft = max([coordInfo.AbsPosLeftX]);
                endPosRight = min([coordInfo.AbsPosRightX]);
            else
                startPosLeft = min([coordInfo.AbsPosLeftX]);
                endPosRight = max([coordInfo.AbsPosRightX]);
            end
            
            startPosUp = min([coordInfo.AbsPosUpY]);
            endPosDown = max([coordInfo.AbsPosDownY]);
        
            for i =1:length(coordInfo)
                if coordInfo(i).AbsPosLeftX > 0
                    coordInfo(i).RelPosLeftX = startPosLeft - coordInfo(i).AbsPosLeftX;
                    coordInfo(i).RelPosRightX = startPosLeft - coordInfo(i).AbsPosRightX;
                else
                    coordInfo(i).RelPosLeftX = coordInfo(i).AbsPosLeftX-startPosLeft;
                    coordInfo(i).RelPosRightX = coordInfo(i).AbsPosRightX-startPosLeft;
                end
                coordInfo(i).RelPosUpY = coordInfo(i).AbsPosUpY - startPosUp;
                coordInfo(i).RelPosDownY = coordInfo(i).AbsPosDownY - startPosUp;
            end
        
            %Step 5: Get pixel coordinates
            %define size of image in total - one z per image minus cell image
            sizeX = ceil((abs(startPosLeft-endPosRight))*pixelperUm)+1; %size in um -> lets have 25 pixels per um
            sizeY = ceil((abs(startPosUp-endPosDown))*pixelperUm)+1;
                
            for d = 1:length(coordInfo)
                %coordinates width
                coordInfo(d).pixelPosLeftX = ceil(coordInfo(d).RelPosLeftX*pixelperUm);
                if coordInfo(d).pixelPosLeftX == 0
                    coordInfo(d).pixelPosLeftX = coordInfo(d).pixelPosLeftX+1;
                end
                coordInfo(d).pixelPosRightX = coordInfo(d).pixelPosLeftX+size(coordInfo(d).ImageScaled,2)-1;
        
                %coordinates height
                coordInfo(d).pixelPosUpY = ceil(coordInfo(d).RelPosUpY*pixelperUm);
                if coordInfo(d).pixelPosUpY == 0
                    coordInfo(d).pixelPosUpY = coordInfo(d).pixelPosUpY+1;
                end
                coordInfo(d).pixelPosDownY = coordInfo(d).pixelPosUpY+size(coordInfo(d).ImageScaled,1)-1;
        
            end
            
            for i = 1:length(coordInfo)
                app.SessionData(app.SelectedItems.SheetNr).Structures(i).Name = coordInfo(i).file;
                app.SessionData(app.SelectedItems.SheetNr).Structures(i).Input = coordInfo(i).Input;
                app.SessionData(app.SelectedItems.SheetNr).Structures(i).PixelXLeft = coordInfo(i).pixelPosLeftX;
                app.SessionData(app.SelectedItems.SheetNr).Structures(i).PixelXRight = coordInfo(i).pixelPosRightX;
                app.SessionData(app.SelectedItems.SheetNr).Structures(i).PixelYUp = coordInfo(i).pixelPosUpY;
                app.SessionData(app.SelectedItems.SheetNr).Structures(i).PixelYDown = coordInfo(i).pixelPosDownY;
                app.SessionData(app.SelectedItems.SheetNr).Structures(i).Image = coordInfo(i).ImageScaled;
            end
            
            app.SessionData(app.SelectedItems.SheetNr).SizeX = sizeX;
            app.SessionData(app.SelectedItems.SheetNr).SizeY = sizeY;
            app.SessionData(app.SelectedItems.SheetNr).NrStruct = length(coordInfo);
            app.SessionData(app.SelectedItems.SheetNr).Name = app.SelectedItems.currSheet;
            
            UpdateImageAxis(app)
            
            %enable manual alignment
            app.ManualsessionAlignmentButton.Visible = 'on';
            app.StructuresListBox.Visible = 'on';
            app.Down.Visible = 'on';
            app.Up.Visible = 'on';
            app.Right.Visible = 'on';
            app.Left.Visible = 'on';
            app.PixelMove.Visible = 'on';
            app.AllcompleteCheckBox.Visible = 'on';
            app.SaveAlignmentButton.Visible = 'on';
            app.LoadAlignmentButton.Visible = 'on';
        end

        % Button pushed function: ManualsessionAlignmentButton
        function StartManualAlignment(app, event)
            %first clear the Box
            [~,idx] = ismember(app.StructuresListBox.Value,app.StructuresListBox.Items);
            app.StructuresListBox.Items(idx) = [];
            
            %get sheet file and information            
            for t = 1:app.SessionData(app.SelectedItems.SheetNr).NrStruct
                StructNames(t)=string(app.SessionData(app.SelectedItems.SheetNr).Structures(t).Name); 
            end
            app.StructuresListBox.Items = StructNames;
        end

        % Button pushed function: Up
        function MoveUp(app, event)
            for s = 1:length(app.SelectedItems.structID)
                itemID = cell2mat(app.SelectedItems.structID(s));
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYUp = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYUp-app.SelectedItems.MoveInPixel;
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYDown = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYDown-app.SelectedItems.MoveInPixel;
                % adjust for having to add more space in the overview
                if app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYUp < 1
                    pixelsToAdd = -app.SessionData(app.SelectedItems.SheetNr).Structures(s).PixelYUp+1;
                    for t = 1:app.SessionData(app.SelectedItems.SheetNr).NrStruct
                        app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelYUp = app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelYUp+pixelsToAdd;
                        app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelYDown = app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelYDown+pixelsToAdd;
                    end
                end
            end
            
            UpdateImageAxis(app)
        end

        % Button pushed function: Right
        function MoveRight(app, event)
            for s = 1:length(app.SelectedItems.structID)
                itemID = cell2mat(app.SelectedItems.structID(s));
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXLeft = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXLeft+app.SelectedItems.MoveInPixel;
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXRight = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXRight+app.SelectedItems.MoveInPixel;
            end
            
            UpdateImageAxis(app)
        end

        % Button pushed function: Down
        function MoveDown(app, event)
            for s = 1:length(app.SelectedItems.structID)
                itemID = cell2mat(app.SelectedItems.structID(s));
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYUp = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYUp+app.SelectedItems.MoveInPixel;
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYDown = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelYDown+app.SelectedItems.MoveInPixel;
            end
            
            UpdateImageAxis(app)
        end

        % Button pushed function: Left
        function MoveLeft(app, event)
           for s = 1:length(app.SelectedItems.structID)
                itemID = cell2mat(app.SelectedItems.structID(s));
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXLeft = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXLeft-app.SelectedItems.MoveInPixel;
                app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXRight = app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXRight-app.SelectedItems.MoveInPixel;
                % adjust for having to add more space in the overview
                if app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXLeft < 1
                    pixelsToAdd = -app.SessionData(app.SelectedItems.SheetNr).Structures(itemID).PixelXLeft+1;
                    for t = 1:app.SessionData(app.SelectedItems.SheetNr).NrStruct
                        app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelXLeft = app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelXLeft+pixelsToAdd;
                        app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelXRight = app.SessionData(app.SelectedItems.SheetNr).Structures(t).PixelXRight+pixelsToAdd;
                    end
                end
            end
            
            UpdateImageAxis(app)
        end

        % Button pushed function: LoadAlignmentButton
        function LoadAlignData(app, event)
            [fileName, Path] = uigetfile('*.mat', 'Select mat-file for reading in aligned coordinates',app.coordDataPath);
            load([Path fileName], 'coordData');
            
            app.SessionData(app.SelectedItems.SheetNr) = coordData;
            UpdateImageAxis(app)
        end

        % Button pushed function: SaveAlignmentButton
        function SaveAlign(app, event)
            %get today's date
            todayDate = today('datetime');
            %save output image
            alignedImage = app.SessionData(app.SelectedItems.SheetNr).Image;
            imwrite(alignedImage, [app.coordDataPath filesep char(app.SelectedItems.currSheet) '_aligned.tif'], 'tiff');
            
            %save aligned pixels
            coordData = app.SessionData(app.SelectedItems.SheetNr);
            save([app.coordDataPath filesep char(app.SelectedItems.currSheet) '_aligned' char(todayDate) '.mat'],'coordData');
        end

        % Value changed function: AllcompleteCheckBox
        function CheckAllAligned(app, event)
            allDone = app.AllcompleteCheckBox.Value;
            if allDone
                %check if all of the structures are filled out and have a
                %position
                for sess = 1:size(app.SessionData)
                    if isempty(app.SessionData(sess).Structures)
                        allDone = 0;
                        disp('Alignment is not finished for all sessions')
                        break
                    end
                end
            end
            %update the value of the box accordingly
            app.AllcompleteCheckBox.Value = allDone;
            
            
            %if really done, save everything with today's date and enable
            %the next step
            if app.AllcompleteCheckBox.Value == 1
                %get today's date
                todayDate = today('datetime');
                for sessions = 1:length(app.SessionData)
                    coordData = app.SessionData(sessions);
                    save([app.coordDataPath filesep char(app.SessionData(sessions).Name) '_aligned' char(todayDate) '.mat'],'coordData');
                end
                
                %switch on visibility
                app.SetreferencepointButton.Visible = 'on';
                app.ReferencessetCheckBox.Visible = 'on';
                app.SavereferencepointButton.Visible = 'on';
                app.LoadreferencepointButton.Visible = 'on';
                app.LoadallreferencepointsButton.Visible = 'on';
                
                %switch off visibilty section 2
                app.AutosessionAlignmentButton.Visible = 'off';
                app.ManualsessionAlignmentButton.Visible = 'off';
                app.StructuresListBox.Visible = 'off';
                app.Down.Visible = 'off';
                app.Up.Visible = 'off';
                app.Right.Visible = 'off';
                app.Left.Visible = 'off';
                app.PixelMove.Visible = 'off';
                app.AllcompleteCheckBox.Visible = 'off';
                app.SaveAlignmentButton.Visible = 'off';
                app.LoadAlignmentButton.Visible = 'off';
            end
            
        end

        % Button pushed function: SKIPAlignmentButton
        function SkipAlign(app, event)
            for sessions = 1:size(app.ImagingSessionsListBox.Items,2)
                %find .mat files
                name = cell2mat(app.ImagingSessionsListBox.Items(sessions));
                matFiles = dir([app.coordDataPath filesep char(name) '_aligned*mat']);
                if length(matFiles) > 1 %if there is more than one, take the most recent one
                    [~,I] = max([matFiles(:).datenum]);
                    fileName = matFiles(I).name;
                else
                    fileName = matFiles.name;
                end
                
                load([app.coordDataPath fileName], 'coordData');
                app.SessionData(sessions).SizeX = coordData.SizeX;
                app.SessionData(sessions).SizeY = coordData.SizeY;
                app.SessionData(sessions).Image = coordData.Image;
                app.SessionData(sessions).Name = coordData.Name;
                app.SessionData(sessions).Structures = coordData.Structures;
                app.SessionData(sessions).NrStruct = coordData.NrStruct;
            end 
            %change visibility
            app.SetreferencepointButton.Visible = 'on';
            app.ReferencessetCheckBox.Visible = 'on';
            app.SavereferencepointButton.Visible = 'on';
            app.LoadreferencepointButton.Visible = 'on';
            app.LoadallreferencepointsButton.Visible = 'on';
            app.AutosessionAlignmentButton.Visible = 'off';
            app.ManualsessionAlignmentButton.Visible = 'off';
            app.StructuresListBox.Visible = 'off';
            app.Down.Visible = 'off';
            app.Up.Visible = 'off';
            app.Right.Visible = 'off';
            app.Left.Visible = 'off';
            app.PixelMove.Visible = 'off';
            app.AllcompleteCheckBox.Visible = 'off';
            app.SaveAlignmentButton.Visible = 'off';
            app.LoadAlignmentButton.Visible = 'off';
            app.SKIPAlignmentButton.Visible = 'off';
        end

        % Button pushed function: SetreferencepointButton
        function SetReference(app, event)
            if ~isempty(app.SelectedItems.SheetNr)
                ImgDisplay = app.SessionData(app.SelectedItems.SheetNr).Image;
                uax = uiaxes;
                imagesc(ImgDisplay,'Parent',uax);
                axis(uax,'image')
                axis(uax, 'off');
                h = drawcircle(uax);
                app.SessionData(app.SelectedItems.SheetNr).RefPoint = h.Center;
            else
                disp('Please select an image to select the reference point')
            end
        end

        % Button pushed function: LoadreferencepointButton
        function LoadRefPoint(app, event)
            [fileName, Path] = uigetfile('*.mat', 'Select mat-file for reading in reference point',app.coordDataPath);
            load([Path fileName], 'SessRefPoint');
            app.SessionData(app.SelectedItems.SheetNr).RefPoint = SessRefPoint;
        end

        % Button pushed function: LoadallreferencepointsButton
        function LoadallRefPoints(app, event)
            for sessions = 1:size(app.ImagingSessionsListBox.Items,2)
                %find .mat files
                name = cell2mat(app.ImagingSessionsListBox.Items(sessions));
                matFiles = dir([app.coordDataPath filesep char(name) '_RefPoint*mat']);
                if length(matFiles) > 1 %if there is more than one, take the most recent one
                    [~,I] = max([matFiles(:).datenum]);
                    fileName = matFiles(I).name;
                else
                    fileName = matFiles.name;
                end
                
                load([app.coordDataPath fileName], 'SessRefPoint');
                app.SessionData(sessions).RefPoint = SessRefPoint;
            end 
        end

        % Button pushed function: SavereferencepointButton
        function SaveRefPoint(app, event)
            %get today's date
            todayDate = today('datetime');
            %save aligned pixels
            SessRefPoint = app.SessionData(app.SelectedItems.SheetNr).RefPoint;
            save([app.coordDataPath filesep char(app.SelectedItems.currSheet) '_RefPoint_' char(todayDate) '.mat'],'SessRefPoint');
        end

        % Value changed function: ReferencessetCheckBox
        function CheckAllRefs(app, event)
            allAligned = app.ReferencessetCheckBox.Value;
            if allAligned
                %check if all of the structures are filled out and have a
                %position
                for sess = 1:size(app.SessionData)
                    if isempty(app.SessionData(sess).RefPoint)
                        allAligned = 0;
                        disp('Alignment is not finished for all sessions')
                        break
                    end
                end
            end
            %update the value of the box accordingly
            app.ReferencessetCheckBox.Value = allAligned;
            
            %if really change visiblity
            if app.ReferencessetCheckBox.Value == 1
                %save reference Points
                %get today's date
                todayDate = today('datetime');
                for sessions = 1:length(app.SessionData)
                    SessRefPoint = app.SessionData(sessions).RefPoint;
                    save([app.coordDataPath filesep char(app.SessionData(sessions).Name) '_RefPoint_' char(todayDate) '.mat'],'SessRefPoint');
                end
                
                %change visibility accordingly
                app.AutostitchsessionsButton.Visible = 'on';
                app.SetreferencepointButton.Visible = 'off';
                app.AllcompleteCheckBox.Visible = 'off';
                app.SavereferencepointButton.Visible = 'off';
                app.LoadreferencepointButton.Visible = 'off';
                app.LoadallreferencepointsButton.Visible = 'off';
                app.ReferencessetCheckBox.Visible = 'off';
                app.LOADButton.Visible = 'on';
            end
        end

        % Button pushed function: AutostitchsessionsButton
        function AutoStitch(app, event)
            stitchCoord = struct;
            
             %Step 1: FOr each session, set the Reference Point as (0/0)
             %and calculate the four corners -> up and left will be -
            for sess = 1:size(app.SessionData,2)
                stitchCoord(sess).AbsLeft = 1-ceil(app.SessionData(sess).RefPoint(1));
                stitchCoord(sess).AbsUp = 1-ceil(app.SessionData(sess).RefPoint(2));
                stitchCoord(sess).AbsRight = stitchCoord(sess).AbsLeft+size(app.SessionData(sess).Image,2);
                stitchCoord(sess).AbsDown = stitchCoord(sess).AbsUp+size(app.SessionData(sess).Image,1);
            end
            
            %Step 2: Get the start point for calculating the relative
            %position of the four coners and calc new coordinates
            startLeft = min([stitchCoord.AbsLeft]);
            startUp = min([stitchCoord.AbsUp]);
            
            for sess = 1:size(app.SessionData,2)
                stitchCoord(sess).RelLeft = ceil(stitchCoord(sess).AbsLeft - startLeft)+1;
                stitchCoord(sess).RelRight = stitchCoord(sess).RelLeft+size(app.SessionData(sess).Image,2)-1;
                stitchCoord(sess).RelUp = ceil(stitchCoord(sess).AbsUp - startUp)+1;
                stitchCoord(sess).RelDown = stitchCoord(sess).RelUp+size(app.SessionData(sess).Image,1)-1;
                stitchCoord(sess).NewSizeX = length(stitchCoord(sess).RelUp:stitchCoord(sess).RelDown);
                stitchCoord(sess).NewSizeY = length(stitchCoord(sess).RelLeft:stitchCoord(sess).RelRight);
            end
            
            %Step 3: create an empty variable to write all in it and write
            %to session data
            CellReconst = zeros(ceil(max([stitchCoord.RelDown])),max(ceil([stitchCoord.RelRight])),size(app.SessionData,2));
            
            for sess = 1:size(app.SessionData,2)
                CellReconst(stitchCoord(sess).RelUp:stitchCoord(sess).RelDown,stitchCoord(sess).RelLeft:stitchCoord(sess).RelRight,sess) = app.SessionData(sess).Image;
                app.SessionData(sess).RelUp =  stitchCoord(sess).RelUp;
                app.SessionData(sess).RelDown =  stitchCoord(sess).RelDown;
                app.SessionData(sess).RelLeft =  stitchCoord(sess).RelLeft;
                app.SessionData(sess).RelRight =  stitchCoord(sess).RelRight;
            end
            
            app.CellConstImg = CellReconst;
                        
            % Step 4: Change visibility
            app.PixelMoveStitch.Visible = 'on';
            app.UpStitch.Visible = 'on';
            app.DownStitch.Visible = 'on';
            app.LeftStitch.Visible = 'on';
            app.RightStitch.Visible = 'on';
            app.VisibleSessionsListBox.Visible = 'on';
            app.VisibleSessionsListBoxLabel.Visible = 'on';
            app.SAVEButton.Visible = 'on';
            app.MoveDropDown.Visible = 'on';
            app.MoveDropDownLabel.Visible = 'on';
            
            %Step 5: Add sessions to list of visible session, mark all as
            %visible, and add sessions to move dropdown
            app.VisibleSessionsListBox.Items = app.ImagingSessionsListBox.Items;
            app.VisibleSessionsListBox.Value = app.VisibleSessionsListBox.Items;
            app.SelectedItems.currVisibleSess = app.VisibleSessionsListBox.Value;
            sessNames = app.VisibleSessionsListBox.Items;
            app.SelectedItems.visibileID = [];
            
            for sel = 1:length(app.SelectedItems.currVisibleSess)
                app.SelectedItems.visibleID{sel} = find(contains(sessNames, app.SelectedItems.currVisibleSess{sel}));
            end
            
            app.MoveDropDown.Items = app.ImagingSessionsListBox.Items;
            
            %Step 6: Update axis
            UpdateCellConst(app)
        end

        % Button pushed function: UpStitch
        function MoveSessUp(app, event)
            app.SessionData(app.SelectedItems.SessMoveNr).RelUp = app.SessionData(app.SelectedItems.SessMoveNr).RelUp-app.SelectedItems.MoveSess;
            app.SessionData(app.SelectedItems.SessMoveNr).RelDown = app.SessionData(app.SelectedItems.SessMoveNr).RelDown-app.SelectedItems.MoveSess;
            % adjust for having to add more space in the overview
            if app.SessionData(app.SelectedItems.SessMoveNr).RelUp < 1
                pixelsToAdd = -app.SessionData(app.SelectedItems.SessMoveNr).RelUp+1;
                for t = 1:size(app.SessionData,2)
                    app.SessionData(t).RelUp = app.SessionData(t).RelUp+pixelsToAdd;
                    app.SessionData(t).RelDown = app.SessionData(t).RelDown+pixelsToAdd;
                end
            end
            UpdateCellConst(app)
        end

        % Button pushed function: DownStitch
        function MoveSessDown(app, event)
            app.SessionData(app.SelectedItems.SessMoveNr).RelUp = app.SessionData(app.SelectedItems.SessMoveNr).RelUp+app.SelectedItems.MoveSess;
            app.SessionData(app.SelectedItems.SessMoveNr).RelDown = app.SessionData(app.SelectedItems.SessMoveNr).RelDown+app.SelectedItems.MoveSess;
            UpdateCellConst(app)
        end

        % Button pushed function: RightStitch
        function MoveSessRight(app, event)
            app.SessionData(app.SelectedItems.SessMoveNr).RelLeft = app.SessionData(app.SelectedItems.SessMoveNr).RelLeft+app.SelectedItems.MoveSess;
            app.SessionData(app.SelectedItems.SessMoveNr).RelRight = app.SessionData(app.SelectedItems.SessMoveNr).RelRight+app.SelectedItems.MoveSess;
            UpdateCellConst(app)
        end

        % Button pushed function: LeftStitch
        function MoveSessLeft(app, event)
            app.SessionData(app.SelectedItems.SessMoveNr).RelLeft = app.SessionData(app.SelectedItems.SessMoveNr).RelLeft-app.SelectedItems.MoveSess;
            app.SessionData(app.SelectedItems.SessMoveNr).RelRight = app.SessionData(app.SelectedItems.SessMoveNr).RelRight-app.SelectedItems.MoveSess;
            % adjust for having to add more space in the overview
            if app.SessionData(app.SelectedItems.SessMoveNr).RelLeft < 1
                pixelsToAdd = -app.SessionData(app.SelectedItems.SessMoveNr).RelLeft+1;
                for t = 1:size(app.SessionData,2)
                    app.SessionData(t).RelLeft = app.SessionData(t).RelLeft+pixelsToAdd;
                    app.SessionData(t).RelRight = app.SessionData(t).RelRight+pixelsToAdd;
                end
            end
            UpdateCellConst(app)
        end

        % Button pushed function: SAVEButton
        function SaveAll(app, event)
           %get today's date
            todayDate = today('datetime');
            % 1.) Save output image
            imwrite(app.CellConstImg, [app.coordDataPath filesep 'FullCell_' char(todayDate) '.tif'], 'tiff');
            
            % 2.) Write new structure of all data in coordinates relative
            % to CellConstImg
            
            STEDData = struct;
            i = 1;
            for s=1:size(app.SessionData,2)
                for str=1:size(app.SessionData(s).Structures,2)
                    name = cell2mat(app.SessionData(s).Structures(str).Name);
                    STEDData(i).Name = name;
                    if find(contains(name,'Cell'))
                        STEDData(i).type = 'Cell';
                    elseif find(contains(name,'Dendrite'))
                        STEDData(i).type = 'Dendrite';
                    else
                        STEDData(i).type = 'Spine';
                    end
                    STEDData(i).Image = app.SessionData(s).Structures(str).Image;
                    STEDData(i).Left = app.SessionData(s).RelLeft+app.SessionData(s).Structures(str).PixelXLeft;
                    STEDData(i).Up = app.SessionData(s).RelUp +app.SessionData(s).Structures(str).PixelYUp;
                    STEDData(i).Input  = app.SessionData(s).Structures(str).Input;
                    i = i+1;
                end
            end
            
            % 3.) Save variables used and new structure
            ImgSessionData = app.SessionData;
            CellImage = app.CellConstImg;
            save([app.coordDataPath filesep 'CellData_' char(todayDate) '.mat'],'ImgSessionData', 'CellImage', 'STEDData');
        end

        % Button pushed function: LOADButton
        function LoadAll(app, event)
            % 1.) Load file
            [File, DataPath] = uigetfile('*.mat', 'Please select file for loading cell datas',app.coordDataPath);
            a = load([DataPath File]);
            
            % 2.) Get variables
            app.SessionData = a.ImgSessionData;
            app.CellConstImg = a.CellImage;
            
            % 3.) Update axis
            UpdateCellConst(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UICellConst = uiaxes(app.UIFigure);
            app.UICellConst.Visible = 'off';
            app.LoadSTEDData = uibutton(app.UIFigure, 'push');
            app.LoadSTEDData.ButtonPushedFcn = createCallbackFcn(app, @LoadSTEDDataPushed, true);
            app.LoadSTEDData.Text = '1. Load STED data';
            app.AutosessionAlignmentButton = uibutton(app.UIFigure, 'push');
            app.AutosessionAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAlignment, true);
            app.AutosessionAlignmentButton.Visible = 'off';
            app.AutosessionAlignmentButton.Text = 'Auto session Alignment';
            app.PixelperumEditFieldLabel = uilabel(app.UIFigure);
            app.PixelperumEditFieldLabel.Text = 'Pixel per um';
            app.ImagingSessionsListBoxLabel = uilabel(app.UIFigure);
            app.ImagingSessionsListBoxLabel.Visible = 'off';
            app.ImagingSessionsListBoxLabel.Text = 'Imaging Sessions';
            app.ImagingSessionsListBox = uilistbox(app.UIFigure);
            app.ImagingSessionsListBox.Items = {};
            app.ImagingSessionsListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSession, true);
            app.ImagingSessionsListBox.Visible = 'off';
            app.ImagingSessionsListBox.Value = {};
            app.PixelperumEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelperumEditField.Limits = [1 50];
            app.PixelperumEditField.Value = 10;
            app.ManualsessionAlignmentButton = uibutton(app.UIFigure, 'push');
            app.ManualsessionAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @StartManualAlignment, true);
            app.ManualsessionAlignmentButton.Visible = 'off';
            app.ManualsessionAlignmentButton.Text = 'Manual session Alignment';
            app.StructuresListBoxLabel = uilabel(app.UIFigure);
            app.StructuresListBoxLabel.Visible = 'off';
            app.StructuresListBoxLabel.Text = 'Structures';
            app.StructuresListBox = uilistbox(app.UIFigure);
            app.StructuresListBox.Items = {};
            app.StructuresListBox.Multiselect = 'on';
            app.StructuresListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateStructure, true);
            app.StructuresListBox.Visible = 'off';
            app.StructuresListBox.Value = {};
            app.Left = uibutton(app.UIFigure, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @MoveLeft, true);
            app.Left.Visible = 'off';
            app.Left.Text = '<';
            app.Right = uibutton(app.UIFigure, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @MoveRight, true);
            app.Right.Visible = 'off';
            app.Right.Text = '>';
            app.Up = uibutton(app.UIFigure, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @MoveUp, true);
            app.Up.Visible = 'off';
            app.Up.Text = '/\';
            app.PixelMove = uieditfield(app.UIFigure, 'numeric');
            app.PixelMove.Limits = [1 50];
            app.PixelMove.ValueChangedFcn = createCallbackFcn(app, @UpdateMovement, true);
            app.PixelMove.Visible = 'off';
            app.PixelMove.Value = 10;
            app.Down = uibutton(app.UIFigure, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @MoveDown, true);
            app.Down.Visible = 'off';
            app.Down.Text = '\/';
            app.AnimalEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalField = uieditfield(app.UIFigure, 'text');
            app.AnimalField.Value = 'F2688';
            app.SaveAlignmentButton = uibutton(app.UIFigure, 'push');
            app.SaveAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAlign, true);
            app.SaveAlignmentButton.Visible = 'off';
            app.SaveAlignmentButton.Text = 'Save Alignment';
            app.LoadAlignmentButton = uibutton(app.UIFigure, 'push');
            app.LoadAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAlignData, true);
            app.LoadAlignmentButton.Visible = 'off';
            app.LoadAlignmentButton.Text = 'Load Alignment';
            app.AlignindividualsessionLabel = uilabel(app.UIFigure);
            app.AlignindividualsessionLabel.Text = '2. Align individual session';
            app.AllcompleteCheckBox = uicheckbox(app.UIFigure);
            app.AllcompleteCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckAllAligned, true);
            app.AllcompleteCheckBox.Visible = 'off';
            app.AllcompleteCheckBox.Text = 'All complete';
            app.StitchsessionsLabel = uilabel(app.UIFigure);
            app.StitchsessionsLabel.Text = '4. Stitch sessions';
            app.SetreferencepointButton = uibutton(app.UIFigure, 'push');
            app.SetreferencepointButton.ButtonPushedFcn = createCallbackFcn(app, @SetReference, true);
            app.SetreferencepointButton.Visible = 'off';
            app.SetreferencepointButton.Text = 'Set reference point';
            app.AutostitchsessionsButton = uibutton(app.UIFigure, 'push');
            app.AutostitchsessionsButton.ButtonPushedFcn = createCallbackFcn(app, @AutoStitch, true);
            app.AutostitchsessionsButton.Visible = 'off';
            app.AutostitchsessionsButton.Text = 'Auto stitch sessions ';
            app.VisibleSessionsListBoxLabel = uilabel(app.UIFigure);
            app.VisibleSessionsListBoxLabel.Visible = 'off';
            app.VisibleSessionsListBoxLabel.Text = 'Visible Sessions';
            app.VisibleSessionsListBox = uilistbox(app.UIFigure);
            app.VisibleSessionsListBox.Items = {};
            app.VisibleSessionsListBox.Multiselect = 'on';
            app.VisibleSessionsListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateVisibleSess, true);
            app.VisibleSessionsListBox.Visible = 'off';
            app.VisibleSessionsListBox.Value = {};
            app.LeftStitch = uibutton(app.UIFigure, 'push');
            app.LeftStitch.ButtonPushedFcn = createCallbackFcn(app, @MoveSessLeft, true);
            app.LeftStitch.Visible = 'off';
            app.LeftStitch.Text = '<';
            app.RightStitch = uibutton(app.UIFigure, 'push');
            app.RightStitch.ButtonPushedFcn = createCallbackFcn(app, @MoveSessRight, true);
            app.RightStitch.Visible = 'off';
            app.RightStitch.Text = '>';
            app.UpStitch = uibutton(app.UIFigure, 'push');
            app.UpStitch.ButtonPushedFcn = createCallbackFcn(app, @MoveSessUp, true);
            app.UpStitch.Visible = 'off';
            app.UpStitch.Text = '/\';
            app.DownStitch = uibutton(app.UIFigure, 'push');
            app.DownStitch.ButtonPushedFcn = createCallbackFcn(app, @MoveSessDown, true);
            app.DownStitch.Visible = 'off';
            app.DownStitch.Text = '\/';
            app.PixelMoveStitch = uieditfield(app.UIFigure, 'numeric');
            app.PixelMoveStitch.Limits = [1 50];
            app.PixelMoveStitch.ValueChangedFcn = createCallbackFcn(app, @UpdateSessMove, true);
            app.PixelMoveStitch.Visible = 'off';
            app.PixelMoveStitch.Value = 10;
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAll, true);
            app.SAVEButton.Visible = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.ReferencessetCheckBox = uicheckbox(app.UIFigure);
            app.ReferencessetCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckAllRefs, true);
            app.ReferencessetCheckBox.Visible = 'off';
            app.ReferencessetCheckBox.Text = 'References set';
            app.SavereferencepointButton = uibutton(app.UIFigure, 'push');
            app.SavereferencepointButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRefPoint, true);
            app.SavereferencepointButton.Visible = 'off';
            app.SavereferencepointButton.Text = 'Save reference point';
            app.LoadreferencepointButton = uibutton(app.UIFigure, 'push');
            app.LoadreferencepointButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRefPoint, true);
            app.LoadreferencepointButton.Visible = 'off';
            app.LoadreferencepointButton.Text = 'Load reference point';
            app.SKIPAlignmentButton = uibutton(app.UIFigure, 'push');
            app.SKIPAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @SkipAlign, true);
            app.SKIPAlignmentButton.Visible = 'off';
            app.SKIPAlignmentButton.Text = 'SKIP Alignment';
            app.MoveDropDownLabel = uilabel(app.UIFigure);
            app.MoveDropDownLabel.Visible = 'off';
            app.MoveDropDownLabel.Text = 'Move ';
            app.MoveDropDown = uidropdown(app.UIFigure);
            app.MoveDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateMoveSess, true);
            app.MoveDropDown.Visible = 'off';
            app.SetreferencepointsLabel = uilabel(app.UIFigure);
            app.SetreferencepointsLabel.Text = '3. Set reference points';
            app.LoadallreferencepointsButton = uibutton(app.UIFigure, 'push');
            app.LoadallreferencepointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadallRefPoints, true);
            app.LoadallreferencepointsButton.Visible = 'off';
            app.LoadallreferencepointsButton.Text = 'Load all reference points';
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAll, true);
            app.LOADButton.Visible = 'off';
            app.LOADButton.Text = 'LOAD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STEDAlignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end