classdef STEDcoverage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StartnextButton        matlab.ui.control.Button
        LoadSavedDataButton    matlab.ui.control.Button
        SaveDataButton         matlab.ui.control.Button
        DendritesListBox       matlab.ui.control.ListBox
        DendritesListBoxLabel  matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        MaximumSliderLabel     matlab.ui.control.Label
        MaximumSlider          matlab.ui.control.Slider
        LoadDendriteData       matlab.ui.control.Button
        AnimalField            matlab.ui.control.EditField
        AnimalEditFieldLabel   matlab.ui.control.Label
        BranchesListBox        matlab.ui.control.ListBox
        BranchesListBoxLabel   matlab.ui.control.Label
        LoadInfoButton         matlab.ui.control.Button
        UIData1                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ConfocalPath = []; 
        ConfocalImage = []
        DendData = [];
        tableData = [];
        cellReconstruction = []; % Description
        SelectedItems = struct('ConfBranch', [], 'Brightness', 1, 'currDendrites', [], 'currDendID', [])
    end
    
    methods (Access = private)
        
        function UpdateAxisConfocal(app)
            %Delete old traces
            oldImage = findobj(app.UIData1, 'Type', 'Image'); %delete old images
            delete(oldImage)
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            %Update the confocal image
            CellImg = app.ConfocalImage;
            CellImg = mat2gray(CellImg); %should scale the values between 0 and 1
            newImage = imadjust(CellImg, [0; app.SelectedItems.Brightness], []);
            imshow(newImage, 'Parent', app.UIData1);
            axis(app.UIData1, 'image');
            colormap(app.UIData1, 'gray')
        end
        
        function UpdateDendrites(app)
            hold(app.UIData1, 'on')
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            denColors = cbrewer('div', 'Spectral', length(app.DendData));
            for dend = 1:size(app.DendData,2)
                if ~isempty(find(cell2mat([app.SelectedItems.currDendID]) ==dend,1))
                    plot(app.UIData1, app.DendData(dend).newCoord(:,1),app.DendData(dend).newCoord(:,2), 'LineWidth',1.5, 'color',  denColors(dend,:));
                    hold(app.UIData1, 'on')
                end
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadInfoButton
        function LoadInfo(app, event)
            [~, xls_txt, xls_all]=xlsread('Z:\Juliane\Organization\Animals\SpinePerAnimal.xlsx', 'bimodal'); %open the sheet with the exp info
            animalCol = find(contains(xls_txt(1,:),'animal'),1); %which column is the animal name?
            dendriteCol = find(contains(xls_txt(1,:),'dendrite'),1); %dendrite ROI
            nameCol = find(contains(xls_txt(1,:),'name'),1); %exp Name
            
            animalExp = cellfun(@(x) find(contains(x, char(app.AnimalField.Value))),xls_txt(:,animalCol),'UniformOutput',false); %find the ones that contain the animal
            animalIDs = cellfun(@(x) ~isempty(x), animalExp);
            dendriteROIsAll = cell2mat(xls_all(animalIDs,dendriteCol)); %find the corresponding dendrite ROIs and exp Names
            expNrAll =  cell2mat(xls_all(animalIDs,nameCol));
            expNrDend = expNrAll(dendriteROIsAll>0,:); %filter for the ones that are dendrite ROIs
            %write list of selectable dendrites to confocal selector
            app.BranchesListBox.Items = string(expNrDend);
            
            
            %load cell reconstruction -> how many branches are there?
            ConfocalDir = 'Z:\Juliane\Data\Confocal\';
            animalName = char(app.AnimalField.Value);
            app.ConfocalPath = [char(ConfocalDir) char(animalName) filesep];
            app.cellReconstruction = load([app.ConfocalPath 'cellReconstruction.mat']);
            numBranches = size(app.cellReconstruction.Branches,2);
            %write list of selectable dendrites to confocal selector
            app.BranchesListBox.Items = string(linspace(1,numBranches, numBranches));
            
            %switch Button appearances 
            app.LoadDendriteData.Visible = 'on';
            app.LoadInfoButton.Enable = 'off';
        end

        % Button pushed function: LoadDendriteData
        function LoadData(app, event)
            %confocal data -> first load the image
            [fileName, Path] = uigetfile('*.tif', 'Select confocal image',app.ConfocalPath);
            %only need to load a partial part of the image
            BranchInfo = app.cellReconstruction.Branches(str2double(app.SelectedItems.ConfBranch));
            xSize = BranchInfo.xMax - BranchInfo.xMin+1;
            ySize = BranchInfo.yMax - BranchInfo.yMin+1;
            zSize = BranchInfo.zMax - BranchInfo.zMin+1;
            zSlices = linspace(BranchInfo.zMin,BranchInfo.zMax, zSize);
            if min(zSlices) < 1
                zSlices = zSlices(2:end);
                zSize = zSize-1;
            end
            app.ConfocalImage = zeros(ySize, xSize, zSize);
            for k = 1:length(zSlices)
                tifFile = imread([Path fileName], zSlices(k));
                app.ConfocalImage(:,:,k) = mat2gray(tifFile(BranchInfo.yMin:BranchInfo.yMax,BranchInfo.xMin:BranchInfo.xMax)); %scales it for better viewability
            end
            app.ConfocalImage = mean(app.ConfocalImage, 3);
            %now the dendrite tracing for that image -> make sure that you
            %modify the positions accordingly to fit the image
            for d = 1:size(BranchInfo.dendrites,2)
                app.DendData(d).newCoord = bsxfun(@minus, BranchInfo.dendrites(d).pixelCoord,[BranchInfo.xMin,BranchInfo.yMin]);
                app.DendData(d).dendNr = BranchInfo.dendrites(d).dendNr;
                app.DendData(d).length = BranchInfo.dendrites(d).length;
            end
            
            %load the dendrite data into a table
            app.UITable.Visible = 'on';
            tData = [[app.DendData.dendNr]' logical(zeros(length(app.DendData),1)) zeros(length(app.DendData),1)];
            app.UITable.Data = tData;            
            app.UITable.ColumnWidth = {40 50 80};
            app.UITable.ColumnFormat = {'char', 'logical', 'char'};
            app.tableData = tData;
            
            %load the dendrite numbers into the listbox
            app.DendritesListBox.Visible = 'on';
            app.DendritesListBoxLabel.Visible = 'on';
            app.DendritesListBox.Items = string([app.DendData.dendNr]);
            app.DendritesListBox.Value = string([app.DendData.dendNr]);
            
            %load the data into the axis
            app.UIData1.Visible = 'on';
            app.MaximumSlider.Visible = 'on';
            app.MaximumSliderLabel.Visible = 'on';
            UpdateAxisConfocal(app)
            UpdateDendrites(app)
            
            %show buttons
            app.StartnextButton.Visible = 'on';
            app.SaveDataButton.Visible = 'on';
            app.LoadSavedDataButton.Visible = 'on';
        end

        % Value changed function: BranchesListBox
        function UpdateSelectedBranch(app, event)
            app.SelectedItems.ConfBranch = app.BranchesListBox.Value;
        end

        % Value changed function: MaximumSlider
        function UpdateMaxBrightness(app, event)
            app.SelectedItems.Brightness = app.MaximumSlider.Value;
            UpdateAxisConfocal(app)
        end

        % Cell edit callback: UITable
        function UpdateTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.tableData(indices(1), indices(2)) = newData;
        end

        % Value changed function: DendritesListBox
        function UpdateDend(app, event)
            app.SelectedItems.currDendrite = app.DendritesListBox.Value;
            structNames = app.DendritesListBox.Items;
            app.SelectedItems.currDendID = [];
            for sel=1:length(app.SelectedItems.currDendrite)
                app.SelectedItems.currDendID{sel} = find(contains(structNames,app.SelectedItems.currDendrite{sel}));
            end
            UpdateDendrites(app)
        end

        % Button pushed function: SaveDataButton
        function SaveData(app, event)
            %get today's date
            todayDate = today('datetime');
            AnalysisDir = ['Z:\Juliane\Data\Confocal\' char(app.AnimalField.Value) '\STED coverage\' filesep];
            
            DendDataT = app.DendData; coverData = app.tableData;
            Name = ['STEDCoverage_Dendrite' sprintf( '%02d',str2double(app.SelectedItems.ConfBranch))];
            %save all
            save([AnalysisDir char(Name) '_' char(todayDate) '.mat'],'DendDataT', 'coverData');
        end

        % Button pushed function: LoadSavedDataButton
        function LoadOldData(app, event)
             AnalysisDir = ['Z:\Juliane\Data\Confocal\' char(app.AnimalField.Value) '\STED coverage\' filesep]; 
            
            [fileName, Path] = uigetfile('*.mat', 'Select mat-file for STED coverage',AnalysisDir);
            temp = load([Path fileName]);
            
            %load variables
            app.DendData = temp.DendDataT;
            app.tableData = temp.coverData;
            
            %update table
            app.UITable.Data = app.tableData;            
            
            %update listbox
            app.DendritesListBox.Items = string([app.DendData.dendNr]);
            
            %update selected items
            app.SelectedItems.currDendrites = [];
            app.SelectedItems.currDendID = [];
            
            %update axis
            UpdateAxisConfocal(app)
            UpdateDend(app)
        end

        % Button pushed function: StartnextButton
        function StartNewDendrite(app, event)
            %delete all data for the specific ones
            app.ConfocalImage = [];
            app.DendData = [];
            app.tableData = [];

            app.SelectedItems = struct('ConfBranch', [], 'Brightness', 1, 'currDendrites', [], 'currDendID', []);
            
            %switch off the panels
            app.UITable.Visible = 'off';
            app.MaximumSlider.Visible = 'off';
            app.MaximumSliderLabel.Visible = 'off';
            app.DendritesListBox.Visible = 'off';
            app.DendritesListBoxLabel.Visible = 'off';
            app.SaveDataButton.Visible = 'off';
            app.LoadSavedDataButton.Visible = 'off';
            app.StartnextButton.Visible = 'off';
            app.UIData1.Visible = 'off';
            
            %switch on the initial panel
            app.LoadDendriteData.Enable = 'on';
            app.BranchesListBox.Enable = 'on';
            
            %set panels etc to reset values
            app.MaximumSlider.Value = app.SelectedItems.Brightness;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIData1 = uiaxes(app.UIFigure);
            app.UIData1.XTick = [];
            app.UIData1.YTick = [];
            app.UIData1.Visible = 'off';
            app.LoadInfoButton = uibutton(app.UIFigure, 'push');
            app.LoadInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfo, true);
            app.LoadInfoButton.Text = 'Load Info';
            app.BranchesListBoxLabel = uilabel(app.UIFigure);
            app.BranchesListBoxLabel.Text = 'Branches:';
            app.BranchesListBox = uilistbox(app.UIFigure);
            app.BranchesListBox.Items = {};
            app.BranchesListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectedBranch, true);
            app.BranchesListBox.Value = {};
            app.AnimalEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalField = uieditfield(app.UIFigure, 'text');
            app.AnimalField.Value = 'F2688';
            app.LoadDendriteData = uibutton(app.UIFigure, 'push');
            app.LoadDendriteData.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDendriteData.Text = 'Load data';
            app.MaximumSlider = uislider(app.UIFigure);
            app.MaximumSlider.Limits = [0 1];
            app.MaximumSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.MaximumSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateMaxBrightness, true);
            app.MaximumSlider.Visible = 'off';
            app.MaximumSliderLabel = uilabel(app.UIFigure);
            app.MaximumSliderLabel.Visible = 'off';
            app.MaximumSliderLabel.Text = 'Brightness';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nr'; 'STED'; 'Percentage'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UpdateTable, true);
            app.UITable.Visible = 'off';
            app.DendritesListBoxLabel = uilabel(app.UIFigure);
            app.DendritesListBoxLabel.Visible = 'off';
            app.DendritesListBoxLabel.Text = 'Dendrites';
            app.DendritesListBox = uilistbox(app.UIFigure);
            app.DendritesListBox.Items = {};
            app.DendritesListBox.Multiselect = 'on';
            app.DendritesListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDend, true);
            app.DendritesListBox.Visible = 'off';
            app.DendritesListBox.Value = {};
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData, true);
            app.SaveDataButton.Visible = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.LoadSavedDataButton = uibutton(app.UIFigure, 'push');
            app.LoadSavedDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOldData, true);
            app.LoadSavedDataButton.Visible = 'off';
            app.LoadSavedDataButton.Text = 'Load Data';
            app.StartnextButton = uibutton(app.UIFigure, 'push');
            app.StartnextButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewDendrite, true);
            app.StartnextButton.Visible = 'off';
            app.StartnextButton.Text = 'Start next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STEDcoverage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end