classdef STEDcoverage_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SlideField               matlab.ui.control.DropDown
        SliceDropDownLabel       matlab.ui.control.Label
        CellField                matlab.ui.control.DropDown
        CellDropDownLabel        matlab.ui.control.Label
        AnimalField              matlab.ui.control.DropDown
        AnimalDropDownLabel      matlab.ui.control.Label
        ResetdendriteButton      matlab.ui.control.Button
        DendritefullydoneButton  matlab.ui.control.Button
        AddnewSTEDsegment        matlab.ui.control.Button
        StartnextButton          matlab.ui.control.Button
        LoadSavedDataButton      matlab.ui.control.Button
        SaveDataButton           matlab.ui.control.Button
        DendritesListBox         matlab.ui.control.ListBox
        DendritesListBoxLabel    matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        MaximumSliderLabel       matlab.ui.control.Label
        MaximumSlider            matlab.ui.control.Slider
        LoadDendriteData         matlab.ui.control.Button
        BranchesListBox          matlab.ui.control.ListBox
        BranchesListBoxLabel     matlab.ui.control.Label
        LoadInfoButton           matlab.ui.control.Button
        UIData1                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ConfocalPath = []; 
        ConfocalImage = [];
        ConfocalData = [];
        DendData = [];
        tableData = [];
        cellReconstruction = []; % Description
        SelectedItems = struct('ConfBranch', [], 'Brightness', 1, 'currDendrites', [], 'currDendID', [])
        SetSTEDDetails % Dialog box app for setting start and end points
    end
    
    methods (Access = private)
        
        function UpdateAxisConfocal(app)
            %Delete old traces
            oldImage = findobj(app.UIData1, 'Type', 'Image'); %delete old images
            delete(oldImage)
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            %Update the confocal image
            CellImg = app.ConfocalImage;
            CellImg = mat2gray(CellImg); %should scale the values between 0 and 1
            newImage = imadjust(CellImg, [0; app.SelectedItems.Brightness], []);
            imshow(newImage, 'Parent', app.UIData1);
            axis(app.UIData1, 'image');
            colormap(app.UIData1, 'gray')
            UpdateDendrites(app)
        end
        
        function UpdateDendrites(app)
            hold(app.UIData1, 'on')
            oldROIs = findobj(app.UIData1, 'Type', 'Line'); %delete old ROIs
            delete(oldROIs)
            denColors = cbrewer('div', 'Spectral', length(app.DendData));
            %make sure that all the colors are valid
            denColors(denColors < 0) = 0;
            denColors(denColors > 1) = 1;
            for dend = 1:size(app.DendData,2)
                if ~isempty(find(cell2mat([app.SelectedItems.currDendID]) ==dend,1))
                    plot(app.UIData1, app.DendData(dend).newCoord(:,1),app.DendData(dend).newCoord(:,2), 'LineWidth',1.5, 'color',  denColors(dend,:));
                    hold(app.UIData1, 'on')    
                end
            end 
        end
    end
    
    methods (Access = public)
        
        function GetDendriteInfo(app, updatedDendData)
            %add it to the dendData
            app.DendData(updatedDendData.dendNr).ROIs = updatedDendData.ROIs;
            app.DendData(updatedDendData.dendNr).STEDdone = updatedDendData.STEDdone;
            
            %add the percentage to the table
            app.tableData(updatedDendData.dendNr,3) = updatedDendData.STEDPerc;
            if updatedDendData.STEDPerc > 0
                app.tableData(updatedDendData.dendNr,2) = 1; 
            end
            
            %update the table
            app.UITable.Data = app.tableData;    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~, xls_txt, xls_all]=xlsread('Z:\Juliane\InputAnalysis\STED animals.xlsx', 'Cells'); %open the sheet with the exp info
            animals = unique((xls_all(2:end,find(contains(xls_txt(1,:),'Animal'),1))));
            app.AnimalField.Items = animals;
            app.AnimalField.Value = animals{1};
        end

        % Value changed function: AnimalField
        function AnimalFieldValueChanged(app, event)
            animal = app.AnimalField.Value;
            
            %find the ones that contain the animal
            [~, xls_txt, ~]=xlsread('Z:\Juliane\InputAnalysis\STED animals.xlsx', 'Cells');
            animalExp = cellfun(@(x) find(contains(x, animal)),xls_txt(:,find(contains(xls_txt(1,:),'Animal'),1)),'UniformOutput',false); 
            animalIDs = find(cellfun(@(x) ~isempty(x), animalExp));
            
            %find the cell names
            cells = unique(xls_txt(animalIDs, find(contains(xls_txt(1,:),'CellName'),1)));
            app.CellField.Items = cells;
            
            %find the slices
            slices = unique(xls_txt(animalIDs, find(contains(xls_txt(1,:),'Slice'),1)));
            app.SlideField.Items = slices;
        end

        % Value changed function: CellField
        function CellFieldValueChanged(app, event)
            animal = app.AnimalField.Value;
            cell = app.CellField.Value;
            
            [~, xls_txt, ~]=xlsread('Z:\Juliane\InputAnalysis\STED animals.xlsx', 'Cells');

            %find the ones that contain the animal
            animalExp = cellfun(@(x) find(contains(x, animal)),xls_txt(:,find(contains(xls_txt(1,:),'Animal'),1)),'UniformOutput',false); 
            animalIDs = find(cellfun(@(x) ~isempty(x), animalExp));
            
            %find the ones that contain the cell
            cellExp = cellfun(@(x) find(contains(x, cell)),xls_txt(:,find(contains(xls_txt(1,:),'CellName'),1)),'UniformOutput',false); 
            cellIDs = find(cellfun(@(x) ~isempty(x), cellExp));
            
            rowIDs = intersect(animalIDs, cellIDs);
            
            %find the slices
            slices = unique(xls_txt(rowIDs, find(contains(xls_txt(1,:),'Slice'),1)));
            app.SlideField.Items = slices;
        end

        % Button pushed function: LoadInfoButton
        function LoadInfo(app, event)
            [~, xls_txt, xls_all]=xlsread('Z:\Juliane\InputAnalysis\STED animals.xlsx', 'Cells'); %open the sheet with the exp info
            animalCol = find(contains(xls_txt(1,:),'Animal'),1); %which column is the animal name?
            cellCol = find(contains(xls_txt(1,:),'CellName'),1); %cell
            sliceCol = find(contains(xls_txt(1,:),'Slice'),1); %slice
            dendCol = find(contains(xls_txt(1,:),'DendriteNr'),1); %dendrite ROI
            
            animalExp = cellfun(@(x) find(contains(x, char(app.AnimalField.Value))),xls_txt(:,animalCol),'UniformOutput',false); %find the ones that contain the animal
            animalIDs = find(cellfun(@(x) ~isempty(x), animalExp));
            
            if length(animalIDs) > 1
                %do we have multiple cells and/or slices? start with cells
                if ~isempty(app.CellField.Value)
                    cellExp = cellfun(@(x) find(contains(x, char(app.CellField.Value))),xls_txt(:,cellCol),'UniformOutput',false); %find the ones that contain the animal
                    cellIDs = find(cellfun(@(x) ~isempty(x), cellExp));
                    rowID = intersect(cellIDs, animalIDs);
                    if length(rowID) > 1
                        sliceExp = cellfun(@(x) find(contains(x, char(app.SlideField.Value))),xls_txt(:,sliceCol),'UniformOutput',false); %find the ones that contain the animal
                        sliceIDs = find(cellfun(@(x) ~isempty(x), sliceExp));
                        rowID = intersect(sliceIDs, rowID);
                    end
                elseif ~isempty(app.SlideField.Value)
                    sliceExp = cellfun(@(x) find(contains(x, char(app.SlideField.Value))),xls_txt(:,sliceCol),'UniformOutput',false); %find the ones that contain the animal
                    sliceIDs = find(cellfun(@(x) ~isempty(x), sliceExp));
                    rowID = intersect(sliceIDs, animalIDs);
                    if length(rowID) > 1
                       errordlg('Please specific the cell!', 'Error', 'modal')
                    end
                else
                    errordlg('Please specific the cell and/or slice!', 'Error', 'modal')
                end
            else
                rowID = animalIDs;
            end
            
            if ~isempty(animalIDs)
                dendriteROIsAll = cell2mat(xls_all(rowID,dendCol)); %get the dendrite Nrs
                dendriteROIs = strsplit(dendriteROIsAll,',');
                dendriteROIs = cellfun(@ (x) str2double(x), dendriteROIs);
                
                %write list of selectable dendrites to confocal selector
                app.BranchesListBox.Items = string(dendriteROIs);
                
                
                %load cell reconstruction -> how many branches are there?
                ConfocalDir = 'Z:\Juliane\InputAnalysis\';
                animalName = char(app.AnimalField.Value);
                cellName = char(app.CellField.Value);
                slideName = char(app.SlideField.Value);
                app.ConfocalPath = [char(ConfocalDir) char(animalName) filesep char(cellName) filesep 'B - Confocal\' slideName filesep];
                try 
                    app.cellReconstruction = load([app.ConfocalPath 'cellReconstruction.mat']);
                catch
                    app.ConfocalPath = [char(ConfocalDir) char(animalName) filesep char(cellName) filesep 'B - Confocal\' filesep];
                    app.cellReconstruction = load([app.ConfocalPath 'cellReconstruction.mat']);
                end
                %numBranches = size(app.cellReconstruction.Branches,2);
                %write list of selectable dendrites to confocal selector
                %app.BranchesListBox.Items = string(linspace(1,numBranches, numBranches));
                
                %switch Button appearances 
                app.LoadDendriteData.Visible = 'on';
                app.LoadInfoButton.Enable = 'off';
                app.AnimalField.Enable = 'off';
                app.CellField.Enable = 'off';
                app.SlideField.Enable = 'off';
            else
                errordlg('No data found - Please check your entered data!', 'Error', 'modal')
            end
        end

        % Button pushed function: LoadDendriteData
        function LoadData(app, event)
            %confocal data -> first load the image
            file = dir([app.ConfocalPath '*.tif']);
            %only need to load a partial part of the image
            branchIndex = find(cellfun(@(x) contains(x, app.SelectedItems.ConfBranch),app.BranchesListBox.Items));
            BranchInfo = app.cellReconstruction.Branches(branchIndex);
            ROIPerimenters = vertcat(BranchInfo.ROIs.perimeter);
            ROISlices = vertcat(BranchInfo.ROIs.slice);
            BranchInfo.xMin = min([BranchInfo.xMin min(ROIPerimenters(:,1))]);
            BranchInfo.xMax = max([BranchInfo.xMax max(ROIPerimenters(:,1))])-1;
            BranchInfo.yMin = min([BranchInfo.yMin min(ROIPerimenters(:,2))]);
            BranchInfo.yMax = max([BranchInfo.yMax max(ROIPerimenters(:,2))])-1;
            BranchInfo.zMin = min([BranchInfo.zMin min(ROISlices)]);
            BranchInfo.zMax = max([BranchInfo.zMax max(ROISlices)]);
            if BranchInfo.xMin < 1
                BranchInfo.xMin = 1;
            end
            if BranchInfo.yMin < 1
                BranchInfo.yMin = 1;
            end
            
            xSize = BranchInfo.xMax - BranchInfo.xMin+1;
            ySize = BranchInfo.yMax - BranchInfo.yMin+1;
            zSize = BranchInfo.zMax - BranchInfo.zMin+1;
            zSlices = linspace(BranchInfo.zMin,BranchInfo.zMax, zSize);
            if min(zSlices) < 1
                zSlices = zSlices(2:end);
                zSize = zSize-1;
            end
            app.ConfocalData = zeros(ySize, xSize, zSize);
            for k = 1:length(zSlices)
                tifFile = imread([file(1).folder filesep file(1).name], zSlices(k));
                app.ConfocalData(:,:,k) = mat2gray(tifFile(BranchInfo.yMin:BranchInfo.yMax,BranchInfo.xMin:BranchInfo.xMax)); %scales it for better viewability
            end
            %average for easier visualization
            app.ConfocalImage = mean(app.ConfocalData, 3);
            
            %now the dendrite tracing for that image -> make sure that you
            %modify the positions accordingly to fit the image
            for d = 1:size(BranchInfo.dendrites,2)
                app.DendData(d).newCoord = bsxfun(@minus, BranchInfo.dendrites(d).pixelCoord,[BranchInfo.xMin,BranchInfo.yMin]);
                ROIsOnDend = find([BranchInfo.ROIs.denOnBranch]== d);
                if ~isempty(ROIsOnDend)
                    %get the ROI information for the dendrite
                    app.DendData(d).zSliceMin = min([BranchInfo.ROIs(ROIsOnDend).slice])-BranchInfo.zMin+1;
                    app.DendData(d).zSliceMax = max([BranchInfo.ROIs(ROIsOnDend).slice])-BranchInfo.zMin+1;
                    app.DendData(d).ROIs = BranchInfo.ROIs(ROIsOnDend);
                    for r = 1:length(ROIsOnDend)
                        app.DendData(d).ROIs(r).newPerimeter = bsxfun(@minus, BranchInfo.ROIs(ROIsOnDend(r)).perimeter,[BranchInfo.xMin,BranchInfo.yMin]);
                    end
                    
                    %add in whether STED was done for that ROI or not
                    app.DendData(d).ROIs = arrayfun(@(x) setfield(x, 'STEDdone', 0), app.DendData(d).ROIs);
                else
                    app.DendData(d).zSliceMin = 1;
                    app.DendData(d).zSliceMax = length(zSlices);
                    app.DendData(d).ROIs = [];
                end
                app.DendData(d).dendNr = BranchInfo.dendrites(d).dendNr;
                app.DendData(d).length = BranchInfo.dendrites(d).length;
                app.DendData(d).distToSoma = BranchInfo.dendrites(d).distToSoma;
                app.DendData(d).STEDdone = zeros(length(app.DendData(d).newCoord),1);                                  
            end
            
            %load the dendrite data into a table
            app.UITable.Visible = 'on';
            tData = [[app.DendData.dendNr]' logical(zeros(length(app.DendData),1)) zeros(length(app.DendData),1)];
            app.UITable.Data = tData;            
            app.UITable.ColumnWidth = {40 50 80};
            app.UITable.ColumnFormat = {'char', 'logical', 'char'};
            app.tableData = tData;
            
            %load the dendrite numbers into the listbox
            app.DendritesListBox.Visible = 'on';
            app.DendritesListBoxLabel.Visible = 'on';
            app.DendritesListBox.Items = string([app.DendData.dendNr]);
            app.DendritesListBox.Value =  string([app.DendData.dendNr]);
            app.SelectedItems.currDendrites = app.DendritesListBox.Value;
            for sel=1:length(app.SelectedItems.currDendrites)
                app.SelectedItems.currDendID{sel} = find(contains(app.DendritesListBox.Items,app.SelectedItems.currDendrites{sel}));
            end
            
            %load the data into the axis
            app.UIData1.Visible = 'on';
            app.MaximumSlider.Visible = 'on';
            app.MaximumSliderLabel.Visible = 'on';
            UpdateAxisConfocal(app)
            UpdateDendrites(app)
            
            %show buttons
            app.StartnextButton.Visible = 'on';
            app.SaveDataButton.Visible = 'on';
            app.LoadSavedDataButton.Visible = 'on';
            app.AddnewSTEDsegment.Visible = 'on';
            app.DendritefullydoneButton.Visible = 'on';
            app.ResetdendriteButton.Visible = 'on';
        end

        % Value changed function: BranchesListBox
        function UpdateSelectedBranch(app, event)
            app.SelectedItems.ConfBranch = app.BranchesListBox.Value;
        end

        % Value changed function: MaximumSlider
        function UpdateMaxBrightness(app, event)
            app.SelectedItems.Brightness = app.MaximumSlider.Value;
            UpdateAxisConfocal(app)
        end

        % Value changed function: DendritesListBox
        function UpdateDend(app, event)
            app.SelectedItems.currDendrites = app.DendritesListBox.Value;
            app.SelectedItems.currDendID = [];
            for sel=1:length(app.SelectedItems.currDendrites)
                app.SelectedItems.currDendID{sel} = find(contains(app.DendritesListBox.Items,app.SelectedItems.currDendrites{sel}));
            end
            UpdateDendrites(app)
        end

        % Button pushed function: AddnewSTEDsegment
        function AddnewSTEDsegmentPushed(app, event)
            if length(app.SelectedItems.currDendrites) == 1
                %cut down the image data to the relevant information
                dendNr = find(cellfun(@(x) contains(x, app.SelectedItems.currDendrites{1}),app.DendritesListBox.Items));
                if length(dendNr) > 1 %in case it is something like '9', when we also have '19'
                    dendNr = dendNr(1);
                end
                Data = app.DendData(dendNr);
                if ~isempty(Data.ROIs)
                    ROINewPerimenter = vertcat(Data.ROIs.newPerimeter);
                    
                    dendXCoordMin = floor(min([Data.newCoord(:,1); ROINewPerimenter(:,1)]))+1;
                    dendXCoordMax = ceil(max([Data.newCoord(:,1); ROINewPerimenter(:,1)]));
                    dendYCoordMin = floor(min([Data.newCoord(:,2); ROINewPerimenter(:,2)]))+1;
                    dendYCoordMax = ceil(max([Data.newCoord(:,2); ROINewPerimenter(:,2)]));
                else
                    dendXCoordMin = floor(min(Data.newCoord(:,1)))+1;
                    dendXCoordMax = ceil(max(Data.newCoord(:,1)));
                    dendYCoordMin = floor(min(Data.newCoord(:,2)))+1;
                    dendYCoordMax = ceil(max(Data.newCoord(:,2)));
                end
                
                
                Data.dendNr = dendNr;
                imgData = app.ConfocalData(dendYCoordMin:dendYCoordMax, dendXCoordMin:dendXCoordMax,Data.zSliceMin:Data.zSliceMax);
                
                %convert the coord of the dendrite trace to fit the new window
                Data.PartCoord = bsxfun(@minus, Data.newCoord,[dendXCoordMin,dendYCoordMin]);
                if ~isempty(Data.ROIs)
                    for r = 1:length(Data.ROIs)
                       Data.ROIs(r).PartPerimeter = bsxfun(@minus, Data.ROIs(r).newPerimeter,[dendXCoordMin,dendYCoordMin]);
                    end
                end
                
                % Open the options dialog and pass inputs
                app.SetSTEDDetails= SetSTEDDetails(app, imgData, Data);
            else
                errordlg('Please select only one dendrite!', 'Error', 'modal');
            end
        end

        % Button pushed function: DendritefullydoneButton
        function DendritefullydoneButtonPushed(app, event)
            for i = 1:length(app.SelectedItems.currDendrites)
                %get the dendrite Nr
                dendNr = find(cellfun(@(x) contains(x, app.SelectedItems.currDendrites{i}),app.DendritesListBox.Items));
                if length(dendNr) > 1 %in case it is something like '9', when we also have '19'
                    dendNr = dendNr(1);
                end
                
                %change the DendData
                %for the dendrite
                app.DendData(dendNr).STEDdone = ones(length(app.DendData(dendNr).STEDdone),1);
                %for the spines
                for r= 1:length(app.DendData(dendNr).ROIs)
                    app.DendData(dendNr).ROIs(r).STEDdone = 1;
                end
                
                %add the percentage to the table
                app.tableData(dendNr,3) = 100;
                app.tableData(dendNr,2) = 1; 
                
                %update the table
                app.UITable.Data = app.tableData; 
            end
        end

        % Button pushed function: ResetdendriteButton
        function ResetdendriteButtonPushed(app, event)
            for i = 1:length(app.SelectedItems.currDendrites)
               %get the dendrite Nr
                dendNr = find(cellfun(@(x) contains(x, app.SelectedItems.currDendrites{i}),app.DendritesListBox.Items));
                if length(dendNr) > 1 %in case it is something like '9', when we also have '19'
                    dendNr = dendNr(1);
                end
                
                %change the DendData
                %for the dendrite
                app.DendData(dendNr).STEDdone = zeros(length(app.DendData(dendNr).STEDdone),1);
                %for the spines
                for r= 1:length(app.DendData(dendNr).ROIs)
                    app.DendData(dendNr).ROIs(r).STEDdone = 0;
                end
                
                %reset the table data
                app.tableData(dendNr,3) = 0;
                app.tableData(dendNr,2) = 0;  
                
                %update the table
                app.UITable.Data = app.tableData;   
            end
        end

        % Button pushed function: StartnextButton
        function StartNewDendrite(app, event)
            %delete all data for the specific ones
            app.ConfocalImage = [];
            app.DendData = [];
            app.tableData = [];

            app.SelectedItems = struct('ConfBranch', [], 'Brightness', 1, 'currDendrites', [], 'currDendID', []);
            
            %switch off the panels
            app.UITable.Visible = 'off';
            app.MaximumSlider.Visible = 'off';
            app.MaximumSliderLabel.Visible = 'off';
            app.DendritesListBox.Visible = 'off';
            app.DendritesListBoxLabel.Visible = 'off';
            app.SaveDataButton.Visible = 'off';
            app.LoadSavedDataButton.Visible = 'off';
            app.StartnextButton.Visible = 'off';
            app.UIData1.Visible = 'off';
            app.AddnewSTEDsegment.Visible = 'off';
            app.DendritefullydoneButton.Visible = 'off';
            app.ResetdendriteButton.Visible = 'off';
            
            %switch on the initial panel
            app.LoadDendriteData.Enable = 'on';
            app.BranchesListBox.Enable = 'on';
            
            %set panels etc to reset values
            app.MaximumSlider.Value = app.SelectedItems.Brightness;
        end

        % Button pushed function: SaveDataButton
        function SaveData(app, event)
            %get today's date
            todayDate = today('datetime');
            
            %set savepath
            animalDir = 'Z:\Juliane\InputAnalysis\';
            AnalysisDir = [char(animalDir) char(app.AnimalField.Value) filesep char(app.CellField.Value) filesep 'D - Alignments\STED coverage' filesep];
            if ~exist(AnalysisDir,"dir")
                mkdir(AnalysisDir)
            end
      
            DendDataT = app.DendData; coverData = app.tableData;
            Name = ['STEDCoverage' char(app.SlideField.Value) '_Branch' sprintf( '%02d',str2double(app.SelectedItems.ConfBranch))];
            %save all
            save([AnalysisDir char(Name) '_' char(todayDate) '.mat'],'DendDataT', 'coverData');
            disp(['Data saved to ' char(AnalysisDir)])
        end

        % Button pushed function: LoadSavedDataButton
        function LoadOldData(app, event)
             AnalysisDir = ['Z:\Juliane\InputAnalysis\' char(app.AnimalField.Value) filesep char(app.CellField.Value) filesep 'D - Alignments\STED coverage' filesep]; 
            
            [fileName, Path] = uigetfile('*.mat', 'Select mat-file for STED coverage',AnalysisDir);
            temp = load([Path fileName]);
            
            %load variables
            app.DendData = temp.DendDataT;
            app.tableData = temp.coverData;
            
            %update table
            app.UITable.Data = app.tableData;            
            
            %update listbox
            app.DendritesListBox.Items = string([app.DendData.dendNr]);
            
            %update selected items
            app.SelectedItems.currDendrites = [];
            app.SelectedItems.currDendID = [];
            
            %update axis
            UpdateAxisConfocal(app)
            UpdateDend(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIData1 = uiaxes(app.UIFigure);
            app.UIData1.XTick = [];
            app.UIData1.YTick = [];
            app.UIData1.Visible = 'off';
            app.LoadInfoButton = uibutton(app.UIFigure, 'push');
            app.LoadInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfo, true);
            app.LoadInfoButton.Text = 'Load Info';
            app.BranchesListBoxLabel = uilabel(app.UIFigure);
            app.BranchesListBoxLabel.Text = 'Branches:';
            app.BranchesListBox = uilistbox(app.UIFigure);
            app.BranchesListBox.Items = {};
            app.BranchesListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectedBranch, true);
            app.BranchesListBox.Value = {};
            app.LoadDendriteData = uibutton(app.UIFigure, 'push');
            app.LoadDendriteData.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDendriteData.Text = 'Load data';
            app.MaximumSlider = uislider(app.UIFigure);
            app.MaximumSlider.Limits = [0 1];
            app.MaximumSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.MaximumSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateMaxBrightness, true);
            app.MaximumSlider.Visible = 'off';
            app.MaximumSliderLabel = uilabel(app.UIFigure);
            app.MaximumSliderLabel.Visible = 'off';
            app.MaximumSliderLabel.Text = 'Brightness';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nr'; 'STED'; 'Percentage'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.DendritesListBoxLabel = uilabel(app.UIFigure);
            app.DendritesListBoxLabel.Visible = 'off';
            app.DendritesListBoxLabel.Text = 'Dendrites';
            app.DendritesListBox = uilistbox(app.UIFigure);
            app.DendritesListBox.Items = {};
            app.DendritesListBox.Multiselect = 'on';
            app.DendritesListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDend, true);
            app.DendritesListBox.Visible = 'off';
            app.DendritesListBox.Value = {};
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData, true);
            app.SaveDataButton.Visible = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.LoadSavedDataButton = uibutton(app.UIFigure, 'push');
            app.LoadSavedDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOldData, true);
            app.LoadSavedDataButton.Visible = 'off';
            app.LoadSavedDataButton.Text = 'Load Data';
            app.StartnextButton = uibutton(app.UIFigure, 'push');
            app.StartnextButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewDendrite, true);
            app.StartnextButton.Visible = 'off';
            app.StartnextButton.Text = 'Start next';
            app.AddnewSTEDsegment = uibutton(app.UIFigure, 'push');
            app.AddnewSTEDsegment.ButtonPushedFcn = createCallbackFcn(app, @AddnewSTEDsegmentPushed, true);
            app.AddnewSTEDsegment.Visible = 'off';
            app.AddnewSTEDsegment.Text = 'Add new STED segment';
            app.DendritefullydoneButton = uibutton(app.UIFigure, 'push');
            app.DendritefullydoneButton.ButtonPushedFcn = createCallbackFcn(app, @DendritefullydoneButtonPushed, true);
            app.DendritefullydoneButton.Visible = 'off';
            app.DendritefullydoneButton.Text = 'Dendrite fully done';
            app.ResetdendriteButton = uibutton(app.UIFigure, 'push');
            app.ResetdendriteButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdendriteButtonPushed, true);
            app.ResetdendriteButton.Visible = 'off';
            app.ResetdendriteButton.Text = 'Reset dendrite';
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalField = uidropdown(app.UIFigure);
            app.AnimalField.Items = {};
            app.AnimalField.ValueChangedFcn = createCallbackFcn(app, @AnimalFieldValueChanged, true);
            app.AnimalField.Value = {};
            app.CellDropDownLabel = uilabel(app.UIFigure);
            app.CellDropDownLabel.Text = 'Cell';
            app.CellField = uidropdown(app.UIFigure);
            app.CellField.Items = {};
            app.CellField.ValueChangedFcn = createCallbackFcn(app, @CellFieldValueChanged, true);
            app.CellField.Value = {};
            app.SliceDropDownLabel = uilabel(app.UIFigure);
            app.SliceDropDownLabel.Text = 'Slice';
            app.SlideField = uidropdown(app.UIFigure);
            app.SlideField.Items = {};
            app.SlideField.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STEDcoverage_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end