classdef SetSTEDDetails < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        currentsegmentLabel       matlab.ui.control.Label
        STEDdoneLabel_2           matlab.ui.control.Label
        noSTEDLabel_2             matlab.ui.control.Label
        DendriteLabel             matlab.ui.control.Label
        STEDdoneLabel             matlab.ui.control.Label
        noSTEDLabel               matlab.ui.control.Label
        ROIsLabel                 matlab.ui.control.Label
        ChangeROIstatus           matlab.ui.control.Button
        DrawNewSegment            matlab.ui.control.Button
        AddSegment                matlab.ui.control.Button
        FinishButton              matlab.ui.control.Button
        BrightnessSlider          matlab.ui.control.Slider
        BrightnessSliderLabel     matlab.ui.control.Label
        zSliceSlider              matlab.ui.control.Slider
        zSliceSliderLabel         matlab.ui.control.Label
        PercentageEditField       matlab.ui.control.NumericEditField
        PercentageEditFieldLabel  matlab.ui.control.Label
        EndPointButton            matlab.ui.control.Button
        StartPointButton          matlab.ui.control.Button
        UIConfImg                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app 
        ImgData = [];
        dendData = [];
        SelectedItems = struct('StartPoint', [], 'EndPoint', [], 'Brightness', 1, 'zSlice', 1);
    end
    
    methods (Access = private)
        
        function UpdateImage(app)
            %Delete old traces
            oldImage = findobj(app.UIConfImg, 'Type', 'Image'); %delete old images
            delete(oldImage)
            %Update the confocal image
            CellImg = app.ImgData(:,:,ceil(app.SelectedItems.zSlice));
            CellImg = mat2gray(CellImg); %should scale the values between 0 and 1
            newImage = imadjust(CellImg, [0; app.SelectedItems.Brightness], []);
            imshow(newImage, 'Parent', app.UIConfImg);
            axis(app.UIConfImg, 'image');
            colormap(app.UIConfImg, 'gray')
            
            %update the dendrite
            UpdateDendrite(app)
        end
        
        function UpdateDendrite(app)
            %delete old drawings
            hold(app.UIConfImg, 'on')
            oldROIs = findobj(app.UIConfImg, 'Type', 'Line'); 
            delete(oldROIs)
            
            %draw trace
            for t = 1:length(app.dendData.PartCoord)-1
                if app.dendData.STEDdone(t) == 1
                    plot(app.UIConfImg, app.dendData.PartCoord(t:t+1,1),app.dendData.PartCoord(t:t+1,2), 'LineWidth',1.5, 'color',  'g');
                    hold(app.UIConfImg, 'on')
                else
                    plot(app.UIConfImg, app.dendData.PartCoord(t:t+1,1),app.dendData.PartCoord(t:t+1,2), 'LineWidth',1.5, 'color',  'b');
                    hold(app.UIConfImg, 'on')
                end
            end
            hold(app.UIConfImg, 'on')
            
            %draw ROIs
            for r = 1:length(app.dendData.ROIs)
                if app.dendData.ROIs(r).STEDdone
                    plot(app.UIConfImg, app.dendData.ROIs(r).PartPerimeter(:,1), app.dendData.ROIs(r).PartPerimeter(:,2), 'Color', 'g')
                else
                    plot(app.UIConfImg, app.dendData.ROIs(r).PartPerimeter(:,1), app.dendData.ROIs(r).PartPerimeter(:,2), 'Color', 'b')
                end
            end
            
            %draw segment
            if ~isempty(app.SelectedItems.EndPoint) && ~isempty(app.SelectedItems.StartPoint)               
                %plot new line
                plot(app.UIConfImg, app.dendData.PartCoord(app.SelectedItems.StartPoint:app.SelectedItems.EndPoint,1), app.dendData.PartCoord(app.SelectedItems.StartPoint:app.SelectedItems.EndPoint,2), 'Color', 'r');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, imgData, dendData)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = caller;
            
            % Update UI with input values
            app.ImgData = imgData;
            app.dendData = dendData;
            
            %update the slider
            if numel(size(app.ImgData))>=3
                app.zSliceSlider.Limits = [1, size(app.ImgData,3)];
            else
                app.zSliceSlider.Limits = [0.05, 1];
            end
            
            %update the image
            UpdateImage(app)
            UpdateDendrite(app)
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            app.SelectedItems.Brightness = app.BrightnessSlider.Value;
            UpdateImage(app)
        end

        % Value changed function: zSliceSlider
        function zSliceSliderValueChanged(app, event)
            app.SelectedItems.zSlice = app.zSliceSlider.Value;
            UpdateImage(app)
        end

        % Button pushed function: StartPointButton
        function StartPointButtonPushed(app, event)
            %delete Point
            Point = findobj(app.UIConfImg, 'Type', 'images.roi.point', 'Label', 'Start');
            delete(Point)   
            
            roiPOC = drawpoint(app.UIConfImg, 'Label', 'Start', 'LabelVisible','off', 'Color', 'c');    %Use Mouse To Select a point ROI
            PosPOC=round(get(roiPOC,'Position')); %Extract Coordinates of the point ROI
            
            %find nearest ROI
            distToDend = zeros(size(app.dendData.PartCoord,1),1);
            for c = 1:size(app.dendData.PartCoord,1)
                distToDend(c) = sqrt((app.dendData.PartCoord(c,1) - PosPOC(1))^2+(app.dendData.PartCoord(c,2) - PosPOC(2))^2);
            end
            [~,app.SelectedItems.StartPoint] = min(distToDend);
            plot(app.UIConfImg, app.dendData.PartCoord(app.SelectedItems.StartPoint,1), app.dendData.PartCoord(app.SelectedItems.StartPoint,2), 'Color', 'black', 'MarkerSize', 10);
            %change trace
            if ~isempty(app.SelectedItems.EndPoint)
                %delete previous line
                segment = findobj(app.UIConfImg, 'Type', 'line', 'Color', [1 1 0]);
                delete(segment);
                
                %plot new line
                plot(app.UIConfImg, app.dendData.PartCoord(app.SelectedItems.StartPoint:app.SelectedItems.EndPoint,1), app.dendData.PartCoord(app.SelectedItems.StartPoint:app.SelectedItems.EndPoint,2), 'Color', 'yellow')
                app.AddSegment.Visible = 'on';
            end
        end

        % Button pushed function: EndPointButton
        function EndPointButtonPushed(app, event)
            %delete point if already there
            Point = findobj(app.UIConfImg, 'Type', 'images.roi.point', 'Label', 'End');
            delete(Point)            
            
            %draw a new point
            roiPOC = drawpoint(app.UIConfImg, 'Label', 'End', 'LabelVisible','off','Color', 'm');    %Use Mouse To Select a point ROI
            PosPOC=round(get(roiPOC,'Position')); %Extract Coordinates of the point ROI
            
            
            %find nearest dendrite point
            distToDend = zeros(size(app.dendData.PartCoord,1),1);
            for c = 1:size(app.dendData.PartCoord,1)
                distToDend(c) = sqrt((app.dendData.PartCoord(c,1) - PosPOC(1))^2+(app.dendData.PartCoord(c,2) - PosPOC(2))^2);
            end
            [~,app.SelectedItems.EndPoint] = min(distToDend);
            plot(app.UIConfImg, app.dendData.PartCoord(app.SelectedItems.EndPoint,1), app.dendData.PartCoord(app.SelectedItems.EndPoint,2), 'Color', 'yellow', 'MarkerSize', 10)
            
            %change trace
            if ~isempty(app.SelectedItems.StartPoint)
                %delete previous line
                segment = findobj(app.UIConfImg, 'Type', 'line', 'Color', [1 1 0]);
                delete(segment);
                
                %plot new line
                plot(app.UIConfImg, app.dendData.PartCoord(app.SelectedItems.StartPoint:app.SelectedItems.EndPoint,1), app.dendData.PartCoord(app.SelectedItems.StartPoint:app.SelectedItems.EndPoint,2), 'Color', 'yellow')
                app.AddSegment.Visible = 'on';
            end
        end

        % Button pushed function: AddSegment
        function AddSegmentPushed(app, event)
            if ~isempty(app.SelectedItems.StartPoint) && ~isempty (app.SelectedItems.EndPoint)
                %changes all points on the dendrite that are part of the
                %segment to 1
                app.dendData.STEDdone(app.SelectedItems.StartPoint:app.SelectedItems.EndPoint) = 1;
                
                %changes all ROIs adjacent to the trace to 1 for STEDdone
                %calculate dist to soma for start and endpoint - all ROIs that
                %are between that
                if ~isempty(app.dendData.ROIs)
                    StartLength = (app.dendData.length/(length(app.dendData.STEDdone)-1))*app.SelectedItems.StartPoint + app.dendData.distToSoma;
                    EndLength = (app.dendData.length/(length(app.dendData.STEDdone)-1))*app.SelectedItems.EndPoint + app.dendData.distToSoma;
                    %find ROIs adjacent
                    onSegment = find([app.dendData.ROIs.distToSoma] > StartLength & [app.dendData.ROIs.distToSoma] < EndLength);
                    %set to one
                    for os = 1:length(onSegment)
                        app.dendData.ROIs(onSegment(os)).STEDdone = 1;
                    end
                end
                
                %calculates the percentage of trace covered by STED
                app.dendData.STEDPerc = sum(app.dendData.STEDdone)/length(app.dendData.STEDdone)*100;
                
                %write the percentage of trace covered by STED to the field
                app.PercentageEditField.Value = ceil(app.dendData.STEDPerc);
    
                %remove the start and endpoint
                app.SelectedItems.StartPoint = [];
                app.SelectedItems.EndPoint = [];
                
                %delete points
                SP = findobj(app.UIConfImg, 'Type', 'images.roi.point', 'Label', 'Start');
                delete(SP) 
                EP = findobj(app.UIConfImg, 'Type', 'images.roi.point', 'Label', 'End');
                delete(EP) 
                
                %puts start and end point button to not visible and shows the
                %new segment button
                app.StartPointButton.Visible = 'off';
                app.EndPointButton.Visible = 'off';
                app.AddSegment.Visible = 'off';
                app.DrawNewSegment.Visible = 'on';
                app.FinishButton.Visible = 'on';
                app.ChangeROIstatus.Visible = 'on';
                
                %update the dendrite tracing
                UpdateDendrite(app)
            else
                errordlg('Please define Start. & Endpoint of the segment!', 'error', 'modal');
            end
        end

        % Button pushed function: DrawNewSegment
        function DrawNewSegmentButtonPushed(app, event)
            %enable the drawing a new segment button
            app.StartPointButton.Visible = 'on';
            app.EndPointButton.Visible = 'on';
            app.AddSegment.Visible = 'on';
            
            %switch off the button
            app.DrawNewSegment.Visible = 'off';
            app.FinishButton.Visible = 'on';
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            %send the info to the main app
            GetDendriteInfo(app.CallingApp, app.dendData);
            
            %close the figure
            close(app.UIFigure)
        end

        % Button pushed function: ChangeROIstatus
        function ChangeROIstatusButtonPushed(app, event)
            %draw a new point
            roiPOC = drawpoint(app.UIConfImg,'Label', 'ROI', 'LabelVisible','off');    %Use Mouse To Select a point ROI
            PosPOC=round(get(roiPOC,'Position')); %Extract Coordinates of the point ROI
                        
            %find nearest ROI
            distToROI = zeros(size(app.dendData.ROIs,2),1);
            for c = 1:size(app.dendData.ROIs,2)
                for p = 1:length(app.dendData.ROIs(c).PartPerimeter)
                    distToPixel(p) = sqrt((app.dendData.ROIs(c).PartPerimeter(p,1) - PosPOC(1))^2+(app.dendData.ROIs(c).PartPerimeter(p,2) - PosPOC(2))^2);
                end
                distToROI(c) = min(distToPixel);
            end
            [~,selectedROI] = min(distToROI);
            
            %delete point
            Point = findobj(app.UIConfImg, 'Type', 'images.roi.point', 'Label', 'ROI');
            delete(Point)  
            
            %what is the status of the ROI? change it accordingly
            status = app.dendData.ROIs(selectedROI).STEDdone;
            
            if status == 1
                app.dendData.ROIs(selectedROI).STEDdone = 0;
            elseif status == 0
                app.dendData.ROIs(selectedROI).STEDdone = 1;
            end
            
            %update the image
            UpdateDendrite(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIConfImg = uiaxes(app.UIFigure);
            app.UIConfImg.XTick = [];
            app.UIConfImg.YTick = [];
            app.StartPointButton = uibutton(app.UIFigure, 'push');
            app.StartPointButton.ButtonPushedFcn = createCallbackFcn(app, @StartPointButtonPushed, true);
            app.StartPointButton.Text = 'Start Point';
            app.EndPointButton = uibutton(app.UIFigure, 'push');
            app.EndPointButton.ButtonPushedFcn = createCallbackFcn(app, @EndPointButtonPushed, true);
            app.EndPointButton.Text = 'End Point';
            app.PercentageEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageEditFieldLabel.Text = 'Percentage';
            app.PercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.zSliceSliderLabel = uilabel(app.UIFigure);
            app.zSliceSliderLabel.Text = 'zSlice';
            app.zSliceSlider = uislider(app.UIFigure);
            app.zSliceSlider.Limits = [1 10];
            app.zSliceSlider.ValueChangedFcn = createCallbackFcn(app, @zSliceSliderValueChanged, true);
            app.zSliceSlider.Value = 1;
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [0 1];
            app.BrightnessSlider.MajorTicks = [0 0.5 1];
            app.BrightnessSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Visible = 'off';
            app.FinishButton.Text = 'Finish';
            app.AddSegment = uibutton(app.UIFigure, 'push');
            app.AddSegment.ButtonPushedFcn = createCallbackFcn(app, @AddSegmentPushed, true);
            app.AddSegment.Visible = 'off';
            app.AddSegment.Text = {'Add '; 'segment'};
            app.DrawNewSegment = uibutton(app.UIFigure, 'push');
            app.DrawNewSegment.ButtonPushedFcn = createCallbackFcn(app, @DrawNewSegmentButtonPushed, true);
            app.DrawNewSegment.Visible = 'off';
            app.DrawNewSegment.Text = {'Draw new '; 'segment'};
            app.ChangeROIstatus = uibutton(app.UIFigure, 'push');
            app.ChangeROIstatus.ButtonPushedFcn = createCallbackFcn(app, @ChangeROIstatusButtonPushed, true);
            app.ChangeROIstatus.Visible = 'off';
            app.ChangeROIstatus.Text = {'Change ROI '; 'status'};
            app.ROIsLabel = uilabel(app.UIFigure);
            app.ROIsLabel.Text = 'ROIs:';
            app.noSTEDLabel = uilabel(app.UIFigure);
            app.noSTEDLabel.Text = 'no STED';
            app.STEDdoneLabel = uilabel(app.UIFigure);
            app.STEDdoneLabel.Text = 'STED done';
            app.DendriteLabel = uilabel(app.UIFigure);
            app.DendriteLabel.Text = 'Dendrite:';
            app.noSTEDLabel_2 = uilabel(app.UIFigure);
            app.noSTEDLabel_2.Text = 'no STED';
            app.STEDdoneLabel_2 = uilabel(app.UIFigure);
            app.STEDdoneLabel_2.Text = 'STED done';
            app.currentsegmentLabel = uilabel(app.UIFigure);
            app.currentsegmentLabel.Text = 'current segment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetSTEDDetails(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end