classdef ZoomData1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SelectROIButton        matlab.ui.control.Button
        ConfocalDataLabel      matlab.ui.control.Label
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        ContrastSlider         matlab.ui.control.Slider
        ContrastSliderLabel    matlab.ui.control.Label
        ROIsListBox            matlab.ui.control.ListBox
        ROIsListBoxLabel       matlab.ui.control.Label
        DendritesCheckBox      matlab.ui.control.CheckBox
        ZplaneSlider           matlab.ui.control.Slider
        ZplaneSliderLabel      matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app
        ConfData = [];
        ConfROIs = [];
        ConfDend = [];
        SelectedItems = struct('Zplane', 1, 'dendTrace', 0, 'ROI', [], 'Contrast', 1, 'Brightness', 0)
    end
    
    methods (Access = private)
        
        function UpdateAxis(app)
            CellImg = app.ConfData(:,:,ceil(app.SelectedItems.Zplane));
            CellImg = mat2gray(CellImg); %should scale the values between 0 and 1
            CellImg = CellImg * app.SelectedItems.Contrast + app.SelectedItems.Brightness;
            
            imagesc(CellImg, 'Parent', app.UIAxes);
            colormap(app.UIAxes,'gray')
            axis(app.UIAxes, 'image');
            axis(app.UIAxes,'off');
            hold(app.UIAxes, 'on')
            if app.SelectedItems.dendTrace
                hold(app.UIAxes, 'on')
                for dend = 1:length(app.ConfDend)
                    line(app.UIAxes, app.ConfDend(dend).xTrace, app.ConfDend(dend).yTrace, 'LineWidth', 1.5, 'color', 'yellow')
                end
            end
            for r = 1:length(app.ConfROIs)
                %ungrouped green, grouped blue
                groupTyp = app.ConfROIs(r).group;
                switch groupTyp
                    case 'No group'
                        line(app.UIAxes,[app.ConfROIs(r).newPerimeter(:,1);app.ConfROIs(r).newPerimeter(1,1)],[app.ConfROIs(r).newPerimeter(:,2); app.ConfROIs(r).newPerimeter(1,2)], 'Color', 'Green', 'LineStyle', '-')
                    otherwise
                        line(app.UIAxes,[app.ConfROIs(r).newPerimeter(:,1);app.ConfROIs(r).newPerimeter(1,1)],[app.ConfROIs(r).newPerimeter(:,2); app.ConfROIs(r).newPerimeter(1,2)], 'Color', 'Blue', 'LineStyle', '-')
                        text(app.UIAxes, app.ConfROIs(r).newX, app.ConfROIs(r).newY,char(groupTyp(7:end)), 'Color', 'blue', 'FontSize', 12);

                end
            end
            if ~isempty(app.SelectedItems.ROI)
                hold(app.UIAxes, 'on')
                idData = app.SelectedItems.ROI;
                infoData = strsplit(idData, ' - ');
                Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ConfROIs.id});
                ROINr = find(Matches,1);
                line(app.UIAxes,[app.ConfROIs(ROINr).newPerimeter(:,1);app.ConfROIs(ROINr).newPerimeter(1,1)],[app.ConfROIs(ROINr).newPerimeter(:,2); app.ConfROIs(ROINr).newPerimeter(1,2)], 'Color', 'Yellow', 'LineStyle', '-')
                %plot(app.UIAxes, app.ConfROIs(ROINr).newX, app.ConfROIs(ROINr).newY, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Red');
                %hold(app.UIAxes, 'on')
            end
        end
    end
    
    methods (Access = public)
        
        function DeleteRoiConfocal(app, idData)
            infoData = strsplit(idData, ' - '); 
            Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ConfROIs.id});
            ROINr = find(Matches,1);
            if ~isempty(ROINr)
                %remove from data
                app.ConfROIs(ROINr) = [];
                %update the ROIsListBox and selected ROI
                app.ROIsListBox.Items = {app.ConfROIs.label};
                app.SelectedItems.ROI = []; 
                %update the figure
                UpdateAxis(app)
            end
        end
        
        function UpdateGoupNrConfocal(app, GroupID, idData)
            infoData = strsplit(idData, ' - ');
            Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.ConfROIs.id});
            ROINrinStruct = find(Matches,1);
            if ~isempty(ROINrinStruct) %only do this if it is part of this dataset
                app.ConfROIs(ROINrinStruct).group = ['Group ' num2str(GroupID)]; %update group nr
                app.ConfROIs(ROINrinStruct).label = [app.ConfROIs(ROINrinStruct).id ' - ' app.ConfROIs(ROINrinStruct).group]; %update label
                app.ROIsListBox.Items = {app.ConfROIs.label}; %update listItems
                UpdateAxis(app) %update the figure
            end
        end
        
        function UngroupConfocal(app, oldGroupNr)
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.ConfROIs.group});
            GroupID = find(GroupMatches,1);
            if ~isempty(GroupID)
                app.ConfROIs(GroupID).group = 'No group';
                app.ConfROIs(GroupID).label = [app.ConfROIs(GroupID).id ' - ' app.ConfROIs(GroupID).group];
                app.ROIsListBox.Items = {app.ConfROIs.label}; %update listItems
                UpdateAxis(app) %update the figure
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, imgData, ROIData, dendData)
           % Store main app in property for CloseRequestFcn to use
            app.CallingApp = caller;
            
            % Update UI with input values
            app.ConfData = imgData;
            app.ConfROIs = ROIData;  
            app.ConfDend = dendData;
            
            %update the slider
            app.ZplaneSlider.Limits = [1, size(app.ConfData,3)];
            
            %update the ROIsListBox
            app.ROIsListBox.Items = {app.ConfROIs.label};
            
            %update the figure
            UpdateAxis(app)
        end

        % Value changed function: ZplaneSlider
        function UpdateZSlider(app, event)
            app.SelectedItems.Zplane = app.ZplaneSlider.Value;
            UpdateAxis(app)
        end

        % Value changed function: DendritesCheckBox
        function UpdateDendrites(app, event)
            app.SelectedItems.dendTrace = app.DendritesCheckBox.Value;
            UpdateAxis(app)
        end

        % Value changed function: ROIsListBox
        function UpdateSelectedROI(app, event)
            app.SelectedItems.ROI = app.ROIsListBox.Value;
            UpdateAxis(app)
            
            %also update the main app
            GetNewSelectedROI1(app.CallingApp, app.SelectedItems.ROI);
        end

        % Value changed function: ContrastSlider
        function UpdateContrast(app, event)
            app.SelectedItems.Contrast = app.ContrastSlider.Value;
            UpdateAxis(app)
        end

        % Value changed function: BrightnessSlider
        function UpdateBrightness(app, event)
            app.SelectedItems.Brightness = app.BrightnessSlider.Value;
            UpdateAxis(app)
        end

        % Button pushed function: SelectROIButton
        function SelectROI(app, event)
            roiPOC = drawpoint(app.UIAxes);    %Use Mouse To Select a point ROI
            PosPOC=round(get(roiPOC,'Position')); %Extract Coordinates of the point ROI
            Point = findobj(app.UIAxes, 'Type', 'images.roi.point'); %delete old images
            delete(Point)
            %find nearest ROI
            distToROIs = zeros(size(app.ConfROIs,2),1);
            for c = 1:size(app.ConfROIs,2)
                distToROIs(c) = sqrt((app.ConfROIs(c).newX - PosPOC(1))^2+(app.ConfROIs(c).newY - PosPOC(2))^2);
            end
            [~,ID] = min(distToROIs);
            %update listbox and axis
            app.ROIsListBox.Value = app.ROIsListBox.Items(ID);
            app.SelectedItems.ROI = app.ROIsListBox.Value; 
            UpdateAxis(app)
            
            %also update the main app
            GetNewSelectedROI1(app.CallingApp, app.SelectedItems.ROI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ZplaneSliderLabel = uilabel(app.UIFigure);
            app.ZplaneSliderLabel.Text = 'Z plane';
            app.ZplaneSlider = uislider(app.UIFigure);
            app.ZplaneSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateZSlider, true);
            app.DendritesCheckBox = uicheckbox(app.UIFigure);
            app.DendritesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDendrites, true);
            app.DendritesCheckBox.Text = 'Dendrites';
            app.ROIsListBoxLabel = uilabel(app.UIFigure);
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.UIFigure);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectedROI, true);
            app.ROIsListBox.Value = {};
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-1 1];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateContrast, true);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-1 1];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateBrightness, true);
            app.ConfocalDataLabel = uilabel(app.UIFigure);
            app.ConfocalDataLabel.Text = 'Confocal Data';
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROI, true);
            app.SelectROIButton.Text = 'Select ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZoomData1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end