classdef ZoomData2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SelectROIButton        matlab.ui.control.Button
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        ContrastSlider         matlab.ui.control.Slider
        ContrastSliderLabel    matlab.ui.control.Label
        Data2Label             matlab.ui.control.Label
        ProjectionCheckBox     matlab.ui.control.CheckBox
        DataPlaneLabel         matlab.ui.control.Label
        ROIsListBoxLabel       matlab.ui.control.Label
        ROIsListBox            matlab.ui.control.ListBox
        DataPlaneSlider        matlab.ui.control.Slider
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app
        Data2Img = [];
        Data2ROIs = [];
        SelectedItems = struct('DataPlane', 1, 'ROI', [], 'Projection', 1, 'Contrast',1, 'Brightness', 0, 'ThreeD', 0) % Description
    end
    
    methods (Access = private)
        
        function UpdateAxis(app)
            if app.SelectedItems.ThreeD
                if app.SelectedItems.Projection
                   CellImg = mean(app.Data2Img,3); 
                else
                   CellImg = app.Data2Img(:,:,ceil(app.SelectedItems.DataPlane)); 
                end
            else
                CellImg = app.Data2Img;
            end
            
            CellImg = mat2gray(CellImg); %should scale the values between 0 and 1
            CellImg = CellImg * app.SelectedItems.Contrast + app.SelectedItems.Brightness;
            imagesc(CellImg, 'Parent', app.UIAxes);
            colormap(app.UIAxes,'gray')
            axis(app.UIAxes, 'image');
            axis(app.UIAxes,'off');
            hold(app.UIAxes, 'on')
            for r = 1:length(app.Data2ROIs)
                %grouped blue, ungrouped green
                groupTyp = app.Data2ROIs(r).group;
                switch groupTyp
                    case 'No group'
                        plot(app.UIAxes, app.Data2ROIs(r).newX, app.Data2ROIs(r).newY, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Green');
                    otherwise 
                        plot(app.UIAxes, app.Data2ROIs(r).newX, app.Data2ROIs(r).newY, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Blue');
                        text(app.UIAxes, app.Data2ROIs(r).newX, app.Data2ROIs(r).newY,char(groupTyp(7:end)), 'Color', 'blue', 'FontSize', 12);
                end
                
                %line(app.UIAxes,[app.Data2ROIs(r).newPerimeter(:,1);app.Data2ROIs(r).newPerimeter(1,1)],[app.Data2ROIs(r).newPerimeter(:,2); app.Data2ROIs(r).newPerimeter(1,2)], 'Color', 'Green', 'LineStyle', '-')
                hold(app.UIAxes, 'on')
            end
            if ~isempty(app.SelectedItems.ROI)
                hold(app.UIAxes, 'on')
                idData = app.SelectedItems.ROI;
                infoData = strsplit(idData, ' - ');
                Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.Data2ROIs.id});
                ROINr = find(Matches,1);
                plot(app.UIAxes, app.Data2ROIs(ROINr).newX, app.Data2ROIs(ROINr).newY, 'ok', 'MarkerSize', 3, 'MarkerFaceColor', 'Yellow');
                hold(app.UIAxes, 'on')
            end
            
        end
    end
    
    methods (Access = public)
        
        function DeleteRoiData2(app, idData)
            infoData = strsplit(idData, ' - '); 
            Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.Data2ROIs.id});
            ROINr = find(Matches,1);
            if ~isempty(ROINr) %only do this if it is part of this dataset
                %remove from data
                app.Data2ROIs(ROINr) = [];
                %update the ROIsListBox and selected ROI
                app.ROIsListBox.Items = {app.Data2ROIs.label};
                app.SelectedItems.ROI = []; 
                %update the figure
                UpdateAxis(app)
            end
        end
        
        function UpdateGoupNrData2(app, GroupID, idData)
            infoData = strsplit(idData, ' - ');
            Matches =  cellfun(@(x) strcmp(x, infoData{1}),{app.Data2ROIs.id});
            ROINrinStruct = find(Matches,1);
            if ~isempty(ROINrinStruct) %only do this if it is part of this dataset
                app.Data2ROIs(ROINrinStruct).group = ['Group ' num2str(GroupID)]; %update group nr
                app.Data2ROIs(ROINrinStruct).label = [app.Data2ROIs(ROINrinStruct).id ' - ' app.Data2ROIs(ROINrinStruct).group]; %update label
                app.ROIsListBox.Items = {app.Data2ROIs.label}; %update listItems
                UpdateAxis(app) %update the figure
            end
        end
        
        function UngroupData2(app, oldGroupNr)
            GroupMatches =  cellfun(@(x) strcmp(x, oldGroupNr),{app.Data2ROIs.group});
            GroupID = find(GroupMatches,1);
            if ~isempty(GroupID)
                app.Data2ROIs(GroupID).group = 'No group';
                app.Data2ROIs(GroupID).label = [app.Data2ROIs(GroupID).id ' - ' app.Data2ROIs(GroupID).group];
                app.ROIsListBox.Items = {app.Data2ROIs.label}; %update listItems
                UpdateAxis(app) %update the figure
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, ImgData, ROIData)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = caller;
            
            % Update UI with input values
            app.Data2Img = ImgData;
            app.Data2ROIs = ROIData;  
            
            %update the slider
            if numel(size(ImgData))>=3
                app.DataPlaneSlider.Limits = [1, size(app.Data2Img,3)];
                app.SelectedItems.ThreeD = 1;
            end
            
            %update the ROIsListBox
            app.ROIsListBox.Items = {app.Data2ROIs.label};
            
            %update the figure
            UpdateAxis(app)
        end

        % Value changed function: DataPlaneSlider
        function UpdateDataSlider(app, event)
            app.SelectedItems.DataPlane = app.DataPlaneSlider.Value;
            UpdateAxis(app)
        end

        % Value changed function: ROIsListBox
        function UpdateSelectedROI(app, event)
            app.SelectedItems.ROI = app.ROIsListBox.Value;
            UpdateAxis(app)
            
            %also update the main app
            GetNewSelectedROI2(app.CallingApp, app.SelectedItems.ROI);
        end

        % Value changed function: ProjectionCheckBox
        function UpdateProjection(app, event)
            app.SelectedItems.Projection = app.ProjectionCheckBox.Value;
            UpdateAxis(app)
        end

        % Value changed function: ContrastSlider
        function UpdateContrast(app, event)
            app.SelectedItems.Contrast = app.ContrastSlider.Value;
            UpdateAxis(app)
        end

        % Value changed function: BrightnessSlider
        function UpdateBrightness(app, event)
            app.SelectedItems.Brightness = app.BrightnessSlider.Value;
            UpdateAxis(app)
        end

        % Button pushed function: SelectROIButton
        function SelectROIbyClick(app, event)
            roiPOC = drawpoint(app.UIAxes);    %Use Mouse To Select a point ROI
            PosPOC=round(get(roiPOC,'Position')); %Extract Coordinates of the point ROI
            Point = findobj(app.UIAxes, 'Type', 'images.roi.point'); %delete old images
            delete(Point)
            %find nearest ROI
            distToROIs = zeros(size(app.Data2ROIs,2),1);
            for c = 1:size(app.Data2ROIs,2)
                distToROIs(c) = sqrt((app.Data2ROIs(c).newX - PosPOC(1))^2+(app.Data2ROIs(c).newY - PosPOC(2))^2);
            end
            [~,ID] = min(distToROIs);
            %update listbox and axis
            app.ROIsListBox.Value = app.ROIsListBox.Items(ID);
            app.SelectedItems.ROI = app.ROIsListBox.Value; 
            UpdateAxis(app)
            
            %also update the main app
            GetNewSelectedROI2(app.CallingApp, app.SelectedItems.ROI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DataPlaneSlider = uislider(app.UIFigure);
            app.DataPlaneSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateDataSlider, true);
            app.ROIsListBox = uilistbox(app.UIFigure);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectedROI, true);
            app.ROIsListBox.Value = {};
            app.ROIsListBoxLabel = uilabel(app.UIFigure);
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.DataPlaneLabel = uilabel(app.UIFigure);
            app.DataPlaneLabel.Text = 'DataPlane';
            app.ProjectionCheckBox = uicheckbox(app.UIFigure);
            app.ProjectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateProjection, true);
            app.ProjectionCheckBox.Text = 'Projection';
            app.ProjectionCheckBox.Value = true;
            app.Data2Label = uilabel(app.UIFigure);
            app.Data2Label.Text = 'Data2';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-1 1];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateContrast, true);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-1 1];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateBrightness, true);
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIbyClick, true);
            app.SelectROIButton.Text = 'Select ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZoomData2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end